classdef AnotacionesFlexibles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIAnFlexibles          matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        textbfBienvenidoaunderlineVentanadeAnotacionesPorMontajeLabel  matlab.ui.control.Label
        ConfiguracincanalesparamontajeyanotacionesPanel  matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        Label_2                matlab.ui.control.Label
        SeleccionlistaButton   matlab.ui.control.Button
        CanalT5DropDown        matlab.ui.control.DropDown
        CanalT5DropDownLabel   matlab.ui.control.Label
        CanalO2DropDown        matlab.ui.control.DropDown
        CanalO2DropDownLabel   matlab.ui.control.Label
        CanalC3DropDown        matlab.ui.control.DropDown
        CanalC3DropDownLabel   matlab.ui.control.Label
        CanalA2DropDown        matlab.ui.control.DropDown
        CanalA2DropDownLabel   matlab.ui.control.Label
        CanalT4DropDown        matlab.ui.control.DropDown
        CanalT4DropDownLabel   matlab.ui.control.Label
        CanalO1DropDown        matlab.ui.control.DropDown
        CanalO1DropDownLabel   matlab.ui.control.Label
        CanalF4DropDown        matlab.ui.control.DropDown
        CanalF4DropDownLabel   matlab.ui.control.Label
        CanalA1DropDown        matlab.ui.control.DropDown
        CanalA1DropDownLabel   matlab.ui.control.Label
        CanalT3DropDown        matlab.ui.control.DropDown
        CanalT3DropDownLabel   matlab.ui.control.Label
        CanalP4DropDown        matlab.ui.control.DropDown
        CanalP4DropDownLabel   matlab.ui.control.Label
        CanalF3DropDown        matlab.ui.control.DropDown
        CanalF3DropDownLabel   matlab.ui.control.Label
        CanalCZDropDown        matlab.ui.control.DropDown
        CanalCZDropDownLabel   matlab.ui.control.Label
        CanalF8DropDown        matlab.ui.control.DropDown
        CanalF8DropDownLabel   matlab.ui.control.Label
        CanalP3DropDown        matlab.ui.control.DropDown
        CanalP3DropDownLabel   matlab.ui.control.Label
        CanalFP2DropDown       matlab.ui.control.DropDown
        CanalFP2DropDownLabel  matlab.ui.control.Label
        CanalT6DropDown        matlab.ui.control.DropDown
        CanalT6DropDownLabel   matlab.ui.control.Label
        CanalF7DropDown        matlab.ui.control.DropDown
        CanalF7DropDownLabel   matlab.ui.control.Label
        CanalC4DropDown        matlab.ui.control.DropDown
        CanalC4DropDownLabel   matlab.ui.control.Label
        CanalFP1DropDown       matlab.ui.control.DropDown
        CanalFP1Label          matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        FileSelector           wt.FileSelector
    end

    % Código realizado por Javier Pérez 2024
    properties (Access = private)
        MainApp % Handle de la app principal
        DBhndl % Conexión con base de datos
        hdr % Header estudio eeg
        record % Señales estudio eeg
        dropDowns % Handles dropdowns selección de canales
        defChannels % Canales necesarios para montaje
        path_eeg % Ruta al archivo
        DataTable
    end
    
    properties (Access = public)
        EEGAnFlexibles % EEG leído para anotaciones
        LBLSAnFlexibles % Etiquetas EEG leído para anotaciones
    end
    
    methods (Access = private)
        
        function edf_montage = eeg_preproc(app,chnlsSeleccionados)
            % Se definen los pares de canales para montaje
            montagePairs = {
                'FP1', 'F7'; 'F7', 'T3'; 'T3', 'T5'; 'T5', 'O1'; 'FP2', 'F8';
                'F8', 'T4'; 'T4', 'T6'; 'T6', 'O2'; 'A1', 'T3'; 'T3', 'C3';
                'C3', 'CZ'; 'CZ', 'C4'; 'C4', 'T4'; 'T4', 'A2'; 'FP1', 'F3';
                'F3', 'C3'; 'C3', 'P3'; 'P3', 'O1'; 'FP2', 'F4'; 'F4', 'C4';
                'C4', 'P4'; 'P4', 'O2'
                };

            % Se aloca memoria para montaje
            edf_montage = zeros(size(app.record, 1), size(montagePairs, 1));

            % Se llena edf_montage con las diferencias de cada par de canales
            for i = 1:size(montagePairs, 1)
                % Índices de cada canal
                idx1 = chnlsSeleccionados.(montagePairs{i, 1}).Index;
                idx2 = chnlsSeleccionados.(montagePairs{i, 2}).Index;

                % Si ambos canales fueron encontrados
                if ~isempty(idx1) && ~isempty(idx2)
                    edf_montage(:, i) = app.record(:, idx1) - app.record(:, idx2);
                else
                    edf_montage(:, i) = zeros(size(app.record, 1),1);
                end
            end

            % Resampling si la frecuencia de muestreo es diferente a la deseada
            des_Fs = 256;
            if app.hdr.Fs ~= des_Fs
                [P, Q] = rat(des_Fs / app.hdr.Fs(1));
                edf_montage = resample(edf_montage, P, Q);
                app.hdr.Fs = des_Fs;
            end

            % Normalización: centrar en cero y escalar
            desvesta = std(edf_montage, 0, 2);
            desvesta(desvesta == 0) = 1; % Evitar división por cero
            edf_montage = normalize(edf_montage, 2, "center", "mean") ./ desvesta;

            % Reescalar entre -1 y 1 usando el valor mínimo y máximo global
            edf_montage = rescale(edf_montage, -1, 1, "InputMin", min(edf_montage, [], "all"), "InputMax", max(edf_montage, [], "all"));
            
            numSamples = size(edf_montage, 1);
            timeVector = (0:numSamples-1) / app.hdr.Fs(1);

            % Crear un stackedplot para mostrar los canales en el orden de montaje
            figure;
            s = stackedplot(timeVector,edf_montage, 'DisplayLabels', strcat(montagePairs(:, 1), '-', montagePairs(:, 2)));
           
            % Personalizar el stackedplot
            s.Title = 'Montaje Bipolar EEG';  % Título del stacked plot
            s.XLabel = 'Tiempo (s)';  % Etiqueta del eje X
        end

        function plotRowData(app, rowIndex)
            Fs = 256; % Frecuencia de muestreo en Hz
            % Canales utilizados
            montagePairs = {
                'FP1', 'F7'; 'F7', 'T3'; 'T3', 'T5'; 'T5', 'O1'; 'FP2', 'F8';
                'F8', 'T4'; 'T4', 'T6'; 'T6', 'O2'; 'A1', 'T3'; 'T3', 'C3';
                'C3', 'CZ'; 'CZ', 'C4'; 'C4', 'T4'; 'T4', 'A2'; 'FP1', 'F3';
                'F3', 'C3'; 'C3', 'P3'; 'P3', 'O1'; 'FP2', 'F4'; 'F4', 'C4';
                'C4', 'P4'; 'P4', 'O2'
                };

            % Se extraen los tiempos de inicio y fin de la fila seleccionada
            strt_lbl = app.DataTable{rowIndex, 1}; 
            stop_lbl = app.DataTable{rowIndex, 2};

            % Se convierten tiempos a índices del array
            strt_idx = ceil(strt_lbl * Fs) + 1;
            stop_idx = min(size(app.record, 1), ceil(stop_lbl * Fs) + 1);

            % Se extrae el fragmento de estudio y su vector de tiempo asociado
            dataFragment = app.EEGAnFlexibles(strt_idx:stop_idx, :); % EEG preprocesado
            timeVector = (strt_idx:stop_idx) / Fs; % Vector de tiempo correspondiente

            % Se generan etiquetas para cada canal del montaje
            displayLabels = strcat(montagePairs(:, 1), '-', montagePairs(:, 2)); % Concatenar etiquetas

            % Se crea nueva figura para el montaje del tiempo seleccionado
            fig = uifigure('Name', sprintf('Gráfico: %.2f a %.2f s', strt_lbl, stop_lbl), ...
                'Position', [500 100 600 400]);

            % Se crea el stacked plot
            sp = stackedplot(fig, timeVector, dataFragment, 'DisplayLabels', displayLabels);

            % Formato de gráfico
            sp.Title = sprintf('Datos EEG entre %.2f y %.2f segundos', strt_lbl, stop_lbl);
            sp.XLabel = 'Tiempo (s)';
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, DBcon)
            app.MainApp = mainApp; % Se guarda handle de mainApp
            app.DBhndl = DBcon; % Se guarda conexión con base de datos
        end

        % Close request function: UIAnFlexibles
        function UIAnFlexiblesCloseRequest(app, event)
            
            % En caso de cerrarse la ventana, se habilita nuevamente su
            % botón de lanzamiento
            if isvalid(app.MainApp)
                app.MainApp.AnotacionesFlexButton.Enable = "on";
            end
         

            % Se cierra ventana
            delete(app)
        end

        % Callback function: FileSelector
        function FileSelectorValueChanged(app, event)
            app.path_eeg = app.FileSelector.Value; % Se obtiene el path que el usuario seleccionó

            if isfile(app.path_eeg) && endsWith(app.path_eeg,'.edf', 'IgnoreCase', true)
                [app.hdr, app.record] = openedf(app.path_eeg); % Lectura del EEG

                % Se obtienen los nombres de los canales disponibles más la
                % opción N/A
                nombresCanales = [app.hdr.SignalLabels; "No aplica"];

                % Lista de todos los dropdown para selección de canales
                app.dropDowns = {app.CanalT5DropDown, app.CanalO2DropDown, app.CanalC3DropDown, ...
                                 app.CanalA2DropDown, app.CanalT4DropDown, app.CanalO1DropDown, ...
                                 app.CanalF4DropDown, app.CanalA1DropDown, app.CanalT3DropDown, ...
                                 app.CanalP4DropDown, app.CanalF3DropDown, app.CanalCZDropDown, ...
                                 app.CanalF8DropDown, app.CanalP3DropDown, app.CanalFP2DropDown, ...
                                 app.CanalT6DropDown, app.CanalF7DropDown, app.CanalC4DropDown, ...
                                 app.CanalFP1DropDown};

                % Nombres de referencia para cada DropDown
                app.defChannels = {"T5", "O2", "C3", "A2",  "T4", "O1", "F4", ...
                                   "A1", "T3", "P4", "F3", "CZ",  "F8", "P3", ...
                                   "FP2", "T6", "F7", "C4", "FP1"};

                for i = 1:numel(app.dropDowns)
                    % Se asignan las opciones de los canales al dropdown actual
                    app.dropDowns{i}.Items = nombresCanales;

                    % Se busca coincidencia parcial en los nombres de los canales
                    matchIndex = find(contains(lower(nombresCanales), lower(app.defChannels{i})), 1);

                    % Si hay una coincidencia, se establece como valor predeterminado
                    if ~isempty(matchIndex)
                        app.dropDowns{i}.Value = nombresCanales{matchIndex};
                    else
                        % Si no hay coincidencia, se establece "No aplica" como valor predeterminado
                        app.dropDowns{i}.Value = "No aplica";
                    end
                end

                % Se hace visible el panel de configuraciones para montaje
                app.ConfiguracincanalesparamontajeyanotacionesPanel.Visible = "on";
                
                % Se habilita botón de confirmación
            app.SeleccionlistaButton.Enable = "on";
            else
                % Alerta al usuario
                uialert(app.UIAnFlexibles, 'La ruta proporcionada no corresponde a un archivo .edf válido', 'Error de archivo', 'Icon', 'error');
                app.FileSelector.Value = "";
            end
            
        end

        % Button pushed function: SeleccionlistaButton
        function SeleccionlistaButtonPushed(app, event)
            % Se inhabilita botón de confirmación
            app.SeleccionlistaButton.Enable = "off";
            
            % Se inicializa un struct para almacenar los valores seleccionados
            chnlsSeleccionados = struct();

            % Se recorre cada dropdown y se obtiene el valor seleccionado
            for i = 1:numel(app.dropDowns)

                % Encontrar el índice del canal seleccionado en la lista de nombres de canales
                indexChnSel = find(strcmp(app.hdr.SignalLabels, app.dropDowns{i}.Value), 1);

                % Guardar el valor y el índice en la estructura
                chnlsSeleccionados.(app.defChannels{i}).Value = app.dropDowns{i}.Value;
                chnlsSeleccionados.(app.defChannels{i}).Index = indexChnSel;
            end
            
            %% Aquí inicia procesamiento de EEG
            
            % Se lee archivo EEG y se preprocesa
            app.EEGAnFlexibles = eeg_preproc(app,chnlsSeleccionados);
            app.LBLSAnFlexibles = 0;
            
            % Se guarda en el Workspace el EEG preprocesado
            assignin('base', 'montaje', app.EEGAnFlexibles);
           
            %% Esta sección es donde se debe de cargar la red neuronal
             % Dado que se sigue en el proceso de entrenar una red no tan
             % sesgada, se emplea de momento las anotaciones que
             % proporciona el estudio.

             % Al implementarse la red neuronal, se tendría que cargar con
             % "load", luego usar la función "predict" y por eficiencia
             % definir:
             %
             % 1. Pasar los scores a labels, luego aplicar un treshold para
             %    para mostrar los eventos que tengan más de x segundos de
             %    duración.
             % 2. Usar los scores sin convertir, luego aplicar un treshold para
             %    para mostrar los eventos que tengan más de x segundos de
             %    duración.
             %
             % Guardar eventos que pasen el treshold en el formato de las
             % tablas de anotaciones.

            % Opciones para leer el archivo CSV
            opts = detectImportOptions(strrep(app.path_eeg,'.edf','_bi.csv'), "Delimiter", ",", "NumHeaderLines", 5);

            % Seleccionar solo las columnas de interés
            opts.SelectedVariableNames = opts.VariableNames(2:4);

            % Leer la tabla con las opciones especificadas
            tbl_lbl = readtable(strrep(app.path_eeg,'.edf','_bi.csv'), opts);

            % Filtrar filas donde aparece la palabra "seiz" en la columna de etiquetas
            idxSeiz = contains(tbl_lbl{:, 3}, 'seiz', 'IgnoreCase', true);
            filteredTable = tbl_lbl(idxSeiz, :);

            % Si no hay coincidencias se devuelve alerta que no hay convulsión
            if isempty(filteredTable)
                uialert(app.UIAnFlexibles, 'No se encontraron eventos de convulsión.', 'Sin resultados');
                return; %Termina el proceso
            end

            % Se guarda la tabla filtrada en una propiedad de la aplicación
            app.DataTable = filteredTable;
            
            %% Se crea la ventana secundaria de eventos de convulsión
            fig = uifigure('Name', 'Eventos de convulsión identificados', 'Position', [100 100 400 600]);

            % Crear un panel con desplazamiento
            scrollPanel = uipanel(fig, 'Position', [10 10 380 580]);
            scrollPanel.Scrollable = 'on';

            % Se obtiene número de filas en la tabla filtrada
            numRows = height(app.DataTable);

            % Se crea un hipervínculo para cada fila (Evento de convulsión)
            for i = 1:numRows
                % Se obtienen los valores de tiempo de inicio y fin
                tInicio = app.DataTable{i, 1};
                tFin = app.DataTable{i, 2};

                % Formato para imprimir eventos
                mensaje = sprintf('Convulsión entre %.2f y %.2f s.', tInicio, tFin);

                % Se crea el hipervínculo con el mensaje y se asigna
                % Callback al presionar cada uno
                link = uibutton(scrollPanel, 'Text', mensaje, ...
                    'Position', [10 580 - 30*i 360 22], ...
                    'ButtonPushedFcn', @(btn, event) plotRowData(app, i));
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIAnFlexibles = uifigure('Visible', 'off');
            app.UIAnFlexibles.Name = 'Anotaciones por montaje';
            app.UIAnFlexibles.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.UIAnFlexibles.CloseRequestFcn = createCallbackFcn(app, @UIAnFlexiblesCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIAnFlexibles);
            app.FileSelector = wt.FileSelector(app.GridLayout);
            app.FileSelector.Filter = "*.edf";
            app.FileSelector.ValueChangedFcn = createCallbackFcn(app, @FileSelectorValueChanged, true);
            app.FileSelector.Tooltip = "Presione el ícono de carpeta para abrir el explorador de archivos";
            app.Label = uilabel(app.GridLayout);
            app.Label.Interpreter = 'html';
            app.Label.Text = 'Seleccione el estudio a analizar <b><font color="red">(.edf)</font></b>:';
            app.ConfiguracincanalesparamontajeyanotacionesPanel = uipanel(app.GridLayout);
            app.ConfiguracincanalesparamontajeyanotacionesPanel.Title = 'Configuración canales para montaje y anotaciones';
            app.ConfiguracincanalesparamontajeyanotacionesPanel.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.ConfiguracincanalesparamontajeyanotacionesPanel);
            app.CanalFP1Label = uilabel(app.GridLayout2);
            app.CanalFP1Label.Text = 'Canal  FP1:';
            app.CanalFP1DropDown = uidropdown(app.GridLayout2);
            app.CanalC4DropDownLabel = uilabel(app.GridLayout2);
            app.CanalC4DropDownLabel.Text = 'Canal  C4:';
            app.CanalC4DropDown = uidropdown(app.GridLayout2);
            app.CanalF7DropDownLabel = uilabel(app.GridLayout2);
            app.CanalF7DropDownLabel.Text = 'Canal  F7:';
            app.CanalF7DropDown = uidropdown(app.GridLayout2);
            app.CanalT6DropDownLabel = uilabel(app.GridLayout2);
            app.CanalT6DropDownLabel.Text = 'Canal  T6:';
            app.CanalT6DropDown = uidropdown(app.GridLayout2);
            app.CanalFP2DropDownLabel = uilabel(app.GridLayout2);
            app.CanalFP2DropDownLabel.Text = 'Canal  FP2:';
            app.CanalFP2DropDown = uidropdown(app.GridLayout2);
            app.CanalP3DropDownLabel = uilabel(app.GridLayout2);
            app.CanalP3DropDownLabel.Text = 'Canal  P3:';
            app.CanalP3DropDown = uidropdown(app.GridLayout2);
            app.CanalF8DropDownLabel = uilabel(app.GridLayout2);
            app.CanalF8DropDownLabel.Text = 'Canal  F8:';
            app.CanalF8DropDown = uidropdown(app.GridLayout2);
            app.CanalCZDropDownLabel = uilabel(app.GridLayout2);
            app.CanalCZDropDownLabel.Text = 'Canal  CZ:';
            app.CanalCZDropDown = uidropdown(app.GridLayout2);
            app.CanalF3DropDownLabel = uilabel(app.GridLayout2);
            app.CanalF3DropDownLabel.Text = 'Canal  F3:';
            app.CanalF3DropDown = uidropdown(app.GridLayout2);
            app.CanalP4DropDownLabel = uilabel(app.GridLayout2);
            app.CanalP4DropDownLabel.Text = 'Canal  P4:';
            app.CanalP4DropDown = uidropdown(app.GridLayout2);
            app.CanalT3DropDownLabel = uilabel(app.GridLayout2);
            app.CanalT3DropDownLabel.Text = 'Canal  T3:';
            app.CanalT3DropDown = uidropdown(app.GridLayout2);
            app.CanalA1DropDownLabel = uilabel(app.GridLayout2);
            app.CanalA1DropDownLabel.Text = 'Canal  A1:';
            app.CanalA1DropDown = uidropdown(app.GridLayout2);
            app.CanalF4DropDownLabel = uilabel(app.GridLayout2);
            app.CanalF4DropDownLabel.Text = 'Canal  F4:';
            app.CanalF4DropDown = uidropdown(app.GridLayout2);
            app.CanalO1DropDownLabel = uilabel(app.GridLayout2);
            app.CanalO1DropDownLabel.Text = 'Canal  O1:';
            app.CanalO1DropDown = uidropdown(app.GridLayout2);
            app.CanalT4DropDownLabel = uilabel(app.GridLayout2);
            app.CanalT4DropDownLabel.Text = 'Canal  T4:';
            app.CanalT4DropDown = uidropdown(app.GridLayout2);
            app.CanalA2DropDownLabel = uilabel(app.GridLayout2);
            app.CanalA2DropDownLabel.Text = 'Canal  A2:';
            app.CanalA2DropDown = uidropdown(app.GridLayout2);
            app.CanalC3DropDownLabel = uilabel(app.GridLayout2);
            app.CanalC3DropDownLabel.Text = 'Canal  C3:';
            app.CanalC3DropDown = uidropdown(app.GridLayout2);
            app.CanalO2DropDownLabel = uilabel(app.GridLayout2);
            app.CanalO2DropDownLabel.Text = 'Canal  O2:';
            app.CanalO2DropDown = uidropdown(app.GridLayout2);
            app.CanalT5DropDownLabel = uilabel(app.GridLayout2);
            app.CanalT5DropDownLabel.Text = 'Canal  T5:';
            app.CanalT5DropDown = uidropdown(app.GridLayout2);
            app.SeleccionlistaButton = uibutton(app.GridLayout2, 'push');
            app.SeleccionlistaButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionlistaButtonPushed, true);
            app.SeleccionlistaButton.Enable = 'off';
            app.SeleccionlistaButton.Text = 'Selección lista';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = 'Por favor, indique y verifique que cada canal del estudio sea asignado correctamente debajo. Una vez finalice la revisión, presione el botón en la esquina inferior derecha.';
            app.textbfBienvenidoaunderlineVentanadeAnotacionesPorMontajeLabel = uilabel(app.GridLayout);
            app.textbfBienvenidoaunderlineVentanadeAnotacionesPorMontajeLabel.Interpreter = 'latex';
            app.textbfBienvenidoaunderlineVentanadeAnotacionesPorMontajeLabel.Text = {'\textbf{¡Bienvenido/a!}'; '\underline{Ventana de Anotaciones Por Montaje}'};
            % Show the figure after all components are created
            app.UIAnFlexibles.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnotacionesFlexibles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIAnFlexibles)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIAnFlexibles)
        end
    end
end