classdef Anotaciones_no_supervisado < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnotacionesnosupervisadasUIFigure  matlab.ui.Figure
        sLabel                         matlab.ui.control.Label
        HoradeiniciodelregistroEEGHHMMSSEditField  matlab.ui.control.EditField
        HoradeiniciodelregistroEEGHHMMSSEditFieldLabel  matlab.ui.control.Label
        VentanasiguienteLabel          matlab.ui.control.Label
        VentanaanteriorLabel           matlab.ui.control.Label
        Boton_Siguiente                matlab.ui.control.Button
        Boton_Anterior                 matlab.ui.control.Button
        GrillaCheckBox                 matlab.ui.control.CheckBox
        TiempoavisualizarEditField     matlab.ui.control.NumericEditField
        TiempoavisualizarLabel         matlab.ui.control.Label
        CanalavisualizarDropDown       matlab.ui.control.DropDown
        CanalavisualizarDropDownLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
       
        all_channel_features  % Estructura de clusters para cada canal
        EEG_a_analizar        % Señal EEG completa
        Fs                    % Frecuencia de muestreo
        Nombres_Canales       % Lista de nombres de los canales
        tiempoVisualizar
        datosVisualizar
    end
    
    properties (Access = private)
        tiempoInicioActual = 0; % Variable para rastrear el tiempo de inicio actual
    end
    
    methods (Access = public)
       
        %---------------------------------------------DI------------------
        function actualizarGrafica(app)
            % Obtén el canal seleccionado del DropDown
            canalSeleccionado = app.CanalavisualizarDropDown.Value;
            
        
            % Verificar que el canal y datos estén disponibles
            if isfield(app.all_channel_features, canalSeleccionado)
                datosCanal = app.EEG_a_analizar(strcmp(app.Nombres_Canales, canalSeleccionado), :);
                etiquetasClusters = app.all_channel_features.(canalSeleccionado);
            else
                disp('El canal seleccionado no tiene datos de cluster');
                return;
            end
        
            % Asegurarse de que existen datos para graficar
            if isempty(datosCanal) || isempty(etiquetasClusters)
                disp('No hay datos disponibles para el canal seleccionado.');
                return;
            end
        
            % Tamaño de la ventana en segundos y en muestras
            window_size = app.TiempoavisualizarEditField.Value;
            muestrasPorVentana = window_size * app.Fs;
        
            % Índices de inicio y fin basados en tiempoInicioActual
            startIdx = round(app.tiempoInicioActual * app.Fs) + 1;
            endIdx = min(startIdx + muestrasPorVentana - 1, length(datosCanal));
        
            % Extrae los datos y el tiempo a graficar
            app.datosVisualizar = datosCanal(startIdx:endIdx);  % Datos del canal en el intervalo
            app.tiempoVisualizar = (0:length(app.datosVisualizar)-1) / app.Fs + app.tiempoInicioActual;  % Tiempo en segundos para el intervalo
        
            % Limpia la gráfica anterior
            cla(app.UIAxes);
        
            % Si etiquetasClusters tiene dos columnas, se interpreta como Fuzzy-C
            if size(etiquetasClusters, 2) == 2
                % Configurar el colormap para Fuzzy-C
                cmap = parula;  % Usar parula o el colormap que prefieras
                numColors = size(cmap, 1);
        
                % Recorre las ventanas y grafica cada segmento con el color correspondiente
                duracionVentanaCluster = length(datosCanal) / size(etiquetasClusters, 1) / app.Fs;
                for i = 1:size(etiquetasClusters, 1)
                    % Calcula el inicio y fin en tiempo para cada ventana de cluster
                    clusterStartTime = (i - 1) * duracionVentanaCluster;
                    clusterEndTime = i * duracionVentanaCluster;
        
                    % Verificar si la ventana de cluster se solapa con el intervalo visible
                    if clusterEndTime < app.tiempoInicioActual || clusterStartTime > app.tiempoInicioActual + window_size
                        continue;  % Si está fuera del rango visible, omitir
                    end
        
                    % Ajustar los tiempos para alinearlos con el intervalo visible
                    inicioVisible = max(clusterStartTime, app.tiempoInicioActual);
                    finVisible = min(clusterEndTime, app.tiempoInicioActual + window_size);
        
                    % Convertir los tiempos a índices de muestra dentro de `datosVisualizar`
                    idxInicio = max(1, round((inicioVisible - app.tiempoInicioActual) * app.Fs) + 1);
                    idxFin = min(length(app.datosVisualizar), round((finVisible - app.tiempoInicioActual) * app.Fs) + 1);
        
                    % Extraer el segmento de datos y tiempo para la etiqueta actual
                    segmentoDatos = app.datosVisualizar(idxInicio:idxFin);
                    segmentoTiempo = app.tiempoVisualizar(idxInicio:idxFin);
        
                    % Calcular el grado de pertenencia al primer cluster y asignar color del gradiente
                    pertenenciaCluster1 = etiquetasClusters(i, 1);  % Pertenencia al primer cluster
                    colorIdx = round(pertenenciaCluster1 * (numColors - 1)) + 1;
                    colorIdx = max(1, min(colorIdx, numColors));  % Asegura que esté en el rango válido
        
                    % Obtén el color del colormap
                    color = cmap(colorIdx, :);
                    

                    % Graficar el segmento de datos con el color correspondiente
                    plot(app.UIAxes, segmentoTiempo, segmentoDatos, 'Color', color, 'LineWidth', 1.5);
                    hold(app.UIAxes, 'on');
                    
                    % % Segunda Gráfica
                    % pertenenciaCluster2 = etiquetasClusters(i, 2);  % Pertenencia al segundo cluster
                    % colorIdx2 = round(pertenenciaCluster2 * (numColors - 1)) + 1;
                    % colorIdx2 = max(1, min(colorIdx2, numColors));  % Asegura que esté en el rango válido
                    % color2 = cmap(colorIdx2, :);
                    % plot(app.Fuzzy2, segmentoTiempo, segmentoDatos, 'Color', color2, 'LineWidth', 1.5);
                    % hold(app.Fuzzy2, 'on');
                    

                end
        
                % Añadir una colorbar para mostrar el gradiente de pertenencia
                colorbar(app.UIAxes, 'Ticks', linspace(0, 1, 6), 'TickLabels', {'0', '0.2', '0.4', '0.6', '0.8', '1'});
                colormap(app.UIAxes, cmap);  % Asegura que el colormap es el mismo para la gráfica y la barra de color
                caxis(app.UIAxes, [0 1]);  % Define el rango de la colorbar de 0 a 1


                % Se puede hacer una segunda gráfica con otro resultado de
                % Fuzzy y visualizarlo de la misma manera, dejo el código
                % comentado si se deseará hacer. Hay que crear un UIAxes
                % con el nombre de ventana Fuzzy2


                % % Añadir una colorbar para mostrar el gradiente de
                % pertenencia de la segunda gráfica
                % colorbar(app.Fuzzy2, 'Ticks', linspace(0, 1, 6), 'TickLabels', {'0', '0.2', '0.4', '0.6', '0.8', '1'});
                % colormap(app.Fuzzy2, cmap);  % Asegura que el colormap es el mismo para la gráfica y la barra de color
                % caxis(app.Fuzzy2, [0 1]);  % Define el rango de la colorbar de 0 a 1
                % 
                % % Configura etiquetas y título
                % xlabel(app.Fuzzy2, 'Tiempo (HH:MM:SS)');
                % ylabel(app.Fuzzy2, 'Voltaje (µV)');
                % title(app.Fuzzy2, ['Señal EEG - Canal: ', canalSeleccionado]);
            
                % Ajusta los límites del eje X y define los ticks en formato HH:MM:SS
                % app.Fuzzy2.XLim = [app.tiempoVisualizar(1), app.tiempoVisualizar(end)];
                % xticks_array = linspace(app.tiempoVisualizar(1), app.tiempoVisualizar(end), 5);
                % xtick_labels = datestr(seconds(xticks_array), 'HH:MM:SS');
                % 
                % app.Fuzzy2.XTick = xticks_array;
                % app.Fuzzy2.XTickLabel = xtick_labels;
                % 
                % hold(app.Fuzzy2, 'off');
        
            else 
                % Lógica de gráficos para clusters estándar (no fuzzy)
                % Define colores para cada cluster
                numClusters = max(etiquetasClusters);  % Número de clusters
                colores = lines(numClusters);  % Colores para cada cluster
                
                % Duración de cada ventana de etiquetas de cluster
                duracionVentanaCluster = length(datosCanal) / length(etiquetasClusters) / app.Fs;
                
                % Recorre las ventanas y grafica cada segmento con el color correspondiente
                for i = 1:length(etiquetasClusters)
                    % Calcular los tiempos de inicio y fin para cada ventana de cluster
                    clusterStartTime = (i - 1) * duracionVentanaCluster;
                    clusterEndTime = i * duracionVentanaCluster;
            
                    % Verificar si la ventana de cluster se solapa con el intervalo visible
                    if clusterEndTime < app.tiempoInicioActual || clusterStartTime > app.tiempoInicioActual + window_size
                        continue;  % Si está fuera del rango visible, omitir
                    end
            
                    % Ajustar los tiempos para alinearlos con el intervalo visible
                    inicioVisible = max(clusterStartTime, app.tiempoInicioActual);
                    finVisible = min(clusterEndTime, app.tiempoInicioActual + window_size);
            
                    % Convertir los tiempos a índices de muestra dentro de `datosVisualizar`
                    idxInicio = max(1, round((inicioVisible - app.tiempoInicioActual) * app.Fs) + 1);
                    idxFin = min(length(app.datosVisualizar), round((finVisible - app.tiempoInicioActual) * app.Fs) + 1);
            
                    % Extraer el segmento de datos y tiempo para la etiqueta actual
                    segmentoDatos = app.datosVisualizar(idxInicio:idxFin);
                    segmentoTiempo = app.tiempoVisualizar(idxInicio:idxFin);
            
                    % Seleccionar el color correspondiente al cluster actual
                    color = colores(etiquetasClusters(i), :);
            
                    % Graficar el segmento de datos con el color correspondiente al cluster
                    plot(app.UIAxes, segmentoTiempo, segmentoDatos, 'Color', color, 'LineWidth', 1.5);
                    hold(app.UIAxes, 'on');
                end
            end
        
            % Configura etiquetas y título
            xlabel(app.UIAxes, 'Tiempo (HH:MM:SS)');
            ylabel(app.UIAxes, 'Voltaje (µV)');
            title(app.UIAxes, ['Señal EEG - Canal: ', canalSeleccionado]);
        
            % Ajusta los límites del eje X y define los ticks en formato HH:MM:SS
            app.UIAxes.XLim = [app.tiempoVisualizar(1), app.tiempoVisualizar(end)];
            xticks_array = linspace(app.tiempoVisualizar(1), app.tiempoVisualizar(end), 5);
            xtick_labels = datestr(seconds(xticks_array), 'HH:MM:SS');
        
            app.UIAxes.XTick = xticks_array;
            app.UIAxes.XTickLabel = xtick_labels;
        
            hold(app.UIAxes, 'off');
        
            crearCursorInteractivo(app);
        end


%------------------------------------------------DI------------------------
        function crearCursorInteractivo(app)
            % Limpia cualquier cursor previo
            delete(findobj(app.UIAxes, 'Tag', 'CursorLine'));
            delete(findobj(app.UIAxes, 'Tag', 'CursorText'));
        
            % Configura la función de movimiento del ratón en la figura
            app.AnotacionesnosupervisadasUIFigure.WindowButtonMotionFcn = @(src, event) actualizarCursor(app);
        end
%------------------------------------------------DI------------------------
        function actualizarCursor(app)
            % Obtiene la posición del ratón en los ejes
            cursorPoint = app.UIAxes.CurrentPoint;
            cursorX = cursorPoint(1, 1);
            cursorY = cursorPoint(1, 2);
        
            % Verifica que el cursor esté dentro de los límites de los ejes X e Y
            if cursorX < app.UIAxes.XLim(1) || cursorX > app.UIAxes.XLim(2) || ...
               cursorY < app.UIAxes.YLim(1) || cursorY > app.UIAxes.YLim(2)
                % Si está fuera de los límites, oculta el cursor y el texto
                delete(findobj(app.UIAxes, 'Tag', 'CursorLineX'));
                delete(findobj(app.UIAxes, 'Tag', 'CursorLineY'));
                delete(findobj(app.UIAxes, 'Tag', 'CursorText'));
                return;
            end
        
            % Encuentra el índice de la posición de la señal más cercana al cursor en X
            [~, closestIndex] = min(abs(app.tiempoVisualizar - cursorX));
            cursorYValue = app.datosVisualizar(closestIndex);
        
            % Actualiza o crea la línea del cursor en la posición X e Y
            cursorLineX = findobj(app.UIAxes, 'Tag', 'CursorLineX');
            cursorLineY = findobj(app.UIAxes, 'Tag', 'CursorLineY');
            
            if isempty(cursorLineX)
                cursorLineX = line(app.UIAxes, [cursorX cursorX], app.UIAxes.YLim, 'Color', 'black', 'LineStyle', '--', 'Tag', 'CursorLineX');
                cursorLineY = line(app.UIAxes, app.UIAxes.XLim, [cursorY cursorY], 'Color', 'black', 'LineStyle', '--', 'Tag', 'CursorLineY');
            else
                cursorLineX.XData = [cursorX cursorX];
                cursorLineX.YData = app.UIAxes.YLim;
                cursorLineY.XData = app.UIAxes.XLim;
                cursorLineY.YData = [cursorY cursorY];
            end
        
            % Actualiza o crea el texto del cursor con el tiempo y valor
            cursorText = findobj(app.UIAxes, 'Tag', 'CursorText');
            tiempoTexto = datestr(seconds(cursorX), 'HH:MM:SS');
            texto = sprintf('Tiempo: %s\nVoltaje: %.2f µV', tiempoTexto, cursorYValue);
            
            if isempty(cursorText)
                cursorText = text(app.UIAxes, cursorX, cursorY, texto, 'VerticalAlignment', 'bottom', 'Color', 'black', 'Tag', 'CursorText', 'FontSize', 12, 'FontWeight', 'bold');
            else
                cursorText.Position = [cursorX, cursorY];
                cursorText.String = texto;
                cursorText.FontSize = 12;
                cursorText.FontWeight = 'bold';
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, all_channel_features, EEG_a_analizar, Fs, Nombres_Canales_normalizado)
            % Asignar los valores a las propiedades de la aplicación
            app.all_channel_features = all_channel_features;
            app.EEG_a_analizar = EEG_a_analizar;
            app.Fs = Fs;
            app.Nombres_Canales = Nombres_Canales_normalizado;
        
            % Configurar los nombres de los canales en el DropDown
            app.CanalavisualizarDropDown.Items = fieldnames(all_channel_features);
            app.CanalavisualizarDropDown.Value = app.CanalavisualizarDropDown.Items{1};
        
            % Llamar a la función para graficar el canal inicial
            actualizarGrafica(app);
            
        end

        % Value changed function: CanalavisualizarDropDown
        function CanalavisualizarDropDownValueChanged(app, event)
            % Llamar a la función para graficar el canal inicial
            actualizarGrafica(app);
        end

        % Button pushed function: Boton_Anterior
        function Boton_AnteriorButtonPushed(app, event)
           % Disminuye el tiempo de inicio actual en el tiempo de visualización
           app.tiempoInicioActual = app.tiempoInicioActual - app.TiempoavisualizarEditField.Value;
            
           % Asegúrate de que el tiempo de inicio no sea menor que cero
           if app.tiempoInicioActual < 0
               app.tiempoInicioActual = 0;
           end
            
           % Actualiza la gráfica
           actualizarGrafica(app);
        end

        % Button pushed function: Boton_Siguiente
        function Boton_SiguienteButtonPushed(app, event)
           % Incrementa el tiempo de inicio actual en el tiempo de visualización
           app.tiempoInicioActual = app.tiempoInicioActual + app.TiempoavisualizarEditField.Value;
            
           % Limita el tiempo de inicio para no pasar el final de la señal
           tiempoTotal = length(app.EEG_a_analizar) / app.Fs;
           if app.tiempoInicioActual >= tiempoTotal
               app.tiempoInicioActual = tiempoTotal - app.TiempoavisualizarEditField.Value;
           end
            
           % Actualiza la gráfica
           actualizarGrafica(app);
        end

        % Value changed function: TiempoavisualizarEditField
        function TiempoavisualizarEditFieldValueChanged(app, event)
            actualizarGrafica(app);
        end

        % Value changed function: HoradeiniciodelregistroEEGHHMMSSEditField
        function HoradeiniciodelregistroEEGHHMMSSEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: GrillaCheckBox
        function GrillaCheckBoxValueChanged(app, event)
            if app.GrillaCheckBox.Value
                % Si el checkbox está seleccionado, muestra la grilla
                grid(app.UIAxes, 'on');
            else
                % Si el checkbox está deseleccionado, oculta la grilla
                grid(app.UIAxes, 'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AnotacionesnosupervisadasUIFigure = uifigure('Visible', 'off');
            app.AnotacionesnosupervisadasUIFigure.Name = 'Anotaciones no supervisadas';
            app.AnotacionesnosupervisadasUIFigure.Icon = fullfile(pathToMLAPP, 'logouvg.jpg');
            app.UIAxes = uiaxes(app.AnotacionesnosupervisadasUIFigure);
            title(app.UIAxes, 'Señal EEG agrupada')
            xlabel(app.UIAxes, 'Tiempo (HH:MM:SS)')
            ylabel(app.UIAxes, 'Voltaje (uV)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTickLabel = {'-300'; '-200'; '-100'; '0'; '100'; '200'; '300'};
            app.CanalavisualizarDropDownLabel = uilabel(app.AnotacionesnosupervisadasUIFigure);
            app.CanalavisualizarDropDownLabel.Text = 'Canal a visualizar';
            app.CanalavisualizarDropDown = uidropdown(app.AnotacionesnosupervisadasUIFigure);
            app.CanalavisualizarDropDown.Items = {'-'};
            app.CanalavisualizarDropDown.ValueChangedFcn = createCallbackFcn(app, @CanalavisualizarDropDownValueChanged, true);
            app.CanalavisualizarDropDown.Value = '-';
            app.TiempoavisualizarLabel = uilabel(app.AnotacionesnosupervisadasUIFigure);
            app.TiempoavisualizarLabel.Text = 'Tiempo a visualizar';
            app.TiempoavisualizarEditField = uieditfield(app.AnotacionesnosupervisadasUIFigure, 'numeric');
            app.TiempoavisualizarEditField.Limits = [1 9000];
            app.TiempoavisualizarEditField.ValueChangedFcn = createCallbackFcn(app, @TiempoavisualizarEditFieldValueChanged, true);
            app.TiempoavisualizarEditField.Value = 60;
            app.GrillaCheckBox = uicheckbox(app.AnotacionesnosupervisadasUIFigure);
            app.GrillaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrillaCheckBoxValueChanged, true);
            app.GrillaCheckBox.Text = 'Grilla';
            app.Boton_Anterior = uibutton(app.AnotacionesnosupervisadasUIFigure, 'push');
            app.Boton_Anterior.ButtonPushedFcn = createCallbackFcn(app, @Boton_AnteriorButtonPushed, true);
            app.Boton_Anterior.Icon = fullfile(pathToMLAPP, 'Flecha_izquierda.jpg');
            app.Boton_Anterior.Text = '';
            app.Boton_Siguiente = uibutton(app.AnotacionesnosupervisadasUIFigure, 'push');
            app.Boton_Siguiente.ButtonPushedFcn = createCallbackFcn(app, @Boton_SiguienteButtonPushed, true);
            app.Boton_Siguiente.Icon = fullfile(pathToMLAPP, 'Flecha_derecha.jpg');
            app.Boton_Siguiente.Text = '';
            app.VentanaanteriorLabel = uilabel(app.AnotacionesnosupervisadasUIFigure);
            app.VentanaanteriorLabel.Text = 'Ventana anterior';
            app.VentanasiguienteLabel = uilabel(app.AnotacionesnosupervisadasUIFigure);
            app.VentanasiguienteLabel.Text = 'Ventana siguiente';
            app.HoradeiniciodelregistroEEGHHMMSSEditFieldLabel = uilabel(app.AnotacionesnosupervisadasUIFigure);
            app.HoradeiniciodelregistroEEGHHMMSSEditFieldLabel.Text = {'Hora de inicio del registro EEG'; ' HH:MM:SS'};
            app.HoradeiniciodelregistroEEGHHMMSSEditField = uieditfield(app.AnotacionesnosupervisadasUIFigure, 'text');
            app.HoradeiniciodelregistroEEGHHMMSSEditField.ValueChangedFcn = createCallbackFcn(app, @HoradeiniciodelregistroEEGHHMMSSEditFieldValueChanged, true);
            app.HoradeiniciodelregistroEEGHHMMSSEditField.Value = '00:00:00';
            app.sLabel = uilabel(app.AnotacionesnosupervisadasUIFigure);
            app.sLabel.Text = 's';
            % Show the figure after all components are created
            app.AnotacionesnosupervisadasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Anotaciones_no_supervisado(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnotacionesnosupervisadasUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnotacionesnosupervisadasUIFigure)
        end
    end
end