classdef Seleccion_montaje < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DeshacermontajeButton           matlab.ui.control.Button
        QuitarcanalButton               matlab.ui.control.Button
        MontajeDeCanalesListBox         matlab.ui.control.ListBox
        MontajedecanalesListBoxLabel    matlab.ui.control.Label
        AplicarmontajeButton            matlab.ui.control.Button
        AgregarcanalesButton            matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        SeleccindecanalesLabel          matlab.ui.control.Label
        Canal1Canal2Label               matlab.ui.control.Label
        CanalesSeleccionadosListBox     matlab.ui.control.ListBox
        CanalesSeleccionadosparaelMontajeLabel  matlab.ui.control.Label
        CanalesDisponiblesListBox       matlab.ui.control.ListBox
        CanalesDisponiblesListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        EEGData % Estructura o tabla que contiene los datos de los canales EEG
        MontajeDataTable % Tabla para almacenar los montajes
        ChannelNames        % Nombres de los canales para indexación
        FinalMontajeTable
        Fs
    end
    
    methods (Access = public)
        
        function setAvailableChannelsAndData(app, availableChannels, eegData, Fs)
            % Asignar los nombres de los canales a la lista de canales disponibles
            app.CanalesDisponiblesListBox.Items = availableChannels;
            app.Fs = Fs;
            % Asignar la data completa de EEG a la propiedad EEGData
            app.EEGData = eegData;
            
            % Guardar también los nombres de los canales para indexación posterior
            app.ChannelNames = availableChannels;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
        % Obtener el canal seleccionado en "Canales Disponibles"
        selectedChannel = app.CanalesDisponiblesListBox.Value;
        
        % Verificar que haya un canal seleccionado
        if isempty(selectedChannel)
            uialert(app.UIFigure, 'Seleccione un canal para agregar.', 'Error');
            return;
        end
    
        % Obtener los canales ya seleccionados para el montaje
        currentSelected = app.CanalesSeleccionadosListBox.Items;
        
        % Eliminar celdas vacías o en blanco de "Canales Seleccionados para el Montaje"
        currentSelected = currentSelected(~cellfun('isempty', currentSelected));
        
        % Verificar si ya hay dos canales en "Canales Seleccionados para el Montaje"
        if length(currentSelected) >= 2
            uialert(app.UIFigure, 'Solo se pueden seleccionar hasta dos canales para el montaje.', 'Error');
            return;
        end
    
        % Añadir el canal seleccionado a "Canales Seleccionados para el Montaje"
        app.CanalesSeleccionadosListBox.Items = [currentSelected; selectedChannel];
        end

        % Button pushed function: AgregarcanalesButton
        function AgregarcanalesButtonPushed(app, event)
            % Obtener los canales seleccionados en "Canales Seleccionados para el Montaje"
            selectedChannels = app.CanalesSeleccionadosListBox.Items;
            
            % Validar que haya exactamente dos canales seleccionados
            if length(selectedChannels) ~= 2
                uialert(app.UIFigure, 'Debe seleccionar exactamente dos canales para crear un montaje.', 'Error');
                return;
            end
            
            % Buscar los índices de los canales seleccionados en la lista de nombres de canales
            idx1 = find(strcmp(app.ChannelNames, selectedChannels{1}));
            idx2 = find(strcmp(app.ChannelNames, selectedChannels{2}));
            
            if isempty(idx1) || isempty(idx2)
                uialert(app.UIFigure, 'Uno o ambos canales no se encontraron en los datos EEG.', 'Error');
                return;
            end
            
            % Obtener la data de los canales usando sus índices
            canal1Data = app.EEGData(idx1, :);  % Asegúrate de que las filas representan canales
            canal2Data = app.EEGData(idx2, :);
            
            % Realizar la resta entre los canales
            montageData = canal1Data - canal2Data;
            
            % Crear una tabla si no existe, o agregar una nueva columna para el montaje
            if isempty(app.MontajeDataTable)
                app.MontajeDataTable = table(montageData', 'VariableNames', {sprintf('%s - %s', selectedChannels{1}, selectedChannels{2})});
            else
                app.MontajeDataTable.(sprintf('%s - %s', selectedChannels{1}, selectedChannels{2})) = montageData';
            end
            
            % Actualizar la tabla de visualización
            currentMontages = app.MontajeDeCanalesListBox.Items;
            currentMontages{end+1} = sprintf('%s - %s', selectedChannels{1}, selectedChannels{2});
            app.MontajeDeCanalesListBox.Items = currentMontages;
            
            % Limpiar la selección de "Canales Seleccionados para el Montaje" para agregar nuevos canales
            app.CanalesSeleccionadosListBox.Items = {};
        end

        % Button pushed function: QuitarcanalButton
        function QuitarcanalButtonPushed(app, event)
           % Obtener el canal seleccionado en "Canales Seleccionados para el Montaje"
            selectedChannel = app.CanalesSeleccionadosListBox.Value;
            
            % Verificar que haya un canal seleccionado
            if isempty(selectedChannel)
                uialert(app.UIFigure, 'Seleccione un canal para quitar.', 'Error');
                return;
            end
        
            % Eliminar el canal de "Canales Seleccionados para el Montaje"
            selectedChannels = app.CanalesSeleccionadosListBox.Items;
            selectedChannels(strcmp(selectedChannels, selectedChannel)) = [];
            app.CanalesSeleccionadosListBox.Items = selectedChannels;
        end

        % Button pushed function: DeshacermontajeButton
        function DeshacermontajeButtonPushed(app, event)
            % Obtener el montaje seleccionado en "Montaje de Canales"
            selectedMontage = app.MontajeDeCanalesListBox.Value;
            
            % Verificar que haya un montaje seleccionado
            if isempty(selectedMontage)
                uialert(app.UIFigure, 'Seleccione un montaje para deshacer.', 'Error');
                return;
            end
        
            % Obtener la lista actual de montajes en "Montaje de Canales"
            currentMontages = app.MontajeDeCanalesListBox.Items;
            
            % Eliminar el montaje seleccionado de la lista "Montaje de Canales"
            currentMontages(strcmp(currentMontages, selectedMontage)) = [];
            app.MontajeDeCanalesListBox.Items = currentMontages;
            
            % Eliminar el montaje de `FinalMontajeTable` si existe
            if ismember(selectedMontage, app.FinalMontajeTable.Properties.VariableNames)
                app.FinalMontajeTable = removevars(app.FinalMontajeTable, selectedMontage);
            end
        
            % Actualizar la visualización para reflejar los cambios
            if isempty(currentMontages)
                % Si no hay montajes restantes, cierra las figuras abiertas
                close(findobj('Type', 'figure'));
            else
                % Volver a dibujar el montaje si hay otros montajes en la lista
                AplicarMontajeButtonPushed(app, event);
            end
        end

        % Button pushed function: AplicarmontajeButton
        function AplicarmontajeButtonPushed(app, event)
            % Verificar si hay montajes en la lista
            if isempty(app.MontajeDeCanalesListBox.Items)
                uialert(app.UIFigure, 'No hay montajes seleccionados para aplicar.', 'Error');
                return;
            end
            
            % Crear una nueva tabla para almacenar los datos de los montajes si no existe
            if isempty(app.FinalMontajeTable)
                app.FinalMontajeTable = table();
            end
        
            % Iterar sobre cada montaje en la lista de "Montaje de canales"
            for i = 1:length(app.MontajeDeCanalesListBox.Items)
                % Obtener el nombre del montaje (e.g., "FP1 - F3")
                montageName = app.MontajeDeCanalesListBox.Items{i};
                
                % Separar los nombres de los canales (e.g., "FP1 - F3" -> "FP1" y "F3")
                channels = split(montageName, ' - ');
                
                % Encontrar los índices de los canales en `app.ChannelNames`
                idx1 = find(strcmp(app.ChannelNames, strtrim(channels{1})));
                idx2 = find(strcmp(app.ChannelNames, strtrim(channels{2})));
                
                % Obtener los datos de los canales usando sus índices
                canal1Data = app.EEGData(idx1, :);  % Asegúrate de que las filas representan canales
                canal2Data = app.EEGData(idx2, :);
        
                % Realizar la resta entre los canales para obtener el montaje
                montageData = canal1Data - canal2Data;
        
                % Agregar el montaje como una nueva columna en `FinalMontajeTable`
                app.FinalMontajeTable.(montageName) = montageData';
            end
            
            % Crear un vector de tiempo en segundos basado en la cantidad de muestras en los datos
            numSamples = size(app.EEGData, 2);
            timeVector = (0:numSamples-1) / app.Fs;  % Asegúrate de que `app.Fs` sea la frecuencia de muestreo
            
            % Convertir el vector de tiempo a formato HH:MM:SS
            timeDuration = duration(0, 0, timeVector, 'Format', 'hh:mm:ss');
            app.FinalMontajeTable.Time = timeDuration';  % Agregar el tiempo en formato HH:MM:SS como una columna en la tabla
        
            % Dividir en dos grupos si hay más de 12 canales
            numChannels = width(app.FinalMontajeTable) - 1;  % Excluyendo la columna 'Time'
            if numChannels > 12
                % Crear el primer stackedplot con los primeros 12 canales
                figure;
                stackedplot(app.FinalMontajeTable(:, [{'Time'}, app.FinalMontajeTable.Properties.VariableNames(1:12)]), 'XVariable', 'Time');
                title('Montaje Aplicado - Primer Grupo de Canales');
        
                % Crear el segundo stackedplot con los canales restantes
                figure;
                stackedplot(app.FinalMontajeTable(:, [{'Time'}, app.FinalMontajeTable.Properties.VariableNames(13:end)]), 'XVariable', 'Time');
                title('Montaje Aplicado - Segundo Grupo de Canales');
            else
                % Si hay 12 o menos canales, crear solo un stackedplot
                figure;
                stackedplot(app.FinalMontajeTable, 'XVariable', 'Time');
                title('Montaje Aplicado - Todos los Canales');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CanalesDisponiblesListBoxLabel = uilabel(app.UIFigure);
            app.CanalesDisponiblesListBoxLabel.Text = {'Canales '; 'Disponibles'};
            app.CanalesDisponiblesListBox = uilistbox(app.UIFigure);
            app.CanalesDisponiblesListBox.Items = {''};
            app.CanalesDisponiblesListBox.Multiselect = 'on';
            app.CanalesDisponiblesListBox.Value = {};
            app.CanalesSeleccionadosparaelMontajeLabel = uilabel(app.UIFigure);
            app.CanalesSeleccionadosparaelMontajeLabel.Text = {'Canales Seleccionados '; 'para el Montaje'};
            app.CanalesSeleccionadosListBox = uilistbox(app.UIFigure);
            app.CanalesSeleccionadosListBox.Items = {''};
            app.CanalesSeleccionadosListBox.Multiselect = 'on';
            app.CanalesSeleccionadosListBox.Value = {};
            app.Canal1Canal2Label = uilabel(app.UIFigure);
            app.Canal1Canal2Label.Text = 'Canal 1 - Canal 2';
            app.SeleccindecanalesLabel = uilabel(app.UIFigure);
            app.SeleccindecanalesLabel.Text = 'Selección de canales';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.AgregarcanalesButton = uibutton(app.UIFigure, 'push');
            app.AgregarcanalesButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarcanalesButtonPushed, true);
            app.AgregarcanalesButton.Text = 'Agregar canales';
            app.AplicarmontajeButton = uibutton(app.UIFigure, 'push');
            app.AplicarmontajeButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarmontajeButtonPushed, true);
            app.AplicarmontajeButton.Text = 'Aplicar montaje';
            app.MontajedecanalesListBoxLabel = uilabel(app.UIFigure);
            app.MontajedecanalesListBoxLabel.Text = 'Montaje de canales';
            app.MontajeDeCanalesListBox = uilistbox(app.UIFigure);
            app.MontajeDeCanalesListBox.Items = {};
            app.MontajeDeCanalesListBox.Value = {};
            app.QuitarcanalButton = uibutton(app.UIFigure, 'push');
            app.QuitarcanalButton.ButtonPushedFcn = createCallbackFcn(app, @QuitarcanalButtonPushed, true);
            app.QuitarcanalButton.Text = 'Quitar canal';
            app.DeshacermontajeButton = uibutton(app.UIFigure, 'push');
            app.DeshacermontajeButton.ButtonPushedFcn = createCallbackFcn(app, @DeshacermontajeButtonPushed, true);
            app.DeshacermontajeButton.Text = 'Deshacer montaje';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Seleccion_montaje

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end