classdef admin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdministrarusuariosUIFigure   matlab.ui.Figure
        Label                         matlab.ui.control.Label
        CerrarButton                  matlab.ui.control.Button
        AdministracindeusuariosLabel  matlab.ui.control.Label
        DesbloquearButton             matlab.ui.control.Button
        BloquearButton                matlab.ui.control.Button
        EliminarButton                matlab.ui.control.Button
        NuevoButton                   matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp;
        datos;
        Conexion;
        p = table();
        NuevoUsuario;
        uselected = [];
        Eliminar;
        Editar;
        Usuario;
    end
    
    methods (Access = public)
        
        function ActivarNuevoU(app)
            app.NuevoButton.Enable = 'on';
        end
        
        function deleteu(app)
            for n = 1:length(app.uselected)
                conn = app.Conexion;

                v = app.uselected(n);
                u = table2array(app.p(v,'Var2'));
                query = ['DROP USER ' char(u)];
                execute(conn, query); 
                query2 = strcat("DELETE FROM humana.usuarios WHERE usuario = '", u);
                query2 = strcat(query2, "'");
                execute(conn, query2);
            end
            actualizart(app);
        end
        
        function actualizart(app)
            con = app.Conexion;
            
            temp_estado = "";
            
            app.p = table();
            app.UITable.Data = {};
            
            query = ['SELECT u.User, u.account_locked, u2.tipo FROM mysql.user u, humana.usuarios u2' ...
                '  WHERE u.User NOT LIKE "mysql%"  AND u.User = u2.usuario AND u.User NOT IN ("root","' char(app.Usuario) '")'];
           
            data = fetch(con,query);
            
            dimension=size(data);
            for i=1:dimension(1)
                if string(data.account_locked(i)) == 'N'
                    temp_estado = 'S';
                else
                    temp_estado = 'N';
                end
                app.p = [app.p; {i, string(data.User(i)), string(data.tipo(i)), temp_estado, false}];
            end
            app.UITable.Data = app.p;
            app.uselected = [];
            
            fig = app.AdministrarusuariosUIFigure;
            message = 'Usuario creado/modificado exitosamente. ';
            uialert(fig ,message,'Aviso','Icon','success'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con, user)
            movegui(app.AdministrarusuariosUIFigure,"center");
            app.CallingApp = mainapp;
            app.Conexion = con;
            app.Usuario = user;
            
            temp_estado = "";
            
            query = ['SELECT u.User, u.account_locked, u2.tipo FROM mysql.user u, humana.usuarios u2' ...
                '  WHERE u.User NOT LIKE "mysql%"  AND u.User = u2.usuario AND u.User NOT IN ("root","' char(app.Usuario) '")'];
           
            data = fetch(con,query);
            
            dimension=size(data);
            for i=1:dimension(1)
                if string(data.account_locked(i)) == 'N'
                    temp_estado = 'S';
                else
                    temp_estado = 'N';
                end
                app.p = [app.p; {i, string(data.User(i)), string(data.tipo(i)), temp_estado, false}];
            end
            app.UITable.Data = app.p;
        end

        % Button pushed function: NuevoButton
        function NuevoButtonPushed(app, event)
%             app.NuevoButton.Enable = 'off';
            app.NuevoUsuario = nuevou(app, app.Conexion);
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            ActivarAdminU(app.CallingApp);
            delete(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            %newData = event.NewData;
            if ismember(indices(1), app.uselected)
                %eliminar del array
                app.uselected = app.uselected(app.uselected~=indices(1));
            else
                app.uselected(end+1) = indices(1);
            end
            %app.uselected
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            if ~isempty(app.uselected)
                app.Eliminar = eliminaru(app);
            else
                fig = app.AdministrarusuariosUIFigure;
                message = 'No se ha seleccionado ningún usuario. ';
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
            end
        end

        % Button pushed function: BloquearButton
        function BloquearButtonPushed(app, event)
            if ~isempty(app.uselected)
                for n = 1:length(app.uselected)
                    conn = app.Conexion;
    
                    v = app.uselected(n);
                    u = table2array(app.p(v,'Var2'));
                    query = strcat("ALTER USER ", u);
                    query = strcat(query, " ACCOUNT LOCK");
                    execute(conn, query);
                end
                actualizart(app);
            else
                fig = app.AdministrarusuariosUIFigure;
                message = 'No se ha seleccionado ningún usuario para bloquear. ';
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
            end
        end

        % Button pushed function: DesbloquearButton
        function DesbloquearButtonPushed(app, event)
            if ~isempty(app.uselected)
                for n = 1:length(app.uselected)
                    conn = app.Conexion;
    
                    v = app.uselected(n);
                    u = table2array(app.p(v,'Var2'));
                    query = strcat("ALTER USER ", u);
                    query = strcat(query, " ACCOUNT UNLOCK");
                    execute(conn, query);
                end
                actualizart(app);
            else
                fig = app.AdministrarusuariosUIFigure;
                message = 'No se ha seleccionado ningún usuario para desbloquear. ';
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            u = table2array(app.p(indices(1),'Var2'));
            
            %Abrir ventana para editar usuario
            app.Editar = editaru(app, app.Conexion, u);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AdministrarusuariosUIFigure = uifigure('Visible', 'off');
            app.AdministrarusuariosUIFigure.Name = 'Administrar usuarios';
            app.AdministrarusuariosUIFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.UITable = uitable(app.AdministrarusuariosUIFigure);
            app.UITable.ColumnName = {'#'; 'Usuario'; 'Tipo'; 'Activo'; 'Seleccionar'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false false];
            app.UITable.ColumnEditable = [false false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.NuevoButton = uibutton(app.AdministrarusuariosUIFigure, 'push');
            app.NuevoButton.ButtonPushedFcn = createCallbackFcn(app, @NuevoButtonPushed, true);
            app.NuevoButton.Text = 'Nuevo';
            app.EliminarButton = uibutton(app.AdministrarusuariosUIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.BloquearButton = uibutton(app.AdministrarusuariosUIFigure, 'push');
            app.BloquearButton.ButtonPushedFcn = createCallbackFcn(app, @BloquearButtonPushed, true);
            app.BloquearButton.Text = 'Bloquear';
            app.DesbloquearButton = uibutton(app.AdministrarusuariosUIFigure, 'push');
            app.DesbloquearButton.ButtonPushedFcn = createCallbackFcn(app, @DesbloquearButtonPushed, true);
            app.DesbloquearButton.Text = 'Desbloquear';
            app.AdministracindeusuariosLabel = uilabel(app.AdministrarusuariosUIFigure);
            app.AdministracindeusuariosLabel.Text = 'Administración de usuarios';
            app.CerrarButton = uibutton(app.AdministrarusuariosUIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.Label = uilabel(app.AdministrarusuariosUIFigure);
            app.Label.Text = '*Para cambiar contraseña, editar rol y/o permisos de cualquier usuario presiona sobre cualquier campo del usuario en la tabla.';
            % Show the figure after all components are created
            app.AdministrarusuariosUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = admin(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AdministrarusuariosUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AdministrarusuariosUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdministrarusuariosUIFigure)
        end
    end
end