classdef agregarpac < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NuevoPaciente                   matlab.ui.Figure
        CamposconfidencialesSernvisiblesunicamentLabel  matlab.ui.control.Label
        NotasconfidencialesTextArea     matlab.ui.control.TextArea
        NotasconfidencialesLabel        matlab.ui.control.Label
        ApellidosEditField              matlab.ui.control.EditField
        ApellidosLabel                  matlab.ui.control.Label
        NombresEditField                matlab.ui.control.EditField
        NombresLabel                    matlab.ui.control.Label
        FechadenacimientoDatePicker     matlab.ui.control.DatePicker
        FechadenacimientoDatePickerLabel  matlab.ui.control.Label
        RegresarButton                  matlab.ui.control.Button
        NuevoButton                     matlab.ui.control.Button
        GuardarButton                   matlab.ui.control.Button
        CondicindelpacienteButtonGroup  matlab.ui.container.ButtonGroup
        PadecedeepilepsiaButton         matlab.ui.control.RadioButton
        NopadecedeepilepsiaButton       matlab.ui.control.RadioButton
        DiagnsticodeprescripcinTextArea  matlab.ui.control.TextArea
        DiagnsticodeprescripcinTextAreaLabel  matlab.ui.control.Label
        AadirnuevopacienteLabel         matlab.ui.control.Label
        AntecedentespersonalespatolgicoTextArea  matlab.ui.control.TextArea
        AntecedentespersonalespatolgicoTextAreaLabel  matlab.ui.control.Label
        SexoButtonGroup                 matlab.ui.container.ButtonGroup
        MButton                         matlab.ui.control.RadioButton
        FButton                         matlab.ui.control.RadioButton
        CdigodepacienteEditField        matlab.ui.control.EditField
        CdigodepacienteEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Conexion
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            movegui(app.NuevoPaciente,"center");
            app.CallingApp = mainapp;
            app.Conexion = con;
        end

        % Close request function: NuevoPaciente
        function NuevoPacienteCloseRequest(app, event)
            % Enable the Plot Opions button in main app
%             app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
%             app.CallingApp.ConsultarButton.Enable = 'on';
%             app.CallingApp.AnalizarpruebaButton.Enable = 'on';
%             app.CallingApp.DesconectarButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
%             app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
%             app.CallingApp.ConsultarButton.Enable = 'on';
%             app.CallingApp.AnalizarpruebaButton.Enable = 'on';
%             app.CallingApp.DesconectarButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            id = app.CdigodepacienteEditField.Value;
            sexo = app.SexoButtonGroup.SelectedObject.Text;
            try
                fenac = datestr(app.FechadenacimientoDatePicker.Value,29);
            catch ME
                fenac = '';
            end
            
            antpat = app.AntecedentespersonalespatolgicoTextArea.Value;
            diagn = app.DiagnsticodeprescripcinTextArea.Value;
            cond = app.CondicindelpacienteButtonGroup.SelectedObject.Text;
            conf = app.NotasconfidencialesTextArea.Value;
            nombre = app.NombresEditField.Value;
            apellido = app.ApellidosEditField.Value;
            
            switch cond
                case 'No padece de epilepsia'
                    enf = 'SANO';
                case 'Padece de epilepsia'
                    enf = 'ENFERMO';
                otherwise
                    enf = '';
            end
            
            % Realizar búsqueda de la base de datos
            conn = app.Conexion;
            query = ['SELECT * ' ...
                'FROM humana.pacientes WHERE pacientes.id_paciente= "' ...
                id...
                '"'];
            data = fetch(conn,query);
            
            
            if((isempty(id)==1) || (isempty(fenac)==1) || (isempty(enf)==1) || (isempty(nombre)==1) || (isempty(apellido)==1) || (isempty(conf)==1))
                errordlg('Ingrese todos los campos.','Error');
            else
                if(isempty(data)==0)
                    errordlg('ID Paciente ya existente','Error');
                else
                    % nuevo={id,nombre,apellido,conf{1,1},sexo,fenac,antpat{1,1},diagn{1,1},enf};
                    % columnas={'id_paciente','nombre','apellido','notas_confidenciales','sexo','fecha_nacimiento','antecedentes','diagnostico','condicion'};
                    % insert(conn,'humana.pacientes',columnas,nuevo);
                    % %close(conn);

                    nuevo = {id,nombre,apellido,conf{1,1},sexo,fenac,antpat{1,1},diagn{1,1},enf};      %Limite para pruebas
                    datawrite = cell2table(nuevo); %Convertimos la informacion a una tabla
                    datawrite.Properties.VariableNames = ["id_paciente","nombre","apellido","notas_confidenciales","sexo","fecha_nacimiento","antecedentes","diagnostico","condicion"];
            %Nuevo formato para trabajar sqlwrite

                    sqlwrite(conn, 'humana.pacientes', datawrite); %reemplazar insert por sqlwrite
                    fig = app.NuevoPaciente;
                    message = 'Se ha guardado correctamente.';
                    uialert(fig ,message,'Aviso','Icon','success');

                    clear conn query
                end
            end

        end

        % Button pushed function: NuevoButton
        function NuevoButtonPushed(app, event)
            app.CdigodepacienteEditField.Value = '';
            app.AntecedentespersonalespatolgicoTextArea.Value = '';
            app.DiagnsticodeprescripcinTextArea.Value = '';
            app.NombresEditField.Value = '';
            app.ApellidosEditField.Value = '';
            app.NotasconfidencialesTextArea.Value = '';
            app.FechadenacimientoDatePicker.Value = NaT;
            msgbox('Campos borrados.','Mensaje');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NuevoPaciente = uifigure('Visible', 'off');
            app.NuevoPaciente.Name = 'Nuevo paciente';
            app.NuevoPaciente.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.NuevoPaciente.CloseRequestFcn = createCallbackFcn(app, @NuevoPacienteCloseRequest, true);
            app.CdigodepacienteEditFieldLabel = uilabel(app.NuevoPaciente);
            app.CdigodepacienteEditFieldLabel.Text = 'Código de paciente';
            app.CdigodepacienteEditField = uieditfield(app.NuevoPaciente, 'text');
            app.SexoButtonGroup = uibuttongroup(app.NuevoPaciente);
            app.SexoButtonGroup.Title = 'Sexo';
            app.FButton = uiradiobutton(app.SexoButtonGroup);
            app.FButton.Text = 'F';
            app.FButton.Value = true;
            app.MButton = uiradiobutton(app.SexoButtonGroup);
            app.MButton.Text = 'M';
            app.AntecedentespersonalespatolgicoTextAreaLabel = uilabel(app.NuevoPaciente);
            app.AntecedentespersonalespatolgicoTextAreaLabel.Text = 'Antecedentes personales patológico';
            app.AntecedentespersonalespatolgicoTextArea = uitextarea(app.NuevoPaciente);
            app.AadirnuevopacienteLabel = uilabel(app.NuevoPaciente);
            app.AadirnuevopacienteLabel.Text = 'Añadir nuevo paciente';
            app.DiagnsticodeprescripcinTextAreaLabel = uilabel(app.NuevoPaciente);
            app.DiagnsticodeprescripcinTextAreaLabel.Text = 'Diagnóstico de prescripción';
            app.DiagnsticodeprescripcinTextArea = uitextarea(app.NuevoPaciente);
            app.CondicindelpacienteButtonGroup = uibuttongroup(app.NuevoPaciente);
            app.CondicindelpacienteButtonGroup.Title = 'Condición del paciente';
            app.NopadecedeepilepsiaButton = uiradiobutton(app.CondicindelpacienteButtonGroup);
            app.NopadecedeepilepsiaButton.Text = 'No padece de epilepsia';
            app.NopadecedeepilepsiaButton.Value = true;
            app.PadecedeepilepsiaButton = uiradiobutton(app.CondicindelpacienteButtonGroup);
            app.PadecedeepilepsiaButton.Text = 'Padece de epilepsia';
            app.GuardarButton = uibutton(app.NuevoPaciente, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.NuevoButton = uibutton(app.NuevoPaciente, 'push');
            app.NuevoButton.ButtonPushedFcn = createCallbackFcn(app, @NuevoButtonPushed, true);
            app.NuevoButton.Text = 'Nuevo';
            app.RegresarButton = uibutton(app.NuevoPaciente, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.FechadenacimientoDatePickerLabel = uilabel(app.NuevoPaciente);
            app.FechadenacimientoDatePickerLabel.Text = 'Fecha de nacimiento';
            app.FechadenacimientoDatePicker = uidatepicker(app.NuevoPaciente);
            app.FechadenacimientoDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.NombresLabel = uilabel(app.NuevoPaciente);
            app.NombresLabel.Text = 'Nombre(s)*';
            app.NombresEditField = uieditfield(app.NuevoPaciente, 'text');
            app.ApellidosLabel = uilabel(app.NuevoPaciente);
            app.ApellidosLabel.Text = 'Apellido(s)*';
            app.ApellidosEditField = uieditfield(app.NuevoPaciente, 'text');
            app.NotasconfidencialesLabel = uilabel(app.NuevoPaciente);
            app.NotasconfidencialesLabel.Text = 'Notas confidenciales*';
            app.NotasconfidencialesTextArea = uitextarea(app.NuevoPaciente);
            app.CamposconfidencialesSernvisiblesunicamentLabel = uilabel(app.NuevoPaciente);
            app.CamposconfidencialesSernvisiblesunicamentLabel.Text = '(*) Los campos confidenciales únicamente serán visibles para los usuarios con los permisos correspondientes.';
            % Show the figure after all components are created
            app.NuevoPaciente.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = agregarpac(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NuevoPaciente)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.NuevoPaciente)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NuevoPaciente)
        end
    end
end