classdef anotacionesautomaticas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraficoVentanasInteres          matlab.ui.Figure
        TodoButton                      matlab.ui.control.Button
        PolimrficaCheckBox              matlab.ui.control.CheckBox
        MonomrficaCheckBox              matlab.ui.control.CheckBox
        InterIctalCheckBox              matlab.ui.control.CheckBox
        PreIctalCheckBox                matlab.ui.control.CheckBox
        SanoCheckBox                    matlab.ui.control.CheckBox
        IctalCheckBox                   matlab.ui.control.CheckBox
        DataaexportarLabel              matlab.ui.control.Label
        ExportarButton                  matlab.ui.control.Button
        RecortarsegmentodeintersButton  matlab.ui.control.Button
        GuardarventanadeanlisisButton   matlab.ui.control.Button
        Canalavisualizar2DropDown       matlab.ui.control.DropDown
        CanalavisualizarDropDownLabel_2  matlab.ui.control.Label
        Grilla2CheckBox                 matlab.ui.control.CheckBox
        GraficarSeleccion1              matlab.ui.control.Button
        GenerarmultigrficaLabel         matlab.ui.control.Label
        CanalesSalidaListBox            matlab.ui.control.ListBox
        CanalesListBoxLabel             matlab.ui.control.Label
        PoliColorDropDown               matlab.ui.control.DropDown
        PolimrficaLabel                 matlab.ui.control.Label
        MonoColorDropDown               matlab.ui.control.DropDown
        MonomrficaLabel                 matlab.ui.control.Label
        LeyendadecolorMorfologaLabel    matlab.ui.control.Label
        PoliColorLamp                   matlab.ui.control.Lamp
        MonoColorLamp                   matlab.ui.control.Lamp
        LeyendadecolorTipodesealLabel   matlab.ui.control.Label
        Grilla1CheckBox                 matlab.ui.control.CheckBox
        InterictalColorLamp             matlab.ui.control.Lamp
        PreictalColorLamp               matlab.ui.control.Lamp
        SanoColorLamp                   matlab.ui.control.Lamp
        InterictalColorDropDown         matlab.ui.control.DropDown
        InterictalLabel                 matlab.ui.control.Label
        PreictalColorDropDown           matlab.ui.control.DropDown
        PreictalLabel                   matlab.ui.control.Label
        SanoColorDropDown               matlab.ui.control.DropDown
        SanoDropDownLabel               matlab.ui.control.Label
        IctalColorLamp                  matlab.ui.control.Lamp
        IctalColorDropDown              matlab.ui.control.DropDown
        IctalDropDownLabel              matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        Canalavisualizar1DropDown       matlab.ui.control.DropDown
        CanalavisualizarDropDownLabel   matlab.ui.control.Label
        HoradeiniciodelregistroEEGHHMMSSEditField  matlab.ui.control.EditField
        HoradeiniciodelregistroEEGHHMMSSEditFieldLabel  matlab.ui.control.Label
        TextoAnotacion                  matlab.ui.control.Label
        AnotacionesLabel                matlab.ui.control.Label
        VentanasiguienteLabel           matlab.ui.control.Label
        VentanaanteriorLabel            matlab.ui.control.Label
        Boton_Siguiente                 matlab.ui.control.Button
        Boton_Anterior                  matlab.ui.control.Button
        TiempoMostrar                   matlab.ui.control.NumericEditField
        TiempoavisualizarLabel          matlab.ui.control.Label
        GrficayventanasdeintersdelasealLabel  matlab.ui.control.Label
        EPILEPTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        EEGPlot2                        matlab.ui.control.UIAxes
        EEGPlot1                        matlab.ui.control.UIAxes
    end

        %Incorporación con versión de Cristpoher Patzan
        
    properties (Access = private)
        %App que abre esta app
        CallingApp        
        %Valor del numero de ventanas de 1 segundo posibles
        Cantidad_de_ventanas
        %Vectores con todos los limites inferiores 
        %y superiores para graficar el intervalo completo
        muestras_bot
        muestras_top
        %Vector con el numero de ventanas desplegadas
        no_ventana
        %Vector de tiempo para emplear como vector en x
        vector_tiempo_grafico
        %Canal actual a graficar
        canal_a_desplegar
        
        labels
        signal        
        window_samples
        fs_plot
        vector_ict
        vector_sano 
        Cantidad_de_ventanas_road = 1;
        sweepict = 1;
        sweepsano = 1;
        %Posicion del canal de interes respecto al vector de características
        canal_EEGVI;
        %Colores de las clases para la clasificación supervisada
        ColorIctal = 'r-';
        ColorSano = 'b-';
        ColorPreictal = 'g-';
        ColorInterictal = 'k-';
        %Colores de las clases para la clasificación no supervisada
        ColorMono = 'm-';
        ColorPoli = 'c-';
        %Frecuencia entera
        Fs_entera
    end
    
    properties (Access = public)
        %Variables compartidas entre ventanas 
        Vector_Car_EEGVI
        EEG_VI
        Intervalo_Ventana_s
        Fs
        Tiempo_Inicio
        Vector_Car_EEGMOR
        %Canales analizados
        ch_int
        canales_index
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Se obtienen las propiedades de la ventana anterior
            app.CallingApp = mainapp;    
            %Se llena la lista de canales disponibles a elegir
            app.Canalavisualizar1DropDown.Items = app.CallingApp.ch_int;
            app.Canalavisualizar1DropDown.Value = app.CallingApp.ch_int{1};
            %Se llena la lista de canales (morfología)
            app.Canalavisualizar2DropDown.Items = app.CallingApp.ch_int;
            app.Canalavisualizar2DropDown.Value = app.CallingApp.ch_int{1};
            %Se llena la lista de canales en la List Box            
            app.CanalesSalidaListBox.Items = app.CallingApp.ch_int;
            %El canal a desplegar es el primero de la lista
            app.canal_a_desplegar = app.CallingApp.canales_index(1);
            %La matriz de etiquetas tiene el mismo índice al iniciar
            app.canal_EEGVI = 1; %app.CallingApp.canales_index(1);
            
            %app.HoradeiniciodelregistroEEGHHMMSSEditField.Text = app.Tiempo_Inicio;
            %Preparación a frecuencia entera:
            app.Fs_entera = floor(app.CallingApp.Fs);
            %Guardamos la cantidad de ventanas de 1s disponibles
            app.Cantidad_de_ventanas = length(app.CallingApp.Vector_Car_EEGVI); 
            %Se genera el vector de valores en x para las gráficas
            app.vector_tiempo_grafico = 1:1:app.Fs_entera*app.Cantidad_de_ventanas;
            %Generamos vectores vacíos para guardar todos los valores de
            %muestras para los limites superior e inferior de todas las
            %ventanas
            app.muestras_bot = zeros(1,app.Cantidad_de_ventanas);
            app.muestras_top = zeros(1,app.Cantidad_de_ventanas);
            %Ciclo para asignar los valores de muestras a los vectores
            for i=1:app.Cantidad_de_ventanas
                if i == 1
                    app.muestras_bot(1,i) = 1;
                    app.muestras_top(1,i) = app.Fs_entera*1;
                else
                    app.muestras_bot(1,i) = app.muestras_top(1,i-1)+1;
                    app.muestras_top(1,i) = app.Fs_entera*i;
                end
            end            
            %Se inicia el vector de ventanas a desplegar, inicialmente es
            %[1,2,3,4,5]
            app.no_ventana = 1:1:floor(app.TiempoMostrar.Value);
            %Se llena la listbox con los canales seleccionados para las
            %multigraficas            
            app.CanalesSalidaListBox.Items = app.Canalavisualizar1DropDown.Items;
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
            
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
            
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=double(string(strsplit(hora_inicial,{':',','})));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            
            %Ticks para el tiempo en el eje horizontal
            tick0 = hours(hh)+minutes(mm)+seconds(ss); %Tiempo inicial
            tick1 = tick0 + seconds(app.no_ventana(1)-1);
            tick2 = tick1 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4)+1);
            tick3 = tick2 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick4 = tick3 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick5 = tick4 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1), time_series(floor(length(time_series)/4)),...
                            time_series(floor(length(time_series)/2)),time_series(3*floor(length(time_series)/4)),...
                            time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            app.EEGPlot1.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),...
                datestr(tick3,'HH:MM:SS'),datestr(tick4,'HH:MM:SS'),datestr(tick5,'HH:MM:SS')};
            
            app.EEGPlot1.XLim = [time_series(1),time_series(end)];
            app.EEGPlot2.XTick = app.EEGPlot1.XTick;
            app.EEGPlot2.XTickLabel = app.EEGPlot1.XTickLabel;
            app.EEGPlot2.XLim = app.EEGPlot1.XLim;
        end

        % Button pushed function: Boton_Anterior
        function Boton_AnteriorButtonPushed(app, event)
            segmentos_entero = floor(app.TiempoMostrar.Value);
            array_ventanas = sort(app.no_ventana(1)-1:-1:app.no_ventana(1)-1-(segmentos_entero-1));
            if array_ventanas(1)<1
                %Mostrar alerta que se excede ya la señal y se queda igual
                %disp('ya valio');
                %Ir al final de la señal
                array_ventanas = sort(app.Cantidad_de_ventanas:-1:app.Cantidad_de_ventanas-(segmentos_entero-1));
            end
            app.no_ventana = array_ventanas;
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
            
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
            
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=double(string(strsplit(hora_inicial,{':',','})));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            
            %Ticks para el tiempo en el eje horizontal
            tick0 = hours(hh)+minutes(mm)+seconds(ss); %Tiempo inicial
            tick1 = tick0 + seconds(app.no_ventana(1)-1);
            tick2 = tick1 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4)+1);
            tick3 = tick2 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick4 = tick3 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick5 = tick4 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1), time_series(floor(length(time_series)/4)),...
                            time_series(floor(length(time_series)/2)),time_series(3*floor(length(time_series)/4)),...
                            time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            app.EEGPlot1.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),...
                datestr(tick3,'HH:MM:SS'),datestr(tick4,'HH:MM:SS'),datestr(tick5,'HH:MM:SS')};
            
            app.EEGPlot1.XLim = [time_series(1),time_series(end)];
            app.EEGPlot2.XTick = app.EEGPlot1.XTick;
            app.EEGPlot2.XTickLabel = app.EEGPlot1.XTickLabel;
            app.EEGPlot2.XLim = app.EEGPlot1.XLim;
        end

        % Button pushed function: Boton_Siguiente
        function Boton_SiguienteButtonPushed(app, event)
            segmentos_entero = floor(app.TiempoMostrar.Value);
            array_ventanas = app.no_ventana(end)+1:1:app.no_ventana(end)+1+(segmentos_entero-1);
            if array_ventanas(end)>app.Cantidad_de_ventanas
                %Mostrar alerta que se excede ya la señal y se queda igual
                %disp('ya valio');
                %U volver al inicio de la señal
                array_ventanas = 1:1:1+(segmentos_entero-1);    
            end
            app.no_ventana = array_ventanas;
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
            
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
            
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=double(string(strsplit(hora_inicial,{':',','})));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            
            %Ticks para el tiempo en el eje horizontal
            tick0 = hours(hh)+minutes(mm)+seconds(ss); %Tiempo inicial
            tick1 = tick0 + seconds(app.no_ventana(1)-1);
            tick2 = tick1 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4)+1);
            tick3 = tick2 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick4 = tick3 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick5 = tick4 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1), time_series(floor(length(time_series)/4)),...
                            time_series(floor(length(time_series)/2)),time_series(3*floor(length(time_series)/4)),...
                            time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            app.EEGPlot1.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),...
                datestr(tick3,'HH:MM:SS'),datestr(tick4,'HH:MM:SS'),datestr(tick5,'HH:MM:SS')};
            
            app.EEGPlot1.XLim = [time_series(1),time_series(end)];
            app.EEGPlot2.XTick = app.EEGPlot1.XTick;
            app.EEGPlot2.XTickLabel = app.EEGPlot1.XTickLabel;
            app.EEGPlot2.XLim = app.EEGPlot1.XLim;
        end

        % Value changed function: TiempoMostrar
        function TiempoMostrarValueChanged(app, event)
            segmentos_entero = floor(app.TiempoMostrar.Value);
            array_ventanas = app.no_ventana(1):1:app.no_ventana(1)+(segmentos_entero-1);
            %cantidad_windows = app.Cantidad_de_ventanas; %----(CP)----
            if array_ventanas(end)>app.Cantidad_de_ventanas
                %Mostrar alerta que se excede ya la señal y se queda igual
                disp('ya valio');
            else
                app.no_ventana = array_ventanas;
            end
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
            
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
            
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=double(string(strsplit(hora_inicial,{':',','})));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            
            %Ticks para el tiempo en el eje horizontal
            tick0 = hours(hh)+minutes(mm)+seconds(ss); %Tiempo inicial
            tick1 = tick0 + seconds(app.no_ventana(1)-1);
            tick2 = tick0 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4)+1);
            tick3 = tick2 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick4 = tick3 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick5 = tick4 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1), time_series(floor(length(time_series)/4)),...
                            time_series(floor(length(time_series)/2)),time_series(3*floor(length(time_series)/4)),...
                            time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            app.EEGPlot1.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),...
                datestr(tick3,'HH:MM:SS'),datestr(tick4,'HH:MM:SS'),datestr(tick5,'HH:MM:SS')};
            
            app.EEGPlot1.XLim = [time_series(1),time_series(end)];
            app.EEGPlot2.XTick = app.EEGPlot1.XTick;
            app.EEGPlot2.XTickLabel = app.EEGPlot1.XTickLabel;
            app.EEGPlot2.XLim = app.EEGPlot1.XLim;
        end

        % Value changed function: HoradeiniciodelregistroEEGHHMMSSEditField
        function HoradeiniciodelregistroEEGHHMMSSEditFieldValueChanged(app, event)
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=double(string(strsplit(hora_inicial,{':',','})));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            
            %Ticks para el tiempo en el eje horizontal
            tick0 = hours(hh)+minutes(mm)+seconds(ss); %Tiempo inicial
            tick1 = tick0 + seconds(app.no_ventana(1)-1);
            tick2 = tick1 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4)+1);
            tick3 = tick2 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick4 = tick3 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick5 = tick4 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1), time_series(floor(length(time_series)/4)),...
                            time_series(floor(length(time_series)/2)),time_series(3*floor(length(time_series)/4)),...
                            time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            app.EEGPlot1.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),...
                datestr(tick3,'HH:MM:SS'),datestr(tick4,'HH:MM:SS'),datestr(tick5,'HH:MM:SS')};
            
            app.EEGPlot1.XLim = [time_series(1),time_series(end)];
            app.EEGPlot2.XTick = app.EEGPlot1.XTick;
            app.EEGPlot2.XTickLabel = app.EEGPlot1.XTickLabel;
            app.EEGPlot2.XLim = app.EEGPlot1.XLim;
        end

        % Value changed function: Canalavisualizar1DropDown
        function Canalavisualizar1DropDownValueChanged(app, event)
            %Se obtiene el nombre del nuevo canal a desplegar
            canal_nuevo = app.Canalavisualizar1DropDown.Value; 
            %Se verifica el índice de este canal respecto al listado de
            %canales procesados
            indice_canales = ismember(app.Canalavisualizar1DropDown.Items,canal_nuevo);
            for n=1:length(indice_canales)
                if indice_canales(n)==1
                    app.canal_EEGVI = n;
                end
            end
            app.canal_a_desplegar = app.CallingApp.canales_index(app.canal_EEGVI);
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
            
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
        end

        % Value changed function: IctalColorDropDown
        function IctalColorDropDownValueChanged(app, event)
            app.ColorIctal = app.IctalColorDropDown.Value;
            if app.ColorIctal == 'b'
                app.IctalColorLamp.Color = [0 0 1];
            elseif app.ColorIctal == 'k'
                app.IctalColorLamp.Color = [0 0 0];
            elseif app.ColorIctal == 'g'
                app.IctalColorLamp.Color = [0 1 0];
            elseif app.ColorIctal == 'r'
                app.IctalColorLamp.Color = [1 0 0];
            elseif app.ColorIctal == 'y'
                app.IctalColorLamp.Color = [1 1 0];
            elseif app.ColorIctal == 'c'
                app.IctalColorLamp.Color = [0 1 1];
            elseif app.ColorIctal == 'm'
                app.IctalColorLamp.Color = [1 0 1];
            else
                app.IctalColorLamp.Color = [1 1 1];
            end
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
        end

        % Value changed function: SanoColorDropDown
        function SanoColorDropDownValueChanged(app, event)
            %delete = app.canal_EEGVI;  %----(CP)----
            %ventanas = app.no_ventana; %----(CP)----
            app.ColorSano = app.SanoColorDropDown.Value;
            if app.ColorSano == 'b'
                app.SanoColorLamp.Color = [0 0 1];
            elseif app.ColorSano == 'k'
                app.SanoColorLamp.Color = [0 0 0];
            elseif app.ColorSano == 'g'
                app.SanoColorLamp.Color = [0 1 0];
            elseif app.ColorSano == 'r'
                app.SanoColorLamp.Color = [1 0 0];
            elseif app.ColorSano == 'y'
                app.SanoColorLamp.Color = [1 1 0];
            elseif app.ColorSano == 'c'
                app.SanoColorLamp.Color = [0 1 1];
            elseif app.ColorSano == 'm'
                app.SanoColorLamp.Color = [1 0 1];
            else
                app.SanoColorLamp.Color = [1 1 1];
            end
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
        end

        % Value changed function: PreictalColorDropDown
        function PreictalColorDropDownValueChanged(app, event)
            app.ColorPreictal = app.PreictalColorDropDown.Value;
            if app.ColorPreictal == 'b'
                app.PreictalColorLamp.Color = [0 0 1];
            elseif app.ColorPreictal == 'k'
                app.PreictalColorLamp.Color = [0 0 0];
            elseif app.ColorPreictal == 'g'
                app.PreictalColorLamp.Color = [0 1 0];
            elseif app.ColorPreictal == 'r'
                app.PreictalColorLamp.Color = [1 0 0];
            elseif app.ColorPreictal == 'y'
                app.PreictalColorLamp.Color = [1 1 0];
            elseif app.ColorPreictal == 'c'
                app.PreictalColorLamp.Color = [0 1 1];
            elseif app.ColorPreictal == 'm'
                app.PreictalColorLamp.Color = [1 0 1];
            else
                app.PreictalColorLamp.Color = [1 1 1];
            end
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
        end

        % Value changed function: InterictalColorDropDown
        function InterictalColorDropDownValueChanged(app, event)
            app.ColorInterictal = app.InterictalColorDropDown.Value;
            if app.ColorInterictal == 'b'
                app.InterictalColorLamp.Color = [0 0 1];
            elseif app.ColorInterictal == 'k'
                app.InterictalColorLamp.Color = [0 0 0];
            elseif app.ColorInterictal == 'g'
                app.InterictalColorLamp.Color = [0 1 0];
            elseif app.ColorInterictal == 'r'
                app.InterictalColorLamp.Color = [1 0 0];
            elseif app.ColorInterictal == 'y'
                app.InterictalColorLamp.Color = [1 1 0];
            elseif app.ColorInterictal == 'c'
                app.InterictalColorLamp.Color = [0 1 1];
            elseif app.ColorInterictal == 'm'
                app.InterictalColorLamp.Color = [1 0 1];
            else
                app.InterictalColorLamp.Color = [1 1 1];
            end
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
        end

        % Value changed function: Grilla1CheckBox
        function Grilla1CheckBoxValueChanged(app, event)
            value = app.Grilla1CheckBox.Value;
            if value == 1  
                grid(app.EEGPlot1, 'on')
            else
                grid(app.EEGPlot1, 'off')
            end
        end

        % Value changed function: MonoColorDropDown
        function MonoColorDropDownValueChanged(app, event)
            app.ColorMono = app.MonoColorDropDown.Value;
            if app.ColorMono == 'b'
                app.MonoColorLamp.Color = [0 0 1];
            elseif app.ColorMono == 'k'
                app.MonoColorLamp.Color = [0 0 0];
            elseif app.ColorMono == 'g'
                app.MonoColorLamp.Color = [0 1 0];
            elseif app.ColorMono == 'r'
                app.MonoColorLamp.Color = [1 0 0];
            elseif app.ColorMono == 'y'
                app.MonoColorLamp.Color = [1 1 0];
            elseif app.ColorMono == 'c'
                app.MonoColorLamp.Color = [0 1 1];
            elseif app.ColorMono == 'm'
                app.MonoColorLamp.Color = [1 0 1];
            else
                app.MonoColorLamp.Color = [1 1 1];
            end
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
        end

        % Value changed function: PoliColorDropDown
        function PoliColorDropDownValueChanged(app, event)
            app.ColorPoli = app.PoliColorDropDown.Value;
            if app.ColorPoli == 'b'
                app.PoliColorLamp.Color = [0 0 1];
            elseif app.ColorPoli == 'k'
                app.PoliColorLamp.Color = [0 0 0];
            elseif app.ColorPoli == 'g'
                app.PoliColorLamp.Color = [0 1 0];
            elseif app.ColorPoli == 'r'
                app.PoliColorLamp.Color = [1 0 0];
            elseif app.ColorPoli == 'y'
                app.PoliColorLamp.Color = [1 1 0];
            elseif app.ColorPoli == 'c'
                app.PoliColorLamp.Color = [0 1 1];
            elseif app.ColorPoli == 'm'
                app.PoliColorLamp.Color = [1 0 1];
            else
                app.PoliColorLamp.Color = [1 1 1];
            end
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
        end

        % Button pushed function: GraficarSeleccion1
        function GraficarSeleccion1ButtonPushed(app, event)
            %Generación ticks horizontales
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=double(string(strsplit(hora_inicial,{':',','})));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            %Ticks para el tiempo en el eje horizontal
            tick0 = hours(hh)+minutes(mm)+seconds(ss); %Tiempo inicial
            tick1 = tick0 + seconds(app.no_ventana(1)-1);
            tick2 = tick1 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4)+1);
            tick3 = tick2 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick4 = tick3 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick5 = tick4 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1), time_series(floor(length(time_series)/4)),...
                            time_series(floor(length(time_series)/2)),time_series(3*floor(length(time_series)/4)),...
                            time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            xticks_labels ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),...
                datestr(tick3,'HH:MM:SS'),datestr(tick4,'HH:MM:SS'),datestr(tick5,'HH:MM:SS')};                       
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%               
            canales = app.CallingApp.canales_index;
            num_canales = length(canales);
            %Parametros de margenes y dimensiones del subplot
            izq_base = 0.05;
            largo_base = 0.9;
            alto = 0.85/num_canales;
            %Se obtiene el tamaño máximo de la figura
            disp(app.ColorIctal)
            disp(app.ColorSano)
            disp(app.ColorPreictal)
            disp(app.ColorInterictal)
            figure('WindowState','maximized')
            for i=num_canales:-1:1
                %canal_indice = canales(i); %----(CP)----
                %listItem = app.CanalesSalidaListBox.Items{i};  %----(CP)----
                if i == 1
                    %Si sólo es un canal o ya corresponda graficar la figura final            
                    a=subplot('Position',[izq_base izq_base+alto*(i-1) largo_base alto]);            
                    %Se indica que vamos a trabajar con el eje derecho
                    yyaxis right            
                    %Aquí va el loop para graficar todas las ventanas
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %Graficamos cada ventana en los ejes (aprendizaje supervisado)
                    for n=1:length(app.no_ventana)
                        w = app.no_ventana(n);
                        plot_top = app.muestras_top(w);
                        plot_bot = app.muestras_bot(w);
                        %Si tiene etiqueta ictal
                        if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorIctal,"-"),'LineStyle','-'); %Color
                        %Si tiene etiqueta sano
                        elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorSano,"-"),'LineStyle','-');
                        %Si tiene etiqueta preictal
                        elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorPreictal,"-"),'LineStyle','-');
                        %Si tiene etiqueta interictal
                        else
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorInterictal,"-"),'LineStyle','-');
                        end
                        hold on
                    end
                    hold off
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
                    %Se guardan los ticks, tick labels y limites del plot 
                    %para usarlos del lado izquierdo también            
                    PlotYTicks = a.YTick;
                    PlotYTicksLabel = a.YTickLabel;
                    PlotYLims = a.YLim;
                    %Luego de establecerlos, se indica que son manuales; esto para
                    %evitar que el programa añada nuevos ticks en un eje pero no en
                    %el otro
                    a.YTickMode = 'manual';
                    a.YTickLabelMode = 'manual';
                    a.YLimMode = 'manual';
                    %Se coloca el nombre del canal del lado derecho
                    ylabel(append(app.CanalesSalidaListBox.Items{i},' (uV)'))
                    %Se indica que vamos a trabajar ahora con el eje vertical
                    %del lado izquierda
                    yyaxis left            
                    %Se colocan los ticks obtenidos ahora del lado izquierdo
                    a.YTick = PlotYTicks;
                    a.YTickLabel = PlotYTicksLabel;
                    a.YLim = PlotYLims;
                    %Nuevamente definimos que estos son manuales
                    a.YTickMode = 'manual';
                    a.YTickLabelMode = 'manual';
                    a.YLimMode = 'manual';
                    %Se coloca el nombre del canal del lado izquierdo
                    ylabel(append(app.CanalesSalidaListBox.Items{i},' (uV)'))
                    %Se habilita la grilla
                    grid on
                    %Se colocan ambos ejes verticales de color negro
                    a.YAxis(1).Color = 'k';
                    a.YAxis(2).Color = 'k';            
                    a.XTick = xticks_array;
                    a.XTickLabel = xticks_labels;
                elseif i == num_canales
                    %Primer gráfica de arriba hacia abajo
                    a=subplot('Position',[izq_base izq_base+alto*(i-1) largo_base alto]);
                    %Se coloca el eje vertical del lado derecho
                    yyaxis right
                    %Aquí va el loop para graficar todas las ventanas
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %Graficamos cada ventana en los ejes (aprendizaje supervisado)
                    for n=1:length(app.no_ventana)
                        w = app.no_ventana(n);
                        plot_top = app.muestras_top(w);
                        plot_bot = app.muestras_bot(w);
                        %Si tiene etiqueta ictal
                        if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorIctal,"-"),'LineStyle','-'); %Color
                        %Si tiene etiqueta sano
                        elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorSano,"-"),'LineStyle','-');
                        %Si tiene etiqueta preictal
                        elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorPreictal,"-"),'LineStyle','-');
                        %Si tiene etiqueta interictal
                        else
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorInterictal,"-"),'LineStyle','-');
                        end
                        hold on
                    end
                    hold off
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %Se remueve el tick vertical mas bajo; esto con el objetivo
                    %de que el minimo no traslape con el maximo de la siguiente
                    %gráfica
                    a.YTick(1) = '';
                    %Se coloca el nombre del canal del lado derecho
                    ylabel(append(app.CanalesSalidaListBox.Items{i},' (uV)'))
                    %Se guardan los ticks, tick labels y limites del plot 
                    %para usarlos del lado izquierdo también            
                    PlotYTicks = a.YTick;
                    PlotYTicksLabel = a.YTickLabel;
                    PlotYLims = a.YLim;
                    %Luego de establecerlos, se indica que son manuales; esto para
                    %evitar que el programa añada nuevos ticks en un eje pero no en
                    %el otro
                    a.YTickMode = 'manual';
                    a.YTickLabelMode = 'manual';
                    a.YLimMode = 'manual';
                    %Se colocan los ticks horizontales arriba
                    set(a,'XAxisLocation','top')
                    %Se indica que vamos a trabajar ahora con el eje vertical
                    %del lado izquierda
                    yyaxis left
                    %Se colocan los ticks obtenidos ahora del lado izquierdo
                    a.YTick = PlotYTicks;
                    a.YTickLabel = PlotYTicksLabel;
                    a.YLim = PlotYLims;
                    %Nuevamente definimos que estos son manuales
                    a.YTickMode = 'manual';
                    a.YTickLabelMode = 'manual';
                    a.YLimMode = 'manual'; 
                    %Se coloca el nombre del canal del lado izquierdo
                    ylabel(append(app.CanalesSalidaListBox.Items{i},' (uV)'))       
                    %Se habilita la grilla
                    grid on
                    %Se colocan ambos ejes verticales de color negro
                    a.YAxis(1).Color = 'k';
                    a.YAxis(2).Color = 'k'; 
                    a.XTick = xticks_array;
                    a.XTickLabel = xticks_labels;
                else
                    a=subplot('Position',[izq_base izq_base+alto*(i-1) largo_base alto]);
                    %Se indica que vamos a trabajar con el eje derecho
                    yyaxis right
                    %Aquí va el loop para graficar todas las ventanas
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %Graficamos cada ventana en los ejes (aprendizaje supervisado)
                    for n=1:length(app.no_ventana)
                        w = app.no_ventana(n);
                        plot_top = app.muestras_top(w);
                        plot_bot = app.muestras_bot(w);
                        %Si tiene etiqueta ictal
                        if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorIctal,"-"),'LineStyle','-'); %Color
                        %Si tiene etiqueta sano
                        elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorSano,"-"),'LineStyle','-');
                        %Si tiene etiqueta preictal
                        elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorPreictal,"-"),'LineStyle','-');
                        %Si tiene etiqueta interictal
                        else
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorInterictal,"-"),'LineStyle','-');
                        end
                        hold on
                    end
                    hold off
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %Removemos los ticks horizontales
                    set(a,'XTickLabel',{''});
                    %Se remueve el tick vertical mas bajo; esto con el objetivo
                    %de que el minimo no traslape con el maximo de la siguiente
                    %gráfica
                    a.YTick(1) = '';
                    %Se coloca el nombre del canal del lado derecho
                    ylabel(append(app.CanalesSalidaListBox.Items{i},' (uV)'))
                    %Se guardan los ticks, tick labels y limites del plot 
                    %para usarlos del lado izquierdo también            
                    PlotYTicks = a.YTick;
                    PlotYTicksLabel = a.YTickLabel;
                    PlotYLims = a.YLim;
                    %Luego de establecerlos, se indica que son manuales; esto para
                    %evitar que el programa añada nuevos ticks en un eje pero no en
                    %el otro
                    a.YTickMode = 'manual';
                    a.YTickLabelMode = 'manual';
                    a.YLimMode = 'manual';
                    %Se indica que vamos a trabajar ahora con el eje vertical
                    %del lado izquierda
                    yyaxis left
                    %Se coloca el nombre del canal del lado izquierdo
                    ylabel(append(app.CanalesSalidaListBox.Items{i},' (uV)'))
                    %Se colocan los ticks obtenidos ahora del lado izquierdo
                    a.YTick = PlotYTicks;
                    a.YTickLabel = PlotYTicksLabel;
                    a.YLim = PlotYLims;
                    %Nuevamente definimos que estos son manuales
                    a.YTickMode = 'manual';
                    a.YTickLabelMode = 'manual';
                    a.YLimMode = 'manual';
                    %Se habilita la grilla
                    grid on
                    %Se colocan ambos ejes verticales de color negro
                    a.YAxis(1).Color = 'k';
                    a.YAxis(2).Color = 'k';
                    a.XTick = {};
                end  
            end
        end

        % Value changed function: Grilla2CheckBox
        function Grilla2CheckBoxValueChanged(app, event)
            value = app.Grilla2CheckBox.Value;
            if value == 1
                grid(app.EEGPlot2, 'on')
            else
                grid(app.EEGPlot2, 'off')
            end
        end

        % Button pushed function: GuardarventanadeanlisisButton
        function GuardarventanadeanlisisButtonPushed(app, event)
            %Se genera la tabla a guardar como .xls
            Mcolores = [app.ColorIctal,...
                              app.ColorSano,...
                              app.ColorPreictal,...
                              app.ColorInterictal];
            Ccolores = {'','','',''};
            for m=1:4
                if Mcolores(1,m) == 'b'
                    Ccolores{m} = 'Azul';
                elseif Mcolores(1,m) == 'k'
                    Ccolores{m} = 'Negro';
                elseif Mcolores(1,m) == 'g'
                    Ccolores{m} = 'Verde';
                elseif Mcolores(1,m) == 'r'
                    Ccolores{m} = 'Rojo';
                elseif Mcolores(1,m) == 'y'
                    Ccolores{m} = 'Amarillo';
                elseif Mcolores(1,m) == 'c'
                    Ccolores{m} = 'Cian';
                elseif Mcolores(1,m) == 'm'
                    Ccolores{m} = 'Magenta';
                else
                    Ccolores{m} = 'Blanco';
                end
            end
            %%
            T = table({'ResultadosAnalisis';'ID';'FechaAnalisis';'Color Ictal';'Color Sano';'Color Preictal';'Color Interictal'},...
            {'';'001';datestr(datetime("now"));Ccolores{1};Ccolores{2};Ccolores{3};Ccolores{4}});
            %%
            %Se guarda la figura como .png
            [file,path] = uiputfile('Registro EEG.png','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            else
                %Guardar archivo en la ruta seleccionada
                fullFileName = fullfile(path, file);  
                %Guardamos el gráfico
                saveas(gcf,fullFileName);
            end
            
            %Guardamos el .xls con información
            [file,path] = uiputfile('Analisis.xls','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            else
                %Guardar archivo en la ruta seleccionada
                fullFileName = fullfile(path, file);  
                %Guardamos la tabla
                writetable(T,fullFileName,'Sheet',1,'WriteVariableNames', false)
            end            
        end

        % Button pushed function: RecortarsegmentodeintersButton
        function RecortarsegmentodeintersButtonPushed(app, event)
           %Generación ticks horizontales
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=double(string(strsplit(hora_inicial,{':',','})));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            %Ticks para el tiempo en el eje horizontal
            tick0 = hours(hh)+minutes(mm)+seconds(ss); %Tiempo inicial
            tick1 = tick0 + seconds(app.no_ventana(1)-1);
            tick2 = tick1 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4)+1);
            tick3 = tick2 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick4 = tick3 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            tick5 = tick4 + seconds((floor(app.no_ventana(end)-app.no_ventana(1))/4));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1), time_series(floor(length(time_series)/4)),...
                            time_series(floor(length(time_series)/2)),time_series(3*floor(length(time_series)/4)),...
                            time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            xticks_labels ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),...
                datestr(tick3,'HH:MM:SS'),datestr(tick4,'HH:MM:SS'),datestr(tick5,'HH:MM:SS')};                       
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%               
            canales = app.CallingApp.canales_index;
            num_canales = length(canales);
            %Parametros de margenes y dimensiones del subplot
            izq_base = 0.05;
            largo_base = 0.9;
            alto = 0.85/num_canales;
            %Se obtiene el tamaño máximo de la figura
            disp(app.ColorIctal)
            disp(app.ColorSano)
            disp(app.ColorPreictal)
            disp(app.ColorInterictal)
            figure('WindowState','maximized')
            for i=num_canales:-1:1
                %canal_indice = canales(i); %----(CP)----
                %listItem = app.CanalesSalidaListBox.Items{i};  %----(CP)----
                if i == 1
                    %Si sólo es un canal o ya corresponda graficar la figura final            
                    %a=subplot('Position',[izq_base izq_base+alto*(i-1) largo_base alto]);            
                    %Se indica que vamos a trabajar con el eje derecho
                    yyaxis right            
                    %Aquí va el loop para graficar todas las ventanas
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %Graficamos cada ventana en los ejes (aprendizaje supervisado)
                    for n=1:length(app.no_ventana)
                        w = app.no_ventana(n);
                        plot_top = app.muestras_top(w);
                        plot_bot = app.muestras_bot(w);
                        %Si tiene etiqueta ictal
                        if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                            plot(app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                                app.CallingApp.EEG_VI(canales(i),plot_bot:plot_top),... %Eje Y
                                strip(app.ColorIctal,"-"),'LineStyle','-'); %Color
                      
                        end
                        hold on
                    end
                    hold off
                end
            end
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            %----CP----
            disp("Iniciando almacenamiento...")

            % Se crean las variables a utilizar
            Tiempo_Ictal = [];
            Senial_Ictal = [];
            Tiempo_Sano = [];
            Senial_Sano = [];
            Tiempo_PreIctal = [];
            Senial_PreIctal = [];
            Tiempo_InterIctal = [];
            Senial_InterIctal = [];
            Tiempo_monomorfico = [];
            Senial_monomorfico = [];
            Tiempo_polimorfico = [];
            Senial_polimorfico = [];
            b_mensaje = 0;
            
            % Se revisa las caracteristicas a almacenar
            check_ictal = app.IctalCheckBox.Value;
            check_sano = app.SanoCheckBox.Value;
            check_preictal = app.PreIctalCheckBox.Value;
            check_interictal = app.InterIctalCheckBox.Value;
            check_monomorfica = app.MonomrficaCheckBox.Value;
            check_polimorfica = app.PolimrficaCheckBox.Value;

            %algoritmo de clasificacion de caracteristicas de la senial
            for i=1:length(app.no_ventana)
                w =  app.no_ventana(i);
                
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);

                %Si tiene etiqueta ictal
                if (check_ictal == 1 ) && (app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1)
                    Tiempo_Ictal = [Tiempo_Ictal, ...
                        app.vector_tiempo_grafico(plot_bot:plot_top)];

                    Senial_Ictal = [Senial_Ictal, ...
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top)];
                end       
                %Si tiene etiqueta sano
                if (check_sano == 1 ) && (app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2)
                    Tiempo_Sano = [Tiempo_Sano, ...
                        app.vector_tiempo_grafico(plot_bot:plot_top)];
                    
                    Senial_Sano = [Senial_Sano, ...
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top)];
                end    
                %Si tiene etiqueta preictal
                if (check_preictal == 1 ) && (app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3)
                    Tiempo_PreIctal = [Tiempo_PreIctal, ...
                        app.vector_tiempo_grafico(plot_bot:plot_top)];

                    Senial_PreIctal = [Senial_PreIctal, ...
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top)];
                end
                %Si tiene etiqueta interictal
                if (check_interictal == 1 ) && (app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 4)
                    Tiempo_InterIctal = [Tiempo_InterIctal, ...
                        app.vector_tiempo_grafico(plot_bot:plot_top)];

                    Senial_InterIctal = [Senial_InterIctal, ...
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top)];
                end
                %Si se tiene etiqueta monomorfica
                if (check_monomorfica == 1 ) && (app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1)
                    Tiempo_monomorfico = [Tiempo_monomorfico, ...
                        app.vector_tiempo_grafico(plot_bot:plot_top)];

                    Senial_monomorfico = [Senial_monomorfico, ...
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top)];
                end
                %Si se tiene etiqueta polimorfica
                if (check_polimorfica == 1 )&& (app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 2)
                    Tiempo_polimorfico = [Tiempo_polimorfico, ...
                        app.vector_tiempo_grafico(plot_bot:plot_top)];

                    Senial_polimorfico = [Senial_polimorfico, ...
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top)];
                end

                %Warning de que no esta guardando nada
                if (~check_monomorfica) && (~check_polimorfica) && (~check_interictal) && (~check_preictal) && (~check_sano) && (~check_ictal)
                    b_mensaje = 1;
                    disp("No se selecciono datos a  exportar")
                    break
                end
            end

            % Exporta los datos a un archivo de Excel
            excelFileName = 'datos_eeg_TACU.xlsx'; % Nombre del archivo de Excel
            sheetName1 = 'Ictal'; % Nombre de la hoja en Excel
            sheetName2 = 'Sano'; % Nombre de la hoja en Excel
            sheetName3 = 'PreIctal'; % Nombre de la hoja en Excel
            sheetName4 = 'InterIctal'; % Nombre de la hoja en Excel
            sheetName5 = 'Monomorfica'; % Nombre de la hoja en Excel
            sheetName6 = 'Polimorfica'; % Nombre de la hoja en Excel
            
            %Se redimensiona a vector columna
            Tiempo_Ictal = Tiempo_Ictal.';
            Senial_Ictal = Senial_Ictal.';
            Tiempo_Sano = Tiempo_Sano.';
            Senial_Sano= Senial_Sano.';
            Tiempo_PreIctal = Tiempo_PreIctal.';
            Senial_PreIctal = Senial_PreIctal.';
            Tiempo_InterIctal = Tiempo_InterIctal.';
            Senial_InterIctal = Senial_InterIctal.';
            Tiempo_monomorfico = Tiempo_monomorfico.';
            Senial_monomorfico = Senial_monomorfico.';
            Tiempo_polimorfico = Tiempo_polimorfico.';
            Senial_polimorfico = Senial_polimorfico.';
            
            %Se crea la tabla de datos tiempo vs senial
            tablaIctal = table(Tiempo_Ictal, Senial_Ictal);
            tablaSano = table(Tiempo_Sano, Senial_Sano);
            tablaPreIctal = table(Tiempo_PreIctal, Senial_PreIctal);
            tablaInterIctal = table(Tiempo_InterIctal, Senial_InterIctal);
            tablamonomorfica = table(Tiempo_monomorfico, Senial_monomorfico);
            tablapolimorfica = table(Tiempo_polimorfico, Senial_polimorfico);
            
            %Se crean las hojas en el documento con su respectiva info
            if check_ictal == 1 
                writetable(tablaIctal,excelFileName,"Sheet",sheetName1,...
                "Range",'A1')
            end
            if check_sano == 1 
                writetable(tablaSano,excelFileName,"Sheet",sheetName2,...
                "Range",'A1')
            end
            if check_preictal == 1 
                writetable(tablaPreIctal,excelFileName,"Sheet",sheetName3,...
                "Range",'A1')
            end
            if check_interictal == 1 
                writetable(tablaInterIctal,excelFileName,"Sheet",sheetName4,...
                "Range",'A1')
            end
            if check_monomorfica == 1 
                writetable(tablamonomorfica,excelFileName,"Sheet",sheetName5,...
                "Range",'A1')
            end
            if check_polimorfica == 1 
                writetable(tablapolimorfica,excelFileName,"Sheet",sheetName6,...
                "Range",'A1')
            end

            if b_mensaje == 0
                disp("Guardado como: " + excelFileName)
            end

        end

        % Button pushed function: TodoButton
        function TodoButtonPushed(app, event)
            %Se selecciona/deselecciona todas las caracteristicas
            app.PolimrficaCheckBox.Value = ~app.PolimrficaCheckBox.Value;
            app.MonomrficaCheckBox.Value = ~app.MonomrficaCheckBox.Value;
            app.InterIctalCheckBox.Value = ~app.InterIctalCheckBox.Value;
            app.PreIctalCheckBox.Value = ~app.PreIctalCheckBox.Value;
            app.SanoCheckBox.Value = ~app.SanoCheckBox.Value;
            app.IctalCheckBox.Value = ~app.IctalCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GraficoVentanasInteres = uifigure('Visible', 'off');
            app.GraficoVentanasInteres.Name = 'Grafico EEG Con Anotaciones';
            app.EEGPlot1 = uiaxes(app.GraficoVentanasInteres);
            title(app.EEGPlot1, 'Señal EEG - Clasificación Patológica')
            xlabel(app.EEGPlot1, 'Tiempo (HH:MM:SS)')
            ylabel(app.EEGPlot1, 'Voltaje (uV)')
            app.EEGPlot1.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.EEGPlot1.YTickLabel = {'-300'; '-200'; '-100'; '0'; '100'; '200'; '300'};
            app.EEGPlot2 = uiaxes(app.GraficoVentanasInteres);
            title(app.EEGPlot2, 'Señal EEG - Clasificación Morfológica')
            xlabel(app.EEGPlot2, 'Tiempo (HH:MM:SS)')
            ylabel(app.EEGPlot2, 'Voltaje (uV)')
            app.EEGPlot2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.EEGPlot2.YTickLabel = {'-300'; '-200'; '-100'; '0'; '100'; '200'; '300'};
            app.EPILEPTICEEGANALYSISTOOLBOXLabel = uilabel(app.GraficoVentanasInteres);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel.Text = 'EPILEPTIC EEG ANALYSIS TOOLBOX';
            app.GrficayventanasdeintersdelasealLabel = uilabel(app.GraficoVentanasInteres);
            app.GrficayventanasdeintersdelasealLabel.Text = 'Gráfica y ventanas de interés de la señal';
            app.TiempoavisualizarLabel = uilabel(app.GraficoVentanasInteres);
            app.TiempoavisualizarLabel.Text = 'Tiempo a visualizar';
            app.TiempoMostrar = uieditfield(app.GraficoVentanasInteres, 'numeric');
            app.TiempoMostrar.Limits = [1 300];
            app.TiempoMostrar.ValueChangedFcn = createCallbackFcn(app, @TiempoMostrarValueChanged, true);
            app.TiempoMostrar.Value = 60;
            app.Boton_Anterior = uibutton(app.GraficoVentanasInteres, 'push');
            app.Boton_Anterior.ButtonPushedFcn = createCallbackFcn(app, @Boton_AnteriorButtonPushed, true);
            app.Boton_Anterior.Icon = fullfile(pathToMLAPP, 'Flecha_izquierda.jpg');
            app.Boton_Anterior.Text = '';
            app.Boton_Siguiente = uibutton(app.GraficoVentanasInteres, 'push');
            app.Boton_Siguiente.ButtonPushedFcn = createCallbackFcn(app, @Boton_SiguienteButtonPushed, true);
            app.Boton_Siguiente.Icon = fullfile(pathToMLAPP, 'Flecha_derecha.jpg');
            app.Boton_Siguiente.Text = '';
            app.VentanaanteriorLabel = uilabel(app.GraficoVentanasInteres);
            app.VentanaanteriorLabel.Text = 'Ventana anterior';
            app.VentanasiguienteLabel = uilabel(app.GraficoVentanasInteres);
            app.VentanasiguienteLabel.Text = 'Ventana siguiente';
            app.AnotacionesLabel = uilabel(app.GraficoVentanasInteres);
            app.AnotacionesLabel.Visible = 'off';
            app.AnotacionesLabel.Text = 'Anotaciones:';
            app.TextoAnotacion = uilabel(app.GraficoVentanasInteres);
            app.TextoAnotacion.Visible = 'off';
            app.HoradeiniciodelregistroEEGHHMMSSEditFieldLabel = uilabel(app.GraficoVentanasInteres);
            app.HoradeiniciodelregistroEEGHHMMSSEditFieldLabel.Text = {'Hora de inicio del registro EEG'; ' HH:MM:SS'};
            app.HoradeiniciodelregistroEEGHHMMSSEditField = uieditfield(app.GraficoVentanasInteres, 'text');
            app.HoradeiniciodelregistroEEGHHMMSSEditField.ValueChangedFcn = createCallbackFcn(app, @HoradeiniciodelregistroEEGHHMMSSEditFieldValueChanged, true);
            app.HoradeiniciodelregistroEEGHHMMSSEditField.Value = '00:00:00';
            app.CanalavisualizarDropDownLabel = uilabel(app.GraficoVentanasInteres);
            app.CanalavisualizarDropDownLabel.Text = 'Canal a visualizar';
            app.Canalavisualizar1DropDown = uidropdown(app.GraficoVentanasInteres);
            app.Canalavisualizar1DropDown.Items = {'-'};
            app.Canalavisualizar1DropDown.ValueChangedFcn = createCallbackFcn(app, @Canalavisualizar1DropDownValueChanged, true);
            app.Canalavisualizar1DropDown.Value = '-';
            app.sLabel = uilabel(app.GraficoVentanasInteres);
            app.sLabel.Text = 's';
            app.IctalDropDownLabel = uilabel(app.GraficoVentanasInteres);
            app.IctalDropDownLabel.Text = 'Ictal';
            app.IctalColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.IctalColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.IctalColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.IctalColorDropDown.ValueChangedFcn = createCallbackFcn(app, @IctalColorDropDownValueChanged, true);
            app.IctalColorDropDown.Value = 'r';
            app.IctalColorLamp = uilamp(app.GraficoVentanasInteres);
            app.SanoDropDownLabel = uilabel(app.GraficoVentanasInteres);
            app.SanoDropDownLabel.Text = 'Sano';
            app.SanoColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.SanoColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.SanoColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.SanoColorDropDown.ValueChangedFcn = createCallbackFcn(app, @SanoColorDropDownValueChanged, true);
            app.SanoColorDropDown.Value = 'b';
            app.PreictalLabel = uilabel(app.GraficoVentanasInteres);
            app.PreictalLabel.Text = 'Preictal';
            app.PreictalColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.PreictalColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.PreictalColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.PreictalColorDropDown.ValueChangedFcn = createCallbackFcn(app, @PreictalColorDropDownValueChanged, true);
            app.PreictalColorDropDown.Value = 'g';
            app.InterictalLabel = uilabel(app.GraficoVentanasInteres);
            app.InterictalLabel.Text = 'Interictal';
            app.InterictalColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.InterictalColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.InterictalColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.InterictalColorDropDown.ValueChangedFcn = createCallbackFcn(app, @InterictalColorDropDownValueChanged, true);
            app.InterictalColorDropDown.Value = 'k';
            app.SanoColorLamp = uilamp(app.GraficoVentanasInteres);
            app.PreictalColorLamp = uilamp(app.GraficoVentanasInteres);
            app.InterictalColorLamp = uilamp(app.GraficoVentanasInteres);
            app.Grilla1CheckBox = uicheckbox(app.GraficoVentanasInteres);
            app.Grilla1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Grilla1CheckBoxValueChanged, true);
            app.Grilla1CheckBox.Text = 'Grilla';
            app.LeyendadecolorTipodesealLabel = uilabel(app.GraficoVentanasInteres);
            app.LeyendadecolorTipodesealLabel.Text = 'Leyenda de color: Tipo de señal';
            app.MonoColorLamp = uilamp(app.GraficoVentanasInteres);
            app.PoliColorLamp = uilamp(app.GraficoVentanasInteres);
            app.LeyendadecolorMorfologaLabel = uilabel(app.GraficoVentanasInteres);
            app.LeyendadecolorMorfologaLabel.Text = 'Leyenda de color: Morfología';
            app.MonomrficaLabel = uilabel(app.GraficoVentanasInteres);
            app.MonomrficaLabel.Text = 'Monomórfica';
            app.MonoColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.MonoColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.MonoColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.MonoColorDropDown.ValueChangedFcn = createCallbackFcn(app, @MonoColorDropDownValueChanged, true);
            app.MonoColorDropDown.Value = 'm';
            app.PolimrficaLabel = uilabel(app.GraficoVentanasInteres);
            app.PolimrficaLabel.Text = 'Polimórfica';
            app.PoliColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.PoliColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.PoliColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.PoliColorDropDown.ValueChangedFcn = createCallbackFcn(app, @PoliColorDropDownValueChanged, true);
            app.PoliColorDropDown.Value = 'c';
            app.CanalesListBoxLabel = uilabel(app.GraficoVentanasInteres);
            app.CanalesListBoxLabel.Text = 'Canales';
            app.CanalesSalidaListBox = uilistbox(app.GraficoVentanasInteres);
            app.CanalesSalidaListBox.Items = {'-'};
            app.CanalesSalidaListBox.Multiselect = 'on';
            app.CanalesSalidaListBox.Value = {'-'};
            app.GenerarmultigrficaLabel = uilabel(app.GraficoVentanasInteres);
            app.GenerarmultigrficaLabel.Text = 'Generar multigráfica';
            app.GraficarSeleccion1 = uibutton(app.GraficoVentanasInteres, 'push');
            app.GraficarSeleccion1.ButtonPushedFcn = createCallbackFcn(app, @GraficarSeleccion1ButtonPushed, true);
            app.GraficarSeleccion1.Text = 'Graficar selección';
            app.Grilla2CheckBox = uicheckbox(app.GraficoVentanasInteres);
            app.Grilla2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Grilla2CheckBoxValueChanged, true);
            app.Grilla2CheckBox.Text = 'Grilla';
            app.CanalavisualizarDropDownLabel_2 = uilabel(app.GraficoVentanasInteres);
            app.CanalavisualizarDropDownLabel_2.Text = 'Canal a visualizar';
            app.Canalavisualizar2DropDown = uidropdown(app.GraficoVentanasInteres);
            app.Canalavisualizar2DropDown.Items = {'-'};
            app.Canalavisualizar2DropDown.Value = '-';
            app.GuardarventanadeanlisisButton = uibutton(app.GraficoVentanasInteres, 'push');
            app.GuardarventanadeanlisisButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarventanadeanlisisButtonPushed, true);
            app.GuardarventanadeanlisisButton.Text = 'Guardar ventana de análisis';
            app.RecortarsegmentodeintersButton = uibutton(app.GraficoVentanasInteres, 'push');
            app.RecortarsegmentodeintersButton.ButtonPushedFcn = createCallbackFcn(app, @RecortarsegmentodeintersButtonPushed, true);
            app.RecortarsegmentodeintersButton.Text = 'Recortar segmento de interés';
            app.ExportarButton = uibutton(app.GraficoVentanasInteres, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            app.DataaexportarLabel = uilabel(app.GraficoVentanasInteres);
            app.DataaexportarLabel.Text = 'Data a exportar';
            app.IctalCheckBox = uicheckbox(app.GraficoVentanasInteres);
            app.IctalCheckBox.Text = 'Ictal';
            app.SanoCheckBox = uicheckbox(app.GraficoVentanasInteres);
            app.SanoCheckBox.Text = 'Sano';
            app.PreIctalCheckBox = uicheckbox(app.GraficoVentanasInteres);
            app.PreIctalCheckBox.Text = 'PreIctal';
            app.InterIctalCheckBox = uicheckbox(app.GraficoVentanasInteres);
            app.InterIctalCheckBox.Text = 'InterIctal';
            app.MonomrficaCheckBox = uicheckbox(app.GraficoVentanasInteres);
            app.MonomrficaCheckBox.Text = 'Monomórfica';
            app.PolimrficaCheckBox = uicheckbox(app.GraficoVentanasInteres);
            app.PolimrficaCheckBox.Text = 'Polimórfica';
            app.TodoButton = uibutton(app.GraficoVentanasInteres, 'push');
            app.TodoButton.ButtonPushedFcn = createCallbackFcn(app, @TodoButtonPushed, true);
            app.TodoButton.Text = 'Todo';
            % Show the figure after all components are created
            app.GraficoVentanasInteres.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = anotacionesautomaticas(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraficoVentanasInteres)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraficoVentanasInteres)
        end
    end
end