classdef clasificadorRN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        REDNEURONALARTIFICIALUIFigure   matlab.ui.Figure
        REGRESARButton                  matlab.ui.control.Button
        SEGMENTOSPARAVALIDAREditField   matlab.ui.control.NumericEditField
        SEGMENTOSPARAVALIDAREditFieldLabel  matlab.ui.control.Label
        TIPODEVECTORButtonGroup         matlab.ui.container.ButtonGroup
        ANLISISWAVELETButton            matlab.ui.control.RadioButton
        ANLISISDIRECTOButton            matlab.ui.control.RadioButton
        VISUALIZARButton                matlab.ui.control.Button
        GENERARButton                   matlab.ui.control.Button
        CARGARNUEVOVECTORDECARACTERSTICASPARAENTRENAMIENTOYPRUEBALabel  matlab.ui.control.Label
        CARGARButton                    matlab.ui.control.Button
        CAPASOCULTASDropDown            matlab.ui.control.DropDown
        CAPASOCULTASDropDownLabel       matlab.ui.control.Label
        ALGORITMODEENTRENAMIENTODropDown  matlab.ui.control.DropDown
        ALGORITMODEENTRENAMIENTODropDownLabel  matlab.ui.control.Label
        SEGMENTOSPARAPRUEBAEditField    matlab.ui.control.NumericEditField
        SEGMENTOSPARAPRUEBAEditFieldLabel  matlab.ui.control.Label
        SEGMENTOSPARAENTRENAREditField  matlab.ui.control.NumericEditField
        SEGMENTOSPARAENTRENAREditFieldLabel  matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        EXACTITUDLabel                  matlab.ui.control.Label
        RESULTADOSLabel                 matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        REDNEURONALARTIFICIALLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    properties (Access = public)
        %Variables compartidas entre ventanas 
        features_vector 
        features_vector_wavelet 
        vectorlabel
        vectorlabelwavelet
        data
        label
        totfeatures
        totlabels
        results
        trainnet
        accuracy
        y
        scores
        a
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            movegui(app.REDNEURONALARTIFICIALUIFigure,"center");
            app.CallingApp = mainapp;
            app.a =0;
            app.GENERARButton.Enable = 'off';
        end

        % Button pushed function: CARGARButton
        function CARGARButtonPushed(app, event)
            %Seleccion archivo MAT con vector de caracteristicas y vector
            %de etiquetas
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            workspace = load(strcat(path,file),'data','labels');
            app.data = workspace.data;
            app.label = workspace.labels;
            app.a=1;
            message = 'Archivo seleccionado exitosamente';
            uialert(app.REDNEURONALARTIFICIALUIFigure,message,'Aviso',...
            'Icon','success');
        end

        % Button pushed function: VISUALIZARButton
        function VISUALIZARButtonPushed(app, event)
            %Mensaje de advertencia 
            fig = app.REDNEURONALARTIFICIALUIFigure;
             message = 'Esto puede tardar varios minutos...';
             d=uiprogressdlg(fig,'Message',message,...
            'Icon','info','Cancelable','off','Indeterminate','on'); % Barra de progreso    
            app.features_vector = app.CallingApp.Matriz_features;
            app.features_vector_wavelet = app.CallingApp.Matriz_features_wavelet;
            app.vectorlabel = app.CallingApp.label;
            app.vectorlabelwavelet = app.CallingApp.labelwave;
            if app.ANLISISDIRECTOButton.Value && app.a==0    %Generar conjuntos train y test con features directas
                feature = app.features_vector;  
                labels = app.vectorlabel;
            elseif app.ANLISISWAVELETButton.Value && app.a==0 %Generar conjuntos train y test con features wavelet
                feature =app.features_vector_wavelet;
                labels = app.vectorlabelwavelet; 
            end
            
            if app.a==0
                app.totfeatures = feature;
                app.totlabels = labels;
            elseif app.a==1
                app.totfeatures = app.data;
                app.totlabels = app.label;
            end

            hiddenLayerSize = double(string(app.CAPASOCULTASDropDown.Value));
            trainFcn = app.ALGORITMODEENTRENAMIENTODropDown.Value;
            %Creacion de red neuronal
            net = patternnet(hiddenLayerSize, trainFcn);
            % Entrenar la red 
            [net,tr] = train(net,app.totfeatures',app.totlabels');
            % COnfigurar division de datos: entrenamiento/testeo/validacion
            divitrain = (app.SEGMENTOSPARAENTRENAREditField.Value);
            divitest = (app.SEGMENTOSPARAPRUEBAEditField.Value);
            divival = app.SEGMENTOSPARAVALIDAREditField.Value;
            if divitrain+divitest+divival ~=100
                divitrain=70;
                divitest = 15;
                divival = 15;
                app.SEGMENTOSPARAENTRENAREditField.Value=70;
                app.SEGMENTOSPARAPRUEBAEditField.Value=15;
                app.SEGMENTOSPARAVALIDAREditField.Value=15;
            end
            net.divideParam.trainRatio = divitrain/100;
            net.divideParam.valRatio = divival/100;
            net.divideParam.testRatio = divitest/100;
            % Testear la red
            app.y = net(app.totfeatures'); 
            %Calcular exactitud/accuracy
            [~,cm] = confusion(app.totlabels',app.y);
            TP = cm(1,1);  % Verdadero positivo
            FP = cm(1,2);  % Falso positivo
            FN = cm(2,1);  % Falso negativo
            TN = cm(2,2);  % Verdadero negativo
            app.Label_3.Text = num2str(100*((TP + TN) / (TP + TN + FP + FN)));  %accuracy
            app.trainnet = tr;
            app.GENERARButton.Enable = 'on';

        end

        % Button pushed function: GENERARButton
        function GENERARButtonPushed(app, event)
            %Guardar archivo
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            [file,path] = uiputfile('Resultados_RN.mat','Save file name');
            if file == 0
             return;
            end
            fullFileName = fullfile(path, file)
            VectorCaracteristicas=app.totfeatures;
            Etiquetas=app.totlabels;
            RN_resultados.net = app.trainnet;
            RN_resultados.salidas = app.y;
            save(fullFileName,'VectorCaracteristicas','Etiquetas','RN_resultados')
            %Mensaje de exito 
            message = sprintf(['Archivo',file,' guardado exitosamente']);
            uialert(app.REDNEURONALARTIFICIALUIFigure,message,'Aviso',...
            'Icon','success');
        end

        % Button pushed function: REGRESARButton
        function REGRESARButtonPushed(app, event)
             delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.REDNEURONALARTIFICIALUIFigure = uifigure('Visible', 'off');
            app.REDNEURONALARTIFICIALUIFigure.Name = 'RED NEURONALARTIFICIAL';
            app.REDNEURONALARTIFICIALUIFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.REDNEURONALARTIFICIALLabel = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.REDNEURONALARTIFICIALLabel.Text = 'RED NEURONAL ARTIFICIAL';
            app.Label = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.Label.Text = '________________________________________________________________';
            app.RESULTADOSLabel = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.RESULTADOSLabel.Text = 'RESULTADOS';
            app.EXACTITUDLabel = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.EXACTITUDLabel.Text = 'EXACTITUD';
            app.Label_3 = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.Label_3.Text = '-----------';
            app.Label_5 = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.Label_5.Text = '________________________________________________________________';
            app.SEGMENTOSPARAENTRENAREditFieldLabel = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.SEGMENTOSPARAENTRENAREditFieldLabel.Text = {'% SEGMENTOS'; 'PARA ENTRENAR'};
            app.SEGMENTOSPARAENTRENAREditField = uieditfield(app.REDNEURONALARTIFICIALUIFigure, 'numeric');
            app.SEGMENTOSPARAENTRENAREditField.Limits = [0 90];
            app.SEGMENTOSPARAENTRENAREditField.Value = 70;
            app.SEGMENTOSPARAPRUEBAEditFieldLabel = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.SEGMENTOSPARAPRUEBAEditFieldLabel.Text = {'% SEGMENTOS'; 'PARA PRUEBA'};
            app.SEGMENTOSPARAPRUEBAEditField = uieditfield(app.REDNEURONALARTIFICIALUIFigure, 'numeric');
            app.SEGMENTOSPARAPRUEBAEditField.Limits = [0 50];
            app.SEGMENTOSPARAPRUEBAEditField.Value = 15;
            app.ALGORITMODEENTRENAMIENTODropDownLabel = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.ALGORITMODEENTRENAMIENTODropDownLabel.Text = {'ALGORITMO DE '; 'ENTRENAMIENTO'};
            app.ALGORITMODEENTRENAMIENTODropDown = uidropdown(app.REDNEURONALARTIFICIALUIFigure);
            app.ALGORITMODEENTRENAMIENTODropDown.Items = {'trainscg', 'trainr', 'trainrp', 'traingd', '', '', ''};
            app.ALGORITMODEENTRENAMIENTODropDown.Value = 'trainscg';
            app.CAPASOCULTASDropDownLabel = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.CAPASOCULTASDropDownLabel.Text = {'CAPAS '; 'OCULTAS'};
            app.CAPASOCULTASDropDown = uidropdown(app.REDNEURONALARTIFICIALUIFigure);
            app.CAPASOCULTASDropDown.Items = {'8', '10', '12', '15', '', '', ''};
            app.CAPASOCULTASDropDown.Value = '8';
            app.CARGARButton = uibutton(app.REDNEURONALARTIFICIALUIFigure, 'push');
            app.CARGARButton.ButtonPushedFcn = createCallbackFcn(app, @CARGARButtonPushed, true);
            app.CARGARButton.Text = 'CARGAR';
            app.CARGARNUEVOVECTORDECARACTERSTICASPARAENTRENAMIENTOYPRUEBALabel = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.CARGARNUEVOVECTORDECARACTERSTICASPARAENTRENAMIENTOYPRUEBALabel.Text = {'CARGAR NUEVO VECTOR'; 'DE CARACTERÍSTICAS PARA'; 'ENTRENAMIENTO Y PRUEBA'};
            app.GENERARButton = uibutton(app.REDNEURONALARTIFICIALUIFigure, 'push');
            app.GENERARButton.ButtonPushedFcn = createCallbackFcn(app, @GENERARButtonPushed, true);
            app.GENERARButton.Text = 'GENERAR';
            app.VISUALIZARButton = uibutton(app.REDNEURONALARTIFICIALUIFigure, 'push');
            app.VISUALIZARButton.ButtonPushedFcn = createCallbackFcn(app, @VISUALIZARButtonPushed, true);
            app.VISUALIZARButton.Text = 'VISUALIZAR';
            app.TIPODEVECTORButtonGroup = uibuttongroup(app.REDNEURONALARTIFICIALUIFigure);
            app.TIPODEVECTORButtonGroup.Title = 'TIPO DE VECTOR';
            app.ANLISISDIRECTOButton = uiradiobutton(app.TIPODEVECTORButtonGroup);
            app.ANLISISDIRECTOButton.Text = 'ANÁLISIS DIRECTO';
            app.ANLISISDIRECTOButton.Value = true;
            app.ANLISISWAVELETButton = uiradiobutton(app.TIPODEVECTORButtonGroup);
            app.ANLISISWAVELETButton.Text = 'ANÁLISIS WAVELET';
            app.SEGMENTOSPARAVALIDAREditFieldLabel = uilabel(app.REDNEURONALARTIFICIALUIFigure);
            app.SEGMENTOSPARAVALIDAREditFieldLabel.Text = {'% SEGMENTOS'; 'PARA VALIDAR'};
            app.SEGMENTOSPARAVALIDAREditField = uieditfield(app.REDNEURONALARTIFICIALUIFigure, 'numeric');
            app.SEGMENTOSPARAVALIDAREditField.Limits = [0 50];
            app.SEGMENTOSPARAVALIDAREditField.Value = 15;
            app.REGRESARButton = uibutton(app.REDNEURONALARTIFICIALUIFigure, 'push');
            app.REGRESARButton.ButtonPushedFcn = createCallbackFcn(app, @REGRESARButtonPushed, true);
            app.REGRESARButton.Text = 'REGRESAR';
            % Show the figure after all components are created
            app.REDNEURONALARTIFICIALUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clasificadorRN(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.REDNEURONALARTIFICIALUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.REDNEURONALARTIFICIALUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.REDNEURONALARTIFICIALUIFigure)
        end
    end
end