classdef configuracion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfiguracinUIFigure          matlab.ui.Figure
        CambiarButton_2               matlab.ui.control.Button
        CorreoEditField               matlab.ui.control.EditField
        CorreoEditFieldLabel          matlab.ui.control.Label
        CorreoLabel                   matlab.ui.control.Label
        pass2EditField                matlab.ui.control.EditField
        pass2EditFieldLabel           matlab.ui.control.Label
        pass1EditField                matlab.ui.control.EditField
        pass1EditFieldLabel           matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        CerrarButton                  matlab.ui.control.Button
        CambiarButton                 matlab.ui.control.Button
        ConfirmarcontraseaEditField   matlab.ui.control.EditField
        ConfirmarcontraseaEditFieldLabel  matlab.ui.control.Label
        NuevacontraseaEditField       matlab.ui.control.EditField
        NuevacontraseaEditFieldLabel  matlab.ui.control.Label
        CambiarcontraseaLabel         matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        Conexion;
        usuario;
        plogin;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con, usuario, plogin)
            movegui(app.ConfiguracinUIFigure,"center");
            app.Conexion = con;
            app.usuario = usuario;
            app.Label.Text = app.usuario;
            app.plogin = plogin;
            if app.plogin
                msgbox('Primer inicio de sesión. Por favor cambia tu contraseña....', 'Importante');
                app.CerrarButton.Enable = 'off';
            end
            
            query = ['SELECT correo ' ...
                'FROM humana.usuarios WHERE usuario= "' ...
                char(app.usuario)...
                '"'];
            data = fetch(app.Conexion,query);
            
            app.CorreoEditField.Value = string(data.correo);
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            delete(app)
        end

        % Value changing function: NuevacontraseaEditField
        function NuevacontraseaEditFieldValueChanging(app, event)
            changingValue = event.Value;
            temp = "*";
            if strlength(app.pass1EditField.Value) ~= 0
                if strlength(changingValue) < strlength(app.pass1EditField.Value)
                    if strlength(changingValue) == 0
                        app.pass1EditField.Value = "";
                    else
                        app.pass1EditField.Value = app.pass1EditField.Value(1:strlength(changingValue));
                    end
                else
                    app.pass1EditField.Value = strcat(app.pass1EditField.Value, changingValue(end));
                end
            else
                app.pass1EditField.Value = changingValue;
            end
            if strlength(changingValue)>0
                for i=1:strlength(changingValue)-1
                    temp = strcat(temp,"*");
                end
                app.NuevacontraseaEditField.Value = temp;
            else
                app.NuevacontraseaEditField.Value = "";
            end
        end

        % Value changing function: ConfirmarcontraseaEditField
        function ConfirmarcontraseaEditFieldValueChanging(app, event)
            changingValue = event.Value;
            temp = "*";
            if strlength(app.pass2EditField.Value) ~= 0
                if strlength(changingValue) < strlength(app.pass2EditField.Value)
                    if strlength(changingValue) == 0
                        app.pass2EditField.Value = "";
                    else
                        app.pass2EditField.Value = app.pass2EditField.Value(1:strlength(changingValue));
                    end
                else
                    app.pass2EditField.Value = strcat(app.pass2EditField.Value, changingValue(end));
                end
            else
                app.pass2EditField.Value = changingValue;
            end
            if strlength(changingValue)>0
                for i=1:strlength(changingValue)-1
                    temp = strcat(temp,"*");
                end
                app.ConfirmarcontraseaEditField.Value = temp;
            else
                app.ConfirmarcontraseaEditField.Value = "";
            end
        end

        % Button pushed function: CambiarButton
        function CambiarButtonPushed(app, event)
            con = app.Conexion;
            campos1 = false;
            campos2 = false;
            
            if (strcmp(app.NuevacontraseaEditField.Value,'') || strcmp(app.ConfirmarcontraseaEditField.Value,''))
                errordlg(['Error. Campos: Nueva contraseña y Confirmar contraseña ' ...
                'son requeridos.'],'Error');
            else
                campos1 = true;
            end
            
            if (strcmp(app.pass1EditField.Value, app.pass2EditField.Value))
                campos2 = true;
            else
                 errordlg(['Error. Nueva contraseña y Confirmar contraseña no coinciden.'],'Error');
            end
            
            if (campos1 && campos2)
                cambiarpass = strcat("ALTER USER ", app.usuario);
                cambiarpass = strcat(cambiarpass, " IDENTIFIED BY '");
                cambiarpass = strcat(cambiarpass, app.pass1EditField.Value);
                cambiarpass = strcat(cambiarpass, "'");
                execute(con, cambiarpass);
                
                if app.plogin
                    updateplogin = "UPDATE humana.usuarios SET primer_login = false WHERE usuario = '";
                    updateplogin = strcat(updateplogin, app.usuario);
                    updateplogin = strcat(updateplogin, "'");
                    execute(con, updateplogin);
                end
                
                msgbox('Contraseña actualizada exitosamente.', 'Mensaje');
                delete(app)
            end
            
        end

        % Button pushed function: CambiarButton_2
        function CambiarButton_2Pushed(app, event)
            campo1 = false;
            
            if (strcmp(app.CorreoEditField.Value,''))
                errordlg(['Error. Campo Correo ' ...
                'es requerido.'],'Error');
            else
                campo1 = true;
            end
            
            if campo1
                con = app.Conexion;
                
                updaterol = "UPDATE humana.usuarios SET correo = '" ;
                updaterol = strcat(updaterol, app.CorreoEditField.Value);
                updaterol = strcat(updaterol, "' WHERE usuario = '");
                updaterol = strcat(updaterol, app.usuario);
                updaterol = strcat(updaterol, "'");
                execute(con, updaterol);
                
                msgbox('Correo actualizado exitosamente.', 'Mensaje');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ConfiguracinUIFigure = uifigure('Visible', 'off');
            app.ConfiguracinUIFigure.Name = 'Configuración';
            app.ConfiguracinUIFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.Image = uiimage(app.ConfiguracinUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'config.svg');
            app.CambiarcontraseaLabel = uilabel(app.ConfiguracinUIFigure);
            app.CambiarcontraseaLabel.Text = 'Cambiar contraseña';
            app.NuevacontraseaEditFieldLabel = uilabel(app.ConfiguracinUIFigure);
            app.NuevacontraseaEditFieldLabel.Text = 'Nueva contraseña';
            app.NuevacontraseaEditField = uieditfield(app.ConfiguracinUIFigure, 'text');
            app.NuevacontraseaEditField.ValueChangingFcn = createCallbackFcn(app, @NuevacontraseaEditFieldValueChanging, true);
            app.ConfirmarcontraseaEditFieldLabel = uilabel(app.ConfiguracinUIFigure);
            app.ConfirmarcontraseaEditFieldLabel.Text = 'Confirmar contraseña';
            app.ConfirmarcontraseaEditField = uieditfield(app.ConfiguracinUIFigure, 'text');
            app.ConfirmarcontraseaEditField.ValueChangingFcn = createCallbackFcn(app, @ConfirmarcontraseaEditFieldValueChanging, true);
            app.CambiarButton = uibutton(app.ConfiguracinUIFigure, 'push');
            app.CambiarButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarButtonPushed, true);
            app.CambiarButton.Text = 'Cambiar';
            app.CerrarButton = uibutton(app.ConfiguracinUIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.Label = uilabel(app.ConfiguracinUIFigure);
            app.pass1EditFieldLabel = uilabel(app.ConfiguracinUIFigure);
            app.pass1EditFieldLabel.Enable = 'off';
            app.pass1EditFieldLabel.Visible = 'off';
            app.pass1EditFieldLabel.Text = 'pass1';
            app.pass1EditField = uieditfield(app.ConfiguracinUIFigure, 'text');
            app.pass1EditField.Editable = 'off';
            app.pass1EditField.Enable = 'off';
            app.pass1EditField.Visible = 'off';
            app.pass2EditFieldLabel = uilabel(app.ConfiguracinUIFigure);
            app.pass2EditFieldLabel.Enable = 'off';
            app.pass2EditFieldLabel.Visible = 'off';
            app.pass2EditFieldLabel.Text = 'pass2';
            app.pass2EditField = uieditfield(app.ConfiguracinUIFigure, 'text');
            app.pass2EditField.Editable = 'off';
            app.pass2EditField.Enable = 'off';
            app.pass2EditField.Visible = 'off';
            app.CorreoLabel = uilabel(app.ConfiguracinUIFigure);
            app.CorreoLabel.Text = 'Correo';
            app.CorreoEditFieldLabel = uilabel(app.ConfiguracinUIFigure);
            app.CorreoEditFieldLabel.Text = 'Correo';
            app.CorreoEditField = uieditfield(app.ConfiguracinUIFigure, 'text');
            app.CambiarButton_2 = uibutton(app.ConfiguracinUIFigure, 'push');
            app.CambiarButton_2.ButtonPushedFcn = createCallbackFcn(app, @CambiarButton_2Pushed, true);
            app.CambiarButton_2.Text = 'Cambiar';
            % Show the figure after all components are created
            app.ConfiguracinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = configuracion(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConfiguracinUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConfiguracinUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfiguracinUIFigure)
        end
    end
end