classdef consultas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConsultasUIFigure             matlab.ui.Figure
        DatosconfidencialesButton     matlab.ui.control.StateButton
        NotasconfidencialesTextArea   matlab.ui.control.TextArea
        NotasconfidencialesTextAreaLabel  matlab.ui.control.Label
        ApellidoEditField             matlab.ui.control.EditField
        ApellidoEditFieldLabel        matlab.ui.control.Label
        NombreEditField               matlab.ui.control.EditField
        NombreEditFieldLabel          matlab.ui.control.Label
        DescargarpruebasyborrarpruebasPanel  matlab.ui.container.Panel
        id_prueba2                    matlab.ui.control.EditField
        IDPruebaEditFieldLabel        matlab.ui.control.Label
        BorrarButton                  matlab.ui.control.Button
        id_prueba                     matlab.ui.control.EditField
        IDPruebaLabel                 matlab.ui.control.Label
        DescargarpuebasCheckBox       matlab.ui.control.CheckBox
        BorrarpruebasCheckBox         matlab.ui.control.CheckBox
        DescargarButton               matlab.ui.control.Button
        ActualizarButton              matlab.ui.control.Button
        RegresarButton                matlab.ui.control.Button
        NuevaconsultaButton           matlab.ui.control.Button
        BuscarButton                  matlab.ui.control.Button
        AgregarnuevoarchivoPanel      matlab.ui.container.Panel
        NoCanalesEditField            matlab.ui.control.EditField
        NoCanalesEditFieldLabel       matlab.ui.control.Label
        FrecuenciaHzEditField         matlab.ui.control.EditField
        FrecuenciaHzEditFieldLabel    matlab.ui.control.Label
        FechadelexamenEditField       matlab.ui.control.EditField
        FechadelexamenEditFieldLabel  matlab.ui.control.Label
        GuardarButton                 matlab.ui.control.Button
        SeleccionarButton             matlab.ui.control.Button
        DuracinsEditField             matlab.ui.control.EditField
        DuracinsEditFieldLabel        matlab.ui.control.Label
        HoradeinicioEditField         matlab.ui.control.EditField
        HoradeinicioEditFieldLabel    matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        CondicinEditField             matlab.ui.control.EditField
        CondicinEditFieldLabel        matlab.ui.control.Label
        DiagnsticodepreescripcinTextArea  matlab.ui.control.TextArea
        DiagnsticodepreescripcinTextAreaLabel  matlab.ui.control.Label
        AntecedentespersonalespatolgicosTextArea  matlab.ui.control.TextArea
        AntecedentespersonalespatolgicosTextAreaLabel  matlab.ui.control.Label
        FechadeNacimientoEditField    matlab.ui.control.EditField
        FechadeNacimientoEditFieldLabel  matlab.ui.control.Label
        SexoEditField                 matlab.ui.control.EditField
        SexoEditFieldLabel            matlab.ui.control.Label
        IngreseelcdigodePacienteEditField  matlab.ui.control.EditField
        IngreseelcdigodePacienteEditFieldLabel  matlab.ui.control.Label
        ConsultadepacientesypruebasLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        datos
        Conexion
        Estadoc
        user
        cconfidencial
    end
    
    methods (Access = public)
        
        function refreshTabla(app)
            id_paciente = app.IngreseelcdigodePacienteEditField.Value;
            global p;
            p.MyData=[];
            app.UITable.Data = {};

            conn = app.Conexion;

            query = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE id_paciente = "' ...
                id_paciente...
                '"'];
            
            data2 = fetch(conn,query);

            % Mostrar datos en tablas
            dimension=size(data2);
            for i=1:dimension(1)
                prueba = sprintf('%d',data2.id_prueba(i));
                fecha = sprintf('%s',cell2mat(data2.fecha_prueba(i)));
                hora = sprintf('%s',cell2mat(data2.hora_inicio(i)));
                duracion = sprintf('%d',data2.duracion(i));
                frecuencia = sprintf('%d',data2.frecuencia(i));
                canales = sprintf('%d',data2.no_canales(i));
                p.MyData=[p.MyData; [{i} {prueba} {fecha} {hora} {duracion} {frecuencia} {canales}]];
            end
            app.UITable.Data = p.MyData;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con, user)
            movegui(app.ConsultasUIFigure,"center");
            app.CallingApp = mainapp;
            global p
            p.MyData=[];
            app.Conexion = con;
            app.user = user;
            
            query = ['SELECT * ' ...
                'FROM humana.usuarios WHERE usuario= "' ...
                user...
                '"'];
            data = fetch(app.Conexion,query);
            if (data.consulta_confidencial == 1)
                app.DatosconfidencialesButton.Enable = 'on';
                app.cconfidencial = 1;
            else
                app.cconfidencial = 0;
            end
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
%             app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
%             app.CallingApp.ConsultarButton.Enable = 'on';
%             app.CallingApp.AnalizarpruebaButton.Enable = 'on';
%             app.CallingApp.DesconectarButton.Enable = 'on';
            delete(app)
        end

        % Close request function: ConsultasUIFigure
        function ConsultasUIFigureCloseRequest(app, event)
%             app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
%             app.CallingApp.ConsultarButton.Enable = 'on';
%             app.CallingApp.AnalizarpruebaButton.Enable = 'on';
%             app.CallingApp.DesconectarButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            global p
            id_paciente = app.IngreseelcdigodePacienteEditField.Value;
            p.MyData=[];
            app.UITable.Data = p.MyData;
            %------ Conexion con la base de datos------------------------
            conn = app.Conexion;
            if app.cconfidencial == 1
                query = ['SELECT * ' ...
                'FROM humana.pacientes WHERE pacientes.id_paciente= "' ...
                id_paciente...
                '"']; %Se busca en la tabla el ID del paciente, junto con los nombres
            else
                query = ['SELECT id_paciente, sexo, fecha_nacimiento, antecedentes, diagnostico, condicion ' ...
                'FROM humana.pacientes WHERE pacientes.id_paciente= "' ...
                id_paciente...
                '"']; %Se busca y se selccionan algunos datos. Sin la informacion confidencial
            end
            
            query2 = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '"']; %Se buscan en la tabla de pruebas, las pruebas asociadas a dicho ID Paciente
            
            
            data = fetch(conn,query);
            data2 = fetch(conn,query2);
            
            if(isempty(data)==1)
                fig = app.ConsultasUIFigure;
                message = 'Paciente no encontrado';
                uialert(fig ,message,'Error','Icon','error');%Acualizacion a ultimo formato 
                
                ini = char(' ');
                p.MyData=[];
                app.IngreseelcdigodePacienteEditField.Value = ini;
                app.SexoEditField.Value = ini;
                app.FechadeNacimientoEditField.Value = ini;
                app.CondicinEditField.Value = ini;
                app.AntecedentespersonalespatolgicosTextArea.Value = ini;
                app.DiagnsticodepreescripcinTextArea.Value = ini;
                app.UITable.Data = p.MyData;
                
            else
                app.SexoEditField.Value = sprintf('%s',cell2mat(data.sexo));
                app.FechadeNacimientoEditField.Value = sprintf('%s',cell2mat(data.fecha_nacimiento));
                cond = sprintf('%s',cell2mat(data.condicion));
                if strcmp(cond,'SANO')
                    app.CondicinEditField.Value = 'No padece epilepsia';
                else
                    app.CondicinEditField.Value = 'Padece epilepsia';
                end
                app.AntecedentespersonalespatolgicosTextArea.Value = sprintf('%s',cell2mat(data.antecedentes));
                app.DiagnsticodepreescripcinTextArea.Value = sprintf('%s',cell2mat(data.diagnostico));
                if app.cconfidencial == 1
                    app.NotasconfidencialesTextArea.Value = sprintf('%s',cell2mat(data.notas_confidenciales));
                    app.NombreEditField.Value = char(data.nombre);
                    app.ApellidoEditField.Value = char(data.apellido);
                end
                % Mostrar datos en tablas
                dimension=size(data2);
                for i=1:dimension(1)
                    prueba = sprintf('%d',data2.id_prueba(i));
                    fecha = sprintf('%s',cell2mat(data2.fecha_prueba(i)));
                    hora = sprintf('%s',cell2mat(data2.hora_inicio(i)));
                    duracion = sprintf('%d',data2.duracion(i));
                    frecuencia = sprintf('%d',data2.frecuencia(i));
                    canales = sprintf('%d',data2.no_canales(i));
                    p.MyData=[p.MyData; [{i} {prueba} {fecha} {hora} {duracion} {frecuencia} {canales}]];
                end
                app.UITable.Data = p.MyData;
            end
            
            %Clear variables
            clear query query2
        end

        % Button pushed function: NuevaconsultaButton
        function NuevaconsultaButtonPushed(app, event)
            global p    
            ini = char('');
            p.MyData=[];
            app.IngreseelcdigodePacienteEditField.Value = ini;
            app.SexoEditField.Value = ini;
            app.FechadeNacimientoEditField.Value = ini;
            app.CondicinEditField.Value = ini;
            app.AntecedentespersonalespatolgicosTextArea.Value = ini;
            app.DiagnsticodepreescripcinTextArea.Value = ini;
            app.UITable.Data = p.MyData;
            app.HoradeinicioEditField.Value = ini;
            app.DuracinsEditField .Value = ini;
            app.FechadelexamenEditField.Value = ini;
            app.FrecuenciaHzEditField.Value = ini;
            app.NoCanalesEditField.Value = ini;

            app.NotasconfidencialesTextArea.Value = "";
            app.NombreEditField.Value = "";
            app.ApellidoEditField.Value = "";
            
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            global p
            p.MyData=[];
            ini = char('');
            app.HoradeinicioEditField.Value = ini;
            app.DuracinsEditField .Value = ini;
            app.FechadelexamenEditField.Value = ini;
            app.FrecuenciaHzEditField.Value = ini;
            app.NoCanalesEditField.Value = ini;
            app.UITable.Data = p.MyData;
            id_paciente=app.IngreseelcdigodePacienteEditField.Value;
            conn = app.Conexion;
            
            %Set query to execute on the database
            query = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '"'];
            
            data = fetch(conn,query);
            
            dimension=size(data);
            for i=1:dimension(1)
                prueba = sprintf('%d',data.id_prueba(i));
                fecha = sprintf('%s',cell2mat(data.fecha_prueba(i)));
                hora = sprintf('%s',cell2mat(data.hora_inicio(i)));
                duracion = sprintf('%d',data.duracion(i));
                freq = sprintf('%d',data.frecuencia(i));
                canales = sprintf('%d',data.no_canales(i));
                p.MyData=[p.MyData; [{i} {prueba} {fecha} {hora} {duracion} {freq} {canales}]];
            end
            app.UITable.Data = p.MyData;
            
            %Clear variables
            clear query
        end

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
          
            [file,path,indx] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            
            [hdr, record] = edfread(strcat(path,file));
            tinicio = sprintf('%s',hdr.starttime);
            finicio = sprintf('%s',hdr.startdate);
            duracions = sprintf('%d',hdr.records);
            frecuencia = sprintf('%d',hdr.frequency(1));
            canales = sprintf('%d',hdr.ns);
            app.HoradeinicioEditField.Value = tinicio;
            app.DuracinsEditField.Value = duracions;
            app.FechadelexamenEditField.Value = finicio;
            app.FrecuenciaHzEditField.Value = frecuencia;
            app.NoCanalesEditField.Value = canales;
            serializedData = serialize(record);
            
            app.datos=record;
            
            % Guardar los vectores del archivo original en archivo.bin
            fid = fopen('archivo.bin', 'w');
            fwrite(fid, serializedData, '*uint8');
            fclose(fid);

            %Habilitar botón guardar
            app.GuardarButton.Enable = "on";
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
        id_paciente = app.IngreseelcdigodePacienteEditField.Value;
        tinicio = app.HoradeinicioEditField.Value;
        duracions = str2double(app.DuracinsEditField.Value);
        finicio = app.FechadelexamenEditField.Value;
        frecuencia = str2double(app.FrecuenciaHzEditField.Value);
        no_canales = str2double(app.NoCanalesEditField.Value);
        tic
        if(no_canales > 35) %La base de datos solo soporta 35 canales
            fig = app.ConsultasUIFigure;
            message = 'La base de datos solo soporta 35 canales, no es posible guardar esta señal';
            uialert(fig ,message,'Error','Icon','error');
        else  
            fig = app.ConsultasUIFigure;
            message = 'Esto puede tardar varios minutos...';
            uialert(fig ,message,'Aviso','Icon','warning');

            %Reemplazar . por :
            tinicio = strrep(tinicio,'.',':');
            finicio = strrep(finicio,'.',':');
             conn = app.Conexion;
     
            nuevo = {id_paciente, finicio, tinicio, duracions, frecuencia, no_canales, 0x1A};      
            datawrite = cell2table(nuevo); %Convertimos la informacion a una tabla
            datawrite.Properties.VariableNames = ["id_paciente", "fecha_prueba", "hora_inicio", "duracion", "frecuencia", "no_canales", "prueba"];
            %Nuevo formato para trabajar sqlwrite
            sqlwrite(conn, 'humana.pruebas', datawrite); %reemplazar insert por sqlwrite
            %Obtener el id de la última prueba ingresada
            query = 'SELECT max(id_prueba) AS ultimaprueba from humana.pruebas';
            data = fetch(conn,query);
            id_prueba = data.ultimaprueba; %Se obtiene la ultima id_prueba de la tabla pruebas
           
            record = app.datos;
            
            data_eeg= record';                       %Se traspone debido a que record tiene los canales en formato de fila (:,3:end)
           
           
            onesithxs = floor(length(data_eeg)/6);
            twositxhs = floor(length(data_eeg)*2/6);
            half = floor(length(data_eeg)/2);
            foursithxs = floor(length(data_eeg)*4/6);
            fivesitxhs = floor(length(data_eeg)*5/6);
            parfor n = 1:6
                if n == 1
                    fid1 = fopen('C:\ProgramData\MySQL\MySQL Server 8.0\Uploads\tmp1.txt','w')
                    for j = 1:onesithxs
                        %id_key = id_key+1;                                      %Ya no es necesario generar el ID key debido a que es una columna invisible
                        fprintf(fid1,'%i;', id_prueba);
                        for i = 1:no_canales
                            fprintf(fid1,'%1.5e;', data_eeg(j,i));
                        end
                        for i = no_canales+1:35                                 %Ponemos 0.0 a partir del ultimo canal +1 hasta el cana 35
                            fprintf(fid1,'0.0;');
                        end
                        fprintf(fid1, '\n');
                    end
                    fclose(fid1);
                elseif n==2
                    fid2 = fopen('C:\ProgramData\MySQL\MySQL Server 8.0\Uploads\tmp2.txt','w')
                    for j = onesithxs+1:twositxhs
                        %id_key = id_key+1;                                      %Ya no es necesario generar el ID key debido a que es una columna invisible
                        fprintf(fid2,'%i;', id_prueba);
                        for i = 1:no_canales
                            fprintf(fid2,'%1.5e;', data_eeg(j,i));
                        end
                        for i = no_canales+1:35                                 %Ponemos 0.0 a partir del ultimo canal +1 hasta el cana 35
                            fprintf(fid2,'0.0;');
                        end
                        fprintf(fid2, '\n');
                    end
                    fclose(fid2);
                elseif n==3
                    fid3 = fopen('C:\ProgramData\MySQL\MySQL Server 8.0\Uploads\tmp3.txt','w')
                    for j = twositxhs+1:half
                        %id_key = id_key+1;                                      %Ya no es necesario generar el ID key debido a que es una columna invisible
                        fprintf(fid3,'%i;', id_prueba);
                        for i = 1:no_canales
                            fprintf(fid3,'%1.5e;', data_eeg(j,i));
                        end
                        for i = no_canales+1:35                                 %Ponemos 0.0 a partir del ultimo canal +1 hasta el cana 35
                            fprintf(fid3,'0.0;');
                        end
                        fprintf(fid3, '\n');
                    end
                    fclose(fid3);
                elseif n == 4
                    fid4 = fopen('C:\ProgramData\MySQL\MySQL Server 8.0\Uploads\tmp4.txt','w')
                    for j = half+1:foursithxs
                        %id_key = id_key+1;                                      %Ya no es necesario generar el ID key debido a que es una columna invisible
                        fprintf(fid4,'%i;', id_prueba);
                        for i = 1:no_canales
                            fprintf(fid4,'%1.5e;', data_eeg(j,i));
                        end
                        for i = no_canales+1:35                                 %Ponemos 0.0 a partir del ultimo canal +1 hasta el cana 35
                            fprintf(fid4,'0.0;');
                        end
                        fprintf(fid4, '\n');
                    end
                    fclose(fid4);
                elseif n==5
                    fid5 = fopen('C:\ProgramData\MySQL\MySQL Server 8.0\Uploads\tmp5.txt','w')
                    for j = foursithxs+1:fivesitxhs
                        %id_key = id_key+1;                                      %Ya no es necesario generar el ID key debido a que es una columna invisible
                        fprintf(fid5,'%i;', id_prueba);
                        for i = 1:no_canales
                            fprintf(fid5,'%1.5e;', data_eeg(j,i));
                        end
                        for i = no_canales+1:35                                 %Ponemos 0.0 a partir del ultimo canal +1 hasta el cana 35
                            fprintf(fid5,'0.0;');
                        end
                        fprintf(fid5, '\n');
                    end
                    fclose(fid5);
                elseif n==6
                    fid6 = fopen('C:\ProgramData\MySQL\MySQL Server 8.0\Uploads\tmp6.txt','w')
                    for j = fivesitxhs+1:length(data_eeg)
                        %id_key = id_key+1;                                      %Ya no es necesario generar el ID key debido a que es una columna invisible
                        fprintf(fid6,'%i;', id_prueba);
                        for i = 1:no_canales
                            fprintf(fid6,'%1.5e;', data_eeg(j,i));
                        end
                        for i = no_canales+1:35                                 %Ponemos 0.0 a partir del ultimo canal +1 hasta el cana 35
                            fprintf(fid6,'0.0;');
                        end
                        fprintf(fid6, '\n');
                    end
                    fclose(fid6);
                else
                end
            end
            
            execute(conn,['LOAD DATA INFILE ' ...
            ' ''C:\\ProgramData\\MySQL\\MySQL Server 8.0\\Uploads\\tmp1.txt'' INTO TABLE humana.pruebas_datos ' ...
            'FIELDS TERMINATED BY '';'' LINES TERMINATED ' ...
            'BY ''\n''']) %Instruccion de guardado a partir de archvio de texto

            execute(conn,['LOAD DATA INFILE ' ...
            ' ''C:\\ProgramData\\MySQL\\MySQL Server 8.0\\Uploads\\tmp2.txt'' INTO TABLE humana.pruebas_datos ' ...
            'FIELDS TERMINATED BY '';'' LINES TERMINATED ' ...
            'BY ''\n''']) %Instruccion de guardado a partir de archvio de texto
           
            execute(conn,['LOAD DATA INFILE ' ...
            ' ''C:\\ProgramData\\MySQL\\MySQL Server 8.0\\Uploads\\tmp3.txt'' INTO TABLE humana.pruebas_datos ' ...
            'FIELDS TERMINATED BY '';'' LINES TERMINATED ' ...
            'BY ''\n''']) %Instruccion de guardado a partir de archvio de texto

             execute(conn,['LOAD DATA INFILE ' ...
            ' ''C:\\ProgramData\\MySQL\\MySQL Server 8.0\\Uploads\\tmp4.txt'' INTO TABLE humana.pruebas_datos ' ...
            'FIELDS TERMINATED BY '';'' LINES TERMINATED ' ...
            'BY ''\n''']) %Instruccion de guardado a partir de archvio de texto

            execute(conn,['LOAD DATA INFILE ' ...
            ' ''C:\\ProgramData\\MySQL\\MySQL Server 8.0\\Uploads\\tmp5.txt'' INTO TABLE humana.pruebas_datos ' ...
            'FIELDS TERMINATED BY '';'' LINES TERMINATED ' ...
            'BY ''\n''']) %Instruccion de guardado a partir de archvio de texto
           
            execute(conn,['LOAD DATA INFILE ' ...
            ' ''C:\\ProgramData\\MySQL\\MySQL Server 8.0\\Uploads\\tmp6.txt'' INTO TABLE humana.pruebas_datos ' ...
            'FIELDS TERMINATED BY '';'' LINES TERMINATED ' ...
            'BY ''\n''']) %Instruccion de guardado a partir de archvio de texto

            toc
            %Termina BULK INSERT
            refreshTabla(app);
            fig = app.ConsultasUIFigure;
            message = 'Se ha guardado correctamente';
            uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato
                       
            %Clear variables
            clear query
            %deshabilitar botón guardar
            app.GuardarButton.Enable = "off";
        end
        end

        % Value changed function: DatosconfidencialesButton
        function DatosconfidencialesButtonValueChanged(app, event)
            value = app.DatosconfidencialesButton.Value;
            if value == 1
                app.SexoEditField.Visible = 'off';
                app.SexoEditFieldLabel.Visible = 'off';
                app.FechadeNacimientoEditField.Visible = 'off';
                app.FechadeNacimientoEditFieldLabel.Visible = 'off';
                app.AntecedentespersonalespatolgicosTextArea.Visible = 'off';
                app.AntecedentespersonalespatolgicosTextAreaLabel.Visible = 'off';
                app.DiagnsticodepreescripcinTextArea.Visible = 'off';
                app.DiagnsticodepreescripcinTextAreaLabel.Visible = 'off';
                app.CondicinEditField.Visible = 'off';
                app.CondicinEditFieldLabel.Visible = 'off';
                
                app.NombreEditField.Visible = 'on';
                app.NombreEditFieldLabel.Visible = 'on';
                app.ApellidoEditField.Visible = 'on';
                app.ApellidoEditFieldLabel.Visible = 'on';
                app.NotasconfidencialesTextArea.Visible = 'on';
                app.NotasconfidencialesTextAreaLabel.Visible = 'on';
            else
                app.SexoEditField.Visible = 'on';
                app.SexoEditFieldLabel.Visible = 'on';
                app.FechadeNacimientoEditField.Visible = 'on';
                app.FechadeNacimientoEditFieldLabel.Visible = 'on';
                app.AntecedentespersonalespatolgicosTextArea.Visible = 'on';
                app.AntecedentespersonalespatolgicosTextAreaLabel.Visible = 'on';
                app.DiagnsticodepreescripcinTextArea.Visible = 'on';
                app.DiagnsticodepreescripcinTextAreaLabel.Visible = 'on';
                app.CondicinEditField.Visible = 'on';
                app.CondicinEditFieldLabel.Visible = 'on';
                
                app.NombreEditField.Visible = 'off';
                app.NombreEditFieldLabel.Visible = 'off';
                app.ApellidoEditField.Visible = 'off';
                app.ApellidoEditFieldLabel.Visible = 'off';
                app.NotasconfidencialesTextArea.Visible = 'off';
                app.NotasconfidencialesTextAreaLabel.Visible = 'off';
            end
            
        end

        % Button pushed function: DescargarButton
        function DescargarButtonPushed(app, event)
            id_paciente = app.IngreseelcdigodePacienteEditField.Value;
            id_prueba = app.id_prueba.Value;
            

            todos = app.BorrarpruebasCheckBox.Value; %Valor de los checkboxes de todos o seleccionar
            numero = app.DescargarpuebasCheckBox.Value;
            
            if isempty(id_paciente)
                fig = app.ConsultasUIFigure;
                message = 'Ingrese código de paciente';
                uialert(fig ,message,'Error','Icon','error');%Acualizacion a ultimo formato 
            else
                conn = app.Conexion;
                query = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '"'];
                data = fetch(conn,query); %Solicitamos la informacion general de la prubea

                query_datos = ['SELECT * FROM humana.pruebas_datos WHERE pruebas_datos.id_prueba ="' ...
                        id_prueba ...
                        '"']
                data_eeg = fetch(conn,query_datos); %Solicitamos los datos de la prueba
               
                
                %Preparacion para generar archivo
                %Header.txt 
                value = app.UITable.Selection(1); 
                
                fecha = sprintf('%s',cell2mat(data.fecha_prueba(value)));
                hora = sprintf('%s',cell2mat(data.hora_inicio(value)));
                duracion = sprintf('%d',data.duracion(value));
                freq = sprintf('%d',data.frecuencia(value));
                canales = sprintf('%d',data.no_canales(value));

                no_canales = data.no_canales(value);

                startingFolder = 'C:\Program Files\MATLAB';
                if ~exist(startingFolder, 'dir')
                    startingFolder = pwd;
                end
                
                %Para que se asigne codigo de paciente y numero de prueba
                %al momento de ponerle nombre al archivo. Tanto el header
                %como la data se almacenan en la misma ubicacion.

                name = sprintf('CodigoPax_%s_PruebaNo_%s', id_paciente, id_prueba);

                % Put in the name of the mat file that the user wants to save.
                [file,path] = uiputfile(sprintf('%s.txt', name),'Seleccione ruta y nombre de descarga');
                if file == 0
                    % User clicked the Cancel button.
                    fig = app.ConsultasUIFigure;
                    message = 'El usuario cancelo la operación.';
                    uialert(fig ,message,'Aviso','Icon','warning');%Acualizacion a ultimo formato
                
                    return;
                end
                fig = app.ConsultasUIFigure;
                message = 'Esto puede tardar unos minutos...';
                uialert(fig ,message,'Aviso','Icon','warning');%Acualizacion a ultimo formato
                
                info = [{id_paciente} {id_prueba} {fecha} {hora} {duracion} {freq} {canales}];
                colum_name = [{'id_paciente'} {'id_prueba'} {'fecha_prueba'} {'hora_inicio'} {'duracion(s)'} {'frecuencia(Hz)'} {'no_canales'}];
                header = [colum_name;info]; %Consolidado de datos
                fullFileName = fullfile(path, file);
                writecell(header,fullFileName,'Delimiter',';'); %save file .txt


                % Usaremos el mismo path que el Header, solo que cambio en el nombre.
                fullFileNameDatos = fullfile(path, sprintf('%s_Data.txt', name)); %Direccion de almacenamiento
                
                
                header_datos = cell(1,no_canales+1);
                header_datos(1,1) = {'id_prueba'};

                %Se asigna los titulos de la tabla datos
                for m = (1:no_canales)
                    string_canal = sprintf('no. canal %d', m);
                    header_datos{1, (m+1)} = string_canal;
                end
                data_eeg = table2cell(data_eeg);
                data_eeg = cell2mat(data_eeg);

                fid = fopen(fullFileNameDatos,'w')
                fprintf(fid,'%s;', header_datos{:});
                for j = 1:length(data_eeg)                                  %Movimiento en Filas
                    %id_key = id_key+1;                                      % Ya no es necesario calcular el ID, ya que es una columna invisible
                    fprintf(fid,'%i;', id_prueba);
                    for i = 1:no_canales
                        fprintf(fid,'%1.5e;', data_eeg(j,i));
                    end
                  
                    fprintf(fid, '\n');
                end
                fclose(fid);
                
                fig = app.ConsultasUIFigure;
                message = 'Se han descargado correctamente';
                uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato
            end
                
                %Clear variables
                clear query
            
        end

        % Value changed function: BorrarpruebasCheckBox
        function BorrarpruebasCheckBoxValueChanged(app, event)
            value = app.BorrarpruebasCheckBox.Value;
            if value==1
                %Borrar datos enabled
                app.BorrarButton.Enable="on";
                app.id_prueba.Enable ="on"; 
                %Descarga disabled
                app.id_prueba2.Enable='off';
                app.DescargarpuebasCheckBox.Enable='off';
                app.DescargarButton.Enable = "off";

            else 
                %Descarga enable
                app.DescargarpuebasCheckBox.Enable='on';
                %Borrar datos disable
                app.id_prueba.Enable='off';
                app.BorrarButton.Enable="off";
                
            end
        end

        % Value changed function: DescargarpuebasCheckBox
        function DescargarpuebasCheckBoxValueChanged(app, event)
            value = app.DescargarpuebasCheckBox.Value;
            if value==1
                %Descargar datos on
                app.DescargarButton.Enable = "on";
                app.id_prueba2.Enable ="on";
                %Borrar datos off
                app.BorrarButton.Enable="off";
                app.id_prueba.Enable ="off"; 
                app.BorrarpruebasCheckBox.Enable='off';
            else 
                %Descargar datos disable
                app.DescargarButton.Enable = "off";
                app.id_prueba2.Enable ="off";
                %Borrar enable
                app.BorrarpruebasCheckBox.Enable='on';
            end
        end

        % Button pushed function: BorrarButton
        function BorrarButtonPushed(app, event)
            conn = app.Conexion;
            selection = app.UITable.Selection;
            fig = app.ConsultasUIFigure;
            
            if ((selection(:,2) ~= 2 )|| isempty(selection));%Indica que se debe de seleccionar en la columna prueba
              message = 'Seleccione el numero de la prueba que desea borrar en la columna "Prueba".';
              uialert(fig ,message,'Error','Icon','error');%Acualizacion a ultimo formato

            elseif (selection(:,2) == 2); %Indica que los datos se borraran permanentemente
               message = '¡Precaución! Los datos serán borrados permanentemente. ¿Desea continuar?';
               
               borrar = uiconfirm(fig,message,'Aviso', ...
               'Options',{'No','Si'}, ...
               'DefaultOption',1,'CancelOption',1);
            end
            if(borrar == ('Si')) %Funcionamiento completo del borrado
                message = 'Esto puede tardar algunos minutos...';
                uialert(fig ,message,'Aviso','Icon','warning');%Acualizacion a ultimo formato

                data = app.UITable.Data(selection(1,1), 2); %Busca el num de prueba que se desea borrar.
                id = cell2mat(data);
                id_paciente = app.IngreseelcdigodePacienteEditField.Value;

                query_nocheck = 'SET foreign_key_checks = 0';
                execute(conn, query_nocheck);
                
               query = ['DELETE FROM humana.pruebas WHERE id_paciente = "' ...
                    id_paciente ...
                    '" AND id_prueba = "' ...
                    id ...
                    '"']; %Busca en base al id_paciente y el numero de prueba
                
                execute(conn,query);

                
                querydatos = ['DELETE FROM humana.pruebas_datos WHERE id_prueba = "' ...
                    id ...
                    '"']; %aqui solo se busca numero de prueba

                execute(conn, querydatos);

                query_check = 'SET foreign_key_checks = 1';
                execute(conn, query_check);


                %id = str2num(id); %Convertimos a numero por numero guardado en SQL 
                message = 'La información fue eliminada correctamente. ';
                uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato
                app.ActualizarButtonPushed();
            else
                
            end
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;

            if (~isempty(selection)) %Evaluamos si se selecciono un dato

                data_prueba = app.UITable.Data(selection(1), 2); %Dato seleccionado
                data_prueba = cell2mat(data_prueba); %Transformacion de cell a que contiene string 

                
                if (selection(2) == 2) %Evaluamos si se selecciono un dato de la columna pruebas
                    app.id_prueba2.Value = data_prueba;
                    app.id_prueba.Value = data_prueba;
                    
                end
            end
        end

        % Value changed function: IngreseelcdigodePacienteEditField
        function IngreseelcdigodePacienteEditFieldValueChanged(app, event)
            % Buscar solo con presionar enter
            app.BuscarButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ConsultasUIFigure = uifigure('Visible', 'off');
            app.ConsultasUIFigure.Name = 'Consultas';
            app.ConsultasUIFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.ConsultasUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConsultasUIFigureCloseRequest, true);
            app.ConsultadepacientesypruebasLabel = uilabel(app.ConsultasUIFigure);
            app.ConsultadepacientesypruebasLabel.Text = 'Consulta de pacientes y pruebas';
            app.IngreseelcdigodePacienteEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.IngreseelcdigodePacienteEditFieldLabel.Text = 'Ingrese el código de Paciente';
            app.IngreseelcdigodePacienteEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.IngreseelcdigodePacienteEditField.ValueChangedFcn = createCallbackFcn(app, @IngreseelcdigodePacienteEditFieldValueChanged, true);
            app.SexoEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.SexoEditFieldLabel.Text = 'Sexo';
            app.SexoEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.SexoEditField.Editable = 'off';
            app.FechadeNacimientoEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.FechadeNacimientoEditFieldLabel.Text = 'Fecha de Nacimiento';
            app.FechadeNacimientoEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.FechadeNacimientoEditField.Editable = 'off';
            app.AntecedentespersonalespatolgicosTextAreaLabel = uilabel(app.ConsultasUIFigure);
            app.AntecedentespersonalespatolgicosTextAreaLabel.Text = 'Antecedentes personales patológicos';
            app.AntecedentespersonalespatolgicosTextArea = uitextarea(app.ConsultasUIFigure);
            app.AntecedentespersonalespatolgicosTextArea.Editable = 'off';
            app.DiagnsticodepreescripcinTextAreaLabel = uilabel(app.ConsultasUIFigure);
            app.DiagnsticodepreescripcinTextAreaLabel.Text = 'Diagnóstico de preescripción';
            app.DiagnsticodepreescripcinTextArea = uitextarea(app.ConsultasUIFigure);
            app.DiagnsticodepreescripcinTextArea.Editable = 'off';
            app.CondicinEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.CondicinEditFieldLabel.Text = 'Condición';
            app.CondicinEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.CondicinEditField.Editable = 'off';
            app.UITable = uitable(app.ConsultasUIFigure);
            app.UITable.ColumnName = {'No.'; 'ID Prueba'; 'Fecha'; 'Hora'; 'Duración (s)'; 'Frecuencia (Hz)'; 'No. Canales'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.AgregarnuevoarchivoPanel = uipanel(app.ConsultasUIFigure);
            app.AgregarnuevoarchivoPanel.Title = 'Agregar nuevo archivo';
            app.HoradeinicioEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.HoradeinicioEditFieldLabel.Text = 'Hora de inicio';
            app.HoradeinicioEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.HoradeinicioEditField.Editable = 'off';
            app.DuracinsEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.DuracinsEditFieldLabel.Text = 'Duración (s)';
            app.DuracinsEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.DuracinsEditField.Editable = 'off';
            app.SeleccionarButton = uibutton(app.AgregarnuevoarchivoPanel, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.GuardarButton = uibutton(app.AgregarnuevoarchivoPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Enable = 'off';
            app.GuardarButton.Text = 'Guardar';
            app.FechadelexamenEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.FechadelexamenEditFieldLabel.Text = 'Fecha del examen';
            app.FechadelexamenEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.FechadelexamenEditField.Editable = 'off';
            app.FrecuenciaHzEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.FrecuenciaHzEditFieldLabel.Text = 'Frecuencia (Hz)';
            app.FrecuenciaHzEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.FrecuenciaHzEditField.Editable = 'off';
            app.NoCanalesEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.NoCanalesEditFieldLabel.Text = 'No. Canales';
            app.NoCanalesEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.NoCanalesEditField.Editable = 'off';
            app.BuscarButton = uibutton(app.ConsultasUIFigure, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.NuevaconsultaButton = uibutton(app.ConsultasUIFigure, 'push');
            app.NuevaconsultaButton.ButtonPushedFcn = createCallbackFcn(app, @NuevaconsultaButtonPushed, true);
            app.NuevaconsultaButton.Text = 'Nueva consulta';
            app.RegresarButton = uibutton(app.ConsultasUIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.ActualizarButton = uibutton(app.ConsultasUIFigure, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.DescargarpruebasyborrarpruebasPanel = uipanel(app.ConsultasUIFigure);
            app.DescargarpruebasyborrarpruebasPanel.Title = 'Descargar pruebas y borrar pruebas';
            app.DescargarButton = uibutton(app.DescargarpruebasyborrarpruebasPanel, 'push');
            app.DescargarButton.ButtonPushedFcn = createCallbackFcn(app, @DescargarButtonPushed, true);
            app.DescargarButton.Text = 'Descargar';
            app.BorrarpruebasCheckBox = uicheckbox(app.DescargarpruebasyborrarpruebasPanel);
            app.BorrarpruebasCheckBox.ValueChangedFcn = createCallbackFcn(app, @BorrarpruebasCheckBoxValueChanged, true);
            app.BorrarpruebasCheckBox.Text = 'Borrar pruebas';
            app.DescargarpuebasCheckBox = uicheckbox(app.DescargarpruebasyborrarpruebasPanel);
            app.DescargarpuebasCheckBox.ValueChangedFcn = createCallbackFcn(app, @DescargarpuebasCheckBoxValueChanged, true);
            app.DescargarpuebasCheckBox.Text = 'Descargar puebas';
            app.IDPruebaLabel = uilabel(app.DescargarpruebasyborrarpruebasPanel);
            app.IDPruebaLabel.Text = 'ID Prueba';
            app.id_prueba = uieditfield(app.DescargarpruebasyborrarpruebasPanel, 'text');
            app.id_prueba.InputType = 'digits';
            app.id_prueba.Editable = 'off';
            app.BorrarButton = uibutton(app.DescargarpruebasyborrarpruebasPanel, 'push');
            app.BorrarButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarButtonPushed, true);
            app.BorrarButton.Text = 'Borrar';
            app.IDPruebaEditFieldLabel = uilabel(app.DescargarpruebasyborrarpruebasPanel);
            app.IDPruebaEditFieldLabel.Text = 'ID Prueba';
            app.id_prueba2 = uieditfield(app.DescargarpruebasyborrarpruebasPanel, 'text');
            app.id_prueba2.InputType = 'digits';
            app.id_prueba2.Editable = 'off';
            app.NombreEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.NombreEditFieldLabel.Visible = 'off';
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.NombreEditField.Editable = 'off';
            app.NombreEditField.Visible = 'off';
            app.ApellidoEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.ApellidoEditFieldLabel.Visible = 'off';
            app.ApellidoEditFieldLabel.Text = 'Apellido';
            app.ApellidoEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.ApellidoEditField.Editable = 'off';
            app.ApellidoEditField.Visible = 'off';
            app.NotasconfidencialesTextAreaLabel = uilabel(app.ConsultasUIFigure);
            app.NotasconfidencialesTextAreaLabel.Visible = 'off';
            app.NotasconfidencialesTextAreaLabel.Text = 'Notas confidenciales';
            app.NotasconfidencialesTextArea = uitextarea(app.ConsultasUIFigure);
            app.NotasconfidencialesTextArea.Editable = 'off';
            app.NotasconfidencialesTextArea.Visible = 'off';
            app.DatosconfidencialesButton = uibutton(app.ConsultasUIFigure, 'state');
            app.DatosconfidencialesButton.ValueChangedFcn = createCallbackFcn(app, @DatosconfidencialesButtonValueChanged, true);
            app.DatosconfidencialesButton.Enable = 'off';
            app.DatosconfidencialesButton.Text = 'Datos confidenciales';
            % Show the figure after all components are created
            app.ConsultasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = consultas(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConsultasUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConsultasUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConsultasUIFigure)
        end
    end
end