classdef credenciales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        cred                     matlab.ui.Figure
        Hyperlink                matlab.ui.control.Hyperlink
        DebugEditField           matlab.ui.control.EditField
        DebugEditFieldLabel      matlab.ui.control.Label
        IntroduzcasuscredencialesdeaccesoLabel  matlab.ui.control.Label
        ContraseaEditField       matlab.ui.control.EditField
        ContraseaEditFieldLabel  matlab.ui.control.Label
        UsuarioEditField         matlab.ui.control.EditField
        UsuarioEditFieldLabel    matlab.ui.control.Label
        ConectarButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            movegui(app.cred,"center");
            app.CallingApp = mainapp;
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            try
                % configureODBCDataSource
                con = database('toolbox', app.UsuarioEditField.Value, app.DebugEditField.Value);
                % disp('Conexión realizada correctamente');
                if ~isempty(con.UserName)
                    %La ventana se cierra automaticamnete despues de
                    %ingresar por lo que se movio el anuncio a la función
                    %activar() que se encuentra en principal.
                else
                    fig = app.cred;
                    message = 'Revise sus credenciales y vuelva a intentarlo. ';
                    uialert(fig ,message,'Error','Icon','error');
                end
                %app.Conexion = con;
                clear query
                activar(app.CallingApp, con, app.UsuarioEditField.Value)
                delete(app)
            catch exception
                if (strcmp(exception.identifier, 'database:database:ODBCDriverError'))
                    % disp('Ha ocurrido u error durante la conexión a la base de datos: ODBCDriverError');
                    fig = app.cred;
                    message = ['(1) Ha ocurrido un error durante la conexión a la base de datos. '...
                               'Verifique que la base de datos "HUMANA" esté creada con sus respectivas tablas.'];
                    uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
                    app.ConectarButton.Value=0;
                    return;
                elseif (strcmp(exception.identifier, 'database:database:dataSourceNameNotFound'))
                    % disp('Ha ocurrido un error durante la conexión a la base de datos: dataSourceNameNotFound. Por favor configure una conexión ODBC de nombre toolbox');
                    fig = app.cred;
                    message = ['(2) Ha ocurrido un error durante la conexión a la base de datos. '...
                               'Configure una conexión ODBC con el nombre "toolbox".'];
                    uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
                    
                    app.ConectarButton.Value=0;
                    return;
                else
                    fig = app.cred;
                    message = ['Error de inicio de sesión.' ...
                               'Revise sus credenciales.'];
                    uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
                end
                
                rethrow(exception);
            end
        end

        % Value changing function: ContraseaEditField
        function ContraseaEditFieldValueChanging(app, event)
            temp = "*";
            changingValue = event.Value;
            if strlength(app.DebugEditField.Value) ~= 0
                if strlength(changingValue) < strlength(app.DebugEditField.Value)
                    if strlength(changingValue) == 0
                        app.DebugEditField.Value = "";
                    else
                        app.DebugEditField.Value = app.DebugEditField.Value(1:strlength(changingValue));
                    end
                else
                    app.DebugEditField.Value = strcat(app.DebugEditField.Value, changingValue(end));
                end
            else
                app.DebugEditField.Value = changingValue;
            end
            if strlength(changingValue)>0
                for i=1:strlength(changingValue)-1
                    temp = strcat(temp,"*");
                end
                app.ContraseaEditField.Value = temp;
            else
                app.ContraseaEditField.Value = "";
            end
        end

        % Close request function: cred
        function credCloseRequest(app, event)
            cancelarLogin(app.CallingApp);
            delete(app);
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            if (strcmp(app.UsuarioEditField.Value,''))
                fig = app.cred;
                message = ['Error. Por favor ingrese el usuario '...
                           'para el cual desea recuperar la contraseña.'];
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
                    
            else
                con = database('toolbox', 'resetpass', '2023');
                query = ['SELECT correo ' ...
                'FROM humana.usuarios WHERE usuario= "' ...
                char(app.UsuarioEditField.Value)...
                '"'];
                data = fetch(con,query);
                if ~isempty(data.correo) && ~strcmp(app.UsuarioEditField.Value,'root')
                    %Consultar datos de config para recuperar contraseña
                    query = 'SELECT * FROM humana.config';
                    data2 = fetch(con,query);

                    %La contraseña se genera y se debe enviar, en caso de
                    %que falle que no se cambie
                    newpass = randi([1000 9999],1);
                   
                    %Enviar correo con nueva contraseña
                    setpref('Internet','SMTP_Server',char(data2.server));
                    setpref('Internet','E_mail',char(data2.correo));
                    setpref('Internet','SMTP_Username',char(data2.correo));
                    setpref('Internet','SMTP_Password',char(data2.pass));
                    props = java.lang.System.getProperties;
                    props.setProperty('mail.smtp.auth','true');
                    props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
                    props.setProperty('mail.smtp.socketFactory.port','465');
                    
                    sendmail(data.correo,char(data2.asunto), [char(data2.mensaje_1) ' Tu nueva contraseña: ' int2str(newpass) '. ' char(data2.mensaje_2)]);
                    
                    %Guardar contraseña en base de datos
                    actualizarpass = strcat("ALTER USER ", app.UsuarioEditField.Value);
                    actualizarpass = strcat(actualizarpass, " IDENTIFIED BY '");
                    actualizarpass = strcat(actualizarpass, string(newpass));
                    actualizarpass = strcat(actualizarpass, "'");
                    execute(con, actualizarpass);
                end
                fig = app.cred;
                message = ['Si el usuario existe recibirá una contraseña temporal al correo asociado. ' ...
                    'De no recibir el correo revisa tu conexión a internet y tu buzón de correo no deseado.'];
                uialert(fig ,message,'Aviso','Icon','success'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
                    
               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.cred = uifigure('Visible', 'off');
            app.cred.Name = 'Iniciar sesión';
            app.cred.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.cred.CloseRequestFcn = createCallbackFcn(app, @credCloseRequest, true);
            app.ConectarButton = uibutton(app.cred, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.UsuarioEditFieldLabel = uilabel(app.cred);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.cred, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.cred);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.cred, 'text');
            app.ContraseaEditField.ValueChangingFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanging, true);
            app.IntroduzcasuscredencialesdeaccesoLabel = uilabel(app.cred);
            app.IntroduzcasuscredencialesdeaccesoLabel.Text = 'Introduzca sus credenciales de acceso.';
            app.DebugEditFieldLabel = uilabel(app.cred);
            app.DebugEditFieldLabel.Visible = 'off';
            app.DebugEditFieldLabel.Text = 'Debug';
            app.DebugEditField = uieditfield(app.cred, 'text');
            app.DebugEditField.Editable = 'off';
            app.DebugEditField.Enable = 'off';
            app.DebugEditField.Visible = 'off';
            app.Hyperlink = uihyperlink(app.cred);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Recuperar contraseña';
            % Show the figure after all components are created
            app.cred.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = credenciales(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.cred)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.cred)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.cred)
        end
    end
end