classdef editaru < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditarusuarioUIFigure          matlab.ui.Figure
        ActualizarCorreo               matlab.ui.control.Button
        CorreoEditField                matlab.ui.control.EditField
        CorreoEditFieldLabel           matlab.ui.control.Label
        CorreoLabel                    matlab.ui.control.Label
        AnotacionesautomticasCheckBox  matlab.ui.control.CheckBox
        ClasificadoresCheckBox         matlab.ui.control.CheckBox
        HerramientadeanlisisCheckBox   matlab.ui.control.CheckBox
        ContraseaLabel                 matlab.ui.control.Label
        RolLabel                       matlab.ui.control.Label
        ActualizarRol                  matlab.ui.control.Button
        CambiarcontraseaButton         matlab.ui.control.Button
        Pass2EditField                 matlab.ui.control.EditField
        Pass2EditFieldLabel            matlab.ui.control.Label
        Pass1EditField                 matlab.ui.control.EditField
        Pass1EditFieldLabel            matlab.ui.control.Label
        RolEditField                   matlab.ui.control.EditField
        RolEditFieldLabel              matlab.ui.control.Label
        AnalizarpruebasCheckBox        matlab.ui.control.CheckBox
        ConsultadepacientesCheckBox    matlab.ui.control.CheckBox
        IngresodepacientesCheckBox     matlab.ui.control.CheckBox
        ConsultadeinformacinconfidencialCheckBox  matlab.ui.control.CheckBox
        PermisosLabel                  matlab.ui.control.Label
        AdministracindeusuariosCheckBox  matlab.ui.control.CheckBox
        ConfirmarcontraseaEditField    matlab.ui.control.EditField
        ConfirmarcontraseaEditFieldLabel  matlab.ui.control.Label
        CerrarButton                   matlab.ui.control.Button
        ActualizarpermisosButton       matlab.ui.control.Button
        EditarusuarioLabel             matlab.ui.control.Label
        ContraseaEditField             matlab.ui.control.EditField
        ContraseaEditFieldLabel        matlab.ui.control.Label
        UsuarioEditField               matlab.ui.control.EditField
        UsuarioEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Description
        Conexion;
        Usuario;
    end
    
    methods (Access = private)
        
        function entero = toentero(~, logico)
            if (logico)
                entero = 1;
            else
                entero = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con, usuario)
            movegui(app.EditarusuarioUIFigure,"center");
            app.CallingApp = mainapp;
            app.Conexion = con;
            app.Usuario = usuario;
            
            app.UsuarioEditField.Value = app.Usuario;
            
            query = ['SELECT * ' ...
                'FROM humana.usuarios WHERE usuario= "' ...
                char(app.Usuario)...
                '"'];
            data = fetch(app.Conexion,query);
            
            if (data.admin_usuarios == 1)
                app.AdministracindeusuariosCheckBox.Value = true;
            end
            if (data.analizar_prueba == 1)
                app.AnalizarpruebasCheckBox.Value = true;
            end
            if (data.ingreso_pacientes == 1)
                app.IngresodepacientesCheckBox.Value = true;
            end
            if (data.consulta_pacientes == 1)
                app.ConsultadepacientesCheckBox.Value = true;
            end
            if (data.consulta_confidencial == 1)
                app.ConsultadeinformacinconfidencialCheckBox.Value = true;
            end
            if (data.anotaciones_automaticas == 1)
                app.HerramientadeanlisisCheckBox.Value = true;
            end
            if (data.crear_algoritmo == 1)
                app.ClasificadoresCheckBox.Value = true;
            end
            if (data.anotaciones_automaticas2 == 1)
                app.AnotacionesautomticasCheckBox.Value = true;
            end
            
            app.RolEditField.Value = string(data.tipo);
            
            app.CorreoEditField.Value = string(data.correo);
            
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            ActivarNuevoU(app.CallingApp);
            delete(app)
        end

        % Button pushed function: ActualizarpermisosButton
        function ActualizarpermisosButtonPushed(app, event)
            campos1 = false;
            
            if (~app.AdministracindeusuariosCheckBox.Value && ~app.AnalizarpruebasCheckBox.Value && ~app.IngresodepacientesCheckBox.Value && ~app.ConsultadepacientesCheckBox.Value && ~app.ConsultadeinformacinconfidencialCheckBox.Value && ~app.HerramientadeanlisisCheckBox.Value && ~app.ClasificadoresCheckBox.Value && ~app.AnotacionesautomticasCheckBox.Value)
                fig = app.AdministrarusuariosUIFigure;
                message = 'Por lo menos 1 permiso debe ser seleccionado. ';
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
              
            else
                campos1 = true;
            end
            
            if (campos1)
                conn = app.Conexion;
                
                %actualizar accesos
                u = strcat("'",app.Usuario);
                u = strcat(u, "'");
                nuevo={toentero(app,app.AdministracindeusuariosCheckBox.Value), toentero(app,app.AnalizarpruebasCheckBox.Value), toentero(app,app.IngresodepacientesCheckBox.Value), toentero(app,app.ConsultadepacientesCheckBox.Value), toentero(app,app.ConsultadeinformacinconfidencialCheckBox.Value), toentero(app,app.HerramientadeanlisisCheckBox.Value), toentero(app,app.ClasificadoresCheckBox.Value), toentero(app,app.AnotacionesautomticasCheckBox.Value),0};
                columnas={'admin_usuarios','analizar_prueba','ingreso_pacientes','consulta_pacientes','consulta_confidencial', 'anotaciones_automaticas', 'crear_algoritmo', 'anotaciones_automaticas2','recuperar_contrasena'};
                donde = ['WHERE usuario = ' char(u)];
                update(conn,'humana.usuarios',columnas,nuevo, donde);
                
                %Quitar todos los permisos al usuario
                permisos = ['REVOKE ALL PRIVILEGES, GRANT OPTION FROM ' app.UsuarioEditField.Value];
                execute(conn, permisos);
                
                %Asignar nuevos permisos a nivel de db
                permiso = ['GRANT SELECT ON humana.usuarios TO ' app.UsuarioEditField.Value];
                execute(conn, permiso);
                %permitir a todos los usuarios cambiar el estado de la bandera
                %primer_login en humana.usuarios
                permiso = ['GRANT UPDATE (primer_login) ON humana.usuarios TO ' app.UsuarioEditField.Value];
                execute(conn, permiso);
                %Asisgnar permisos seleccionados
                %Administrar usuarios
                if (app.AdministracindeusuariosCheckBox.Value)
                    permiso1 = ['GRANT ALL PRIVILEGES ON humana.usuarios TO ' app.UsuarioEditField.Value ' WITH GRANT OPTION'];
                    execute(conn, permiso1);
                    permiso1_1 = ['GRANT SELECT ON mysql.user TO ' app.UsuarioEditField.Value ' WITH GRANT OPTION'];
                    execute(conn, permiso1_1);
                    permiso1_2 = ['GRANT CREATE USER ON *.* TO ' app.UsuarioEditField.Value ' WITH GRANT OPTION'];
                    execute(conn, permiso1_2);
                end
                %analizar pruebas
                if (app.AnalizarpruebasCheckBox.Value)
                    permiso2 = ['GRANT SELECT ON humana.pacientes TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2);
                    permiso2_1 = ['GRANT SELECT ON humana.pruebas TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_1);
                    permiso2_2 = ['GRANT SELECT ON humana.pruebas_datos TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_2);
                    permiso2_3 = ['GRANT ALL PRIVILEGES ON humana.modelo TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_3);
                    permiso2_4 = ['GRANT ALL PRIVILEGES ON humana.resultados_modelo TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_4);
                    permiso2_5 = ['GRANT ALL PRIVILEGES ON humana.analisis TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_5);
                    permiso2_6 = ['GRANT ALL PRIVILEGES ON humana.resultados_analisis TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_6);
                end
                %Ingresar paciente
                if (app.IngresodepacientesCheckBox.Value)
                    permiso3 = ['GRANT ALL PRIVILEGES ON humana.pacientes TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso3);
                end
                %Consultar pacientes
                if (app.ConsultadepacientesCheckBox.Value)
                    permiso4 = ['GRANT SELECT (id_paciente, sexo, fecha_nacimiento, antecedentes, diagnostico, condicion), UPDATE (id_paciente, sexo, fecha_nacimiento, antecedentes, diagnostico, condicion), SELECT (id_paciente, sexo, fecha_nacimiento, antecedentes, diagnostico, condicion) ON humana.pacientes TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso4);
                    permiso4_1 = ['GRANT ALL PRIVILEGES ON humana.pruebas TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso4_1);
                    permiso4_2 = ['GRANT ALL PRIVILEGES ON humana.pruebas_datos TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso4_2);
                end
                %Consultar información confidencial
                if (app.ConsultadeinformacinconfidencialCheckBox.Value)
                    permiso5 = ['GRANT ALL PRIVILEGES ON humana.pacientes TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso5);
                    permiso5_1 = ['GRANT ALL PRIVILEGES ON humana.pruebas TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso5_1);
                    permiso5_2 = ['GRANT ALL PRIVILEGES ON humana.pruebas_datos TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso5_2);
                end
                fig = app.EditarusuarioUIFigure;
                message = 'Permisos actualizados exitosamente. ';
                uialert(fig ,message,'Aviso','Icon','success'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas 
                
            end
        end

        % Value changed function: ConsultadeinformacinconfidencialCheckBox
        function ConsultadeinformacinconfidencialCheckBoxValueChanged(app, event)
            value = app.ConsultadeinformacinconfidencialCheckBox.Value;
            if (~app.ConsultadepacientesCheckBox.Value && value)
                app.ConsultadepacientesCheckBox.Value = value;
            end
        end

        % Value changed function: ConsultadepacientesCheckBox
        function ConsultadepacientesCheckBoxValueChanged(app, event)
            value = app.ConsultadepacientesCheckBox.Value;
            if (app.ConsultadeinformacinconfidencialCheckBox.Value && ~value)
                app.ConsultadeinformacinconfidencialCheckBox.Value = value;
            end
        end

        % Value changing function: ContraseaEditField
        function ContraseaEditFieldValueChanging(app, event)
            temp = "*";
            changingValue = event.Value;
            if strlength(app.Pass1EditField.Value) ~= 0
                if strlength(changingValue) < strlength(app.Pass1EditField.Value)
                    if strlength(changingValue) == 0
                        app.Pass1EditField.Value = "";
                    else
                        app.Pass1EditField.Value = app.Pass1EditField.Value(1:strlength(changingValue));
                    end
                else
                    app.Pass1EditField.Value = strcat(app.Pass1EditField.Value, changingValue(end));
                end
            else
                app.Pass1EditField.Value = changingValue;
            end
            if strlength(changingValue)>0
                for i=1:strlength(changingValue)-1
                    temp = strcat(temp,"*");
                end
                app.ContraseaEditField.Value = temp;
            else
                app.ContraseaEditField.Value = "";
            end
        end

        % Value changing function: ConfirmarcontraseaEditField
        function ConfirmarcontraseaEditFieldValueChanging(app, event)
            temp = "*";
            changingValue = event.Value;
            if strlength(app.Pass2EditField.Value) ~= 0
                if strlength(changingValue) < strlength(app.Pass2EditField.Value)
                    if strlength(changingValue) == 0
                        app.Pass2EditField.Value = "";
                    else
                        app.Pass2EditField.Value = app.Pass2EditField.Value(1:strlength(changingValue));
                    end
                else
                    app.Pass2EditField.Value = strcat(app.Pass2EditField.Value, changingValue(end));
                end
            else
                app.Pass2EditField.Value = changingValue;
            end
            if strlength(changingValue)>0
                for i=1:strlength(changingValue)-1
                    temp = strcat(temp,"*");
                end
                app.ConfirmarcontraseaEditField.Value = temp;
            else
                app.ConfirmarcontraseaEditField.Value = "";
            end
        end

        % Button pushed function: CambiarcontraseaButton
        function CambiarcontraseaButtonPushed(app, event)
            campos1 = false;
            campos2 = false;
            
            if (strcmp(app.UsuarioEditField.Value,'') || strcmp(app.ContraseaEditField.Value,'') || strcmp(app.ConfirmarcontraseaEditField.Value,''))
                fig = app.EditarusuarioUIFigure;
                message = 'Campos: Contraseña y Confirmar contraseña son requeridos.';
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
              
            else
                campos1 = true;
            end
            
            if (strcmp(app.Pass1EditField.Value, app.Pass2EditField.Value))
                campos2 = true;
            else
                fig = app.EditarusuarioUIFigure;
                message = 'Contraseña y Confirmar contraseña no coinciden. ';
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
              
            end
            
            if (campos1 && campos2)
                con = app.Conexion;
                actualizarpass = strcat("ALTER USER ", app.UsuarioEditField.Value);
                actualizarpass = strcat(actualizarpass, " IDENTIFIED BY '");
                actualizarpass = strcat(actualizarpass, app.Pass1EditField.Value);
                actualizarpass = strcat(actualizarpass, "'");
                execute(con, actualizarpass);
                fig = app.EditarusuarioUIFigure;
                message = 'Contraseña actualizada exitosamente. ';
                uialert(fig ,message,'Aviso','Icon','success'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
              
                
            end
        end

        % Button pushed function: ActualizarRol
        function ActualizarRolButtonPushed(app, event)
            campo1 = false;
            
            if (strcmp(app.RolEditField.Value,''))
                fig = app.EditarusuarioUIFigure;
                message = 'Error. Campo Rol es requerido. ';
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas 
            else
                campo1 = true;
            end
            
            if campo1
                con = app.Conexion;
                
                updaterol = "UPDATE humana.usuarios SET tipo = '" ;
                updaterol = strcat(updaterol, app.RolEditField.Value);
                updaterol = strcat(updaterol, "' WHERE usuario = '");
                updaterol = strcat(updaterol, app.Usuario);
                updaterol = strcat(updaterol, "'");
                execute(con, updaterol);
                fig = app.EditarusuarioUIFigure;
                message = 'Rol modificado exitosamente. ';
                uialert(fig ,message,'Aviso','Icon','success'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas 
                
                actualizart(app.CallingApp);
            end
        end

        % Value changed function: HerramientadeanlisisCheckBox
        function HerramientadeanlisisCheckBoxValueChanged(app, event)
            value = app.HerramientadeanlisisCheckBox.Value;
            if value == 0
                app.ClasificadoresCheckBox.Value = 0;
                app.AnotacionesautomticasCheckBox.Value = 0;
            end
        end

        % Value changed function: ClasificadoresCheckBox
        function ClasificadoresCheckBoxValueChanged(app, event)
            value = app.ClasificadoresCheckBox.Value;
            value2 = app.AnotacionesautomticasCheckBox.Value;
            if value == 1
                app.HerramientadeanlisisCheckBox.Value = 1;
            end
            if value == 0 && value2 == 0
                app.HerramientadeanlisisCheckBox.Value = 0;
            end
        end

        % Value changed function: AnotacionesautomticasCheckBox
        function AnotacionesautomticasCheckBoxValueChanged(app, event)
            value = app.AnotacionesautomticasCheckBox.Value;
            value2 = app.ClasificadoresCheckBox.Value;
            if value == 1
                app.HerramientadeanlisisCheckBox.Value = 1;
            end
            if value == 0 && value2 == 0
                app.HerramientadeanlisisCheckBox.Value = 0;
            end
        end

        % Button pushed function: ActualizarCorreo
        function ActualizarCorreoButtonPushed(app, event)
             campo1 = false;
            
            if (strcmp(app.CorreoEditField.Value,''))
                fig = app.EditarusuarioUIFigure;
                message = 'Error. Campo Correo es requerido. ';
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
              
            else
                campo1 = true;
            end
            
            if campo1
                con = app.Conexion;
                
                updaterol = "UPDATE humana.usuarios SET correo = '" ;
                updaterol = strcat(updaterol, app.CorreoEditField.Value);
                updaterol = strcat(updaterol, "' WHERE usuario = '");
                updaterol = strcat(updaterol, app.Usuario);
                updaterol = strcat(updaterol, "'");
                execute(con, updaterol);
                
               fig = app.EditarusuarioUIFigure;
                message = 'Correo actualizado exitosamente. ';
                uialert(fig ,message,'Aviso','Icon','success'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EditarusuarioUIFigure = uifigure('Visible', 'off');
            app.EditarusuarioUIFigure.Name = 'Editar usuario';
            app.EditarusuarioUIFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.UsuarioEditFieldLabel = uilabel(app.EditarusuarioUIFigure);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.EditarusuarioUIFigure, 'text');
            app.UsuarioEditField.Editable = 'off';
            app.ContraseaEditFieldLabel = uilabel(app.EditarusuarioUIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.EditarusuarioUIFigure, 'text');
            app.ContraseaEditField.ValueChangingFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanging, true);
            app.EditarusuarioLabel = uilabel(app.EditarusuarioUIFigure);
            app.EditarusuarioLabel.Text = 'Editar usuario';
            app.ActualizarpermisosButton = uibutton(app.EditarusuarioUIFigure, 'push');
            app.ActualizarpermisosButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarpermisosButtonPushed, true);
            app.ActualizarpermisosButton.Text = 'Actualizar permisos';
            app.CerrarButton = uibutton(app.EditarusuarioUIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.ConfirmarcontraseaEditFieldLabel = uilabel(app.EditarusuarioUIFigure);
            app.ConfirmarcontraseaEditFieldLabel.Text = 'Confirmar contraseña';
            app.ConfirmarcontraseaEditField = uieditfield(app.EditarusuarioUIFigure, 'text');
            app.ConfirmarcontraseaEditField.ValueChangingFcn = createCallbackFcn(app, @ConfirmarcontraseaEditFieldValueChanging, true);
            app.AdministracindeusuariosCheckBox = uicheckbox(app.EditarusuarioUIFigure);
            app.AdministracindeusuariosCheckBox.Text = 'Administración de usuarios';
            app.PermisosLabel = uilabel(app.EditarusuarioUIFigure);
            app.PermisosLabel.Text = 'Permisos:';
            app.ConsultadeinformacinconfidencialCheckBox = uicheckbox(app.EditarusuarioUIFigure);
            app.ConsultadeinformacinconfidencialCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsultadeinformacinconfidencialCheckBoxValueChanged, true);
            app.ConsultadeinformacinconfidencialCheckBox.Text = 'Consulta de información confidencial';
            app.IngresodepacientesCheckBox = uicheckbox(app.EditarusuarioUIFigure);
            app.IngresodepacientesCheckBox.Text = 'Ingreso de pacientes';
            app.ConsultadepacientesCheckBox = uicheckbox(app.EditarusuarioUIFigure);
            app.ConsultadepacientesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsultadepacientesCheckBoxValueChanged, true);
            app.ConsultadepacientesCheckBox.Text = 'Consulta de pacientes';
            app.AnalizarpruebasCheckBox = uicheckbox(app.EditarusuarioUIFigure);
            app.AnalizarpruebasCheckBox.Text = 'Analizar pruebas';
            app.RolEditFieldLabel = uilabel(app.EditarusuarioUIFigure);
            app.RolEditFieldLabel.Text = 'Rol';
            app.RolEditField = uieditfield(app.EditarusuarioUIFigure, 'text');
            app.Pass1EditFieldLabel = uilabel(app.EditarusuarioUIFigure);
            app.Pass1EditFieldLabel.Enable = 'off';
            app.Pass1EditFieldLabel.Visible = 'off';
            app.Pass1EditFieldLabel.Text = 'Pass1';
            app.Pass1EditField = uieditfield(app.EditarusuarioUIFigure, 'text');
            app.Pass1EditField.Editable = 'off';
            app.Pass1EditField.Enable = 'off';
            app.Pass1EditField.Visible = 'off';
            app.Pass2EditFieldLabel = uilabel(app.EditarusuarioUIFigure);
            app.Pass2EditFieldLabel.Enable = 'off';
            app.Pass2EditFieldLabel.Visible = 'off';
            app.Pass2EditFieldLabel.Text = 'Pass2';
            app.Pass2EditField = uieditfield(app.EditarusuarioUIFigure, 'text');
            app.Pass2EditField.Editable = 'off';
            app.Pass2EditField.Enable = 'off';
            app.Pass2EditField.Visible = 'off';
            app.CambiarcontraseaButton = uibutton(app.EditarusuarioUIFigure, 'push');
            app.CambiarcontraseaButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarcontraseaButtonPushed, true);
            app.CambiarcontraseaButton.Text = 'Cambiar contraseña';
            app.ActualizarRol = uibutton(app.EditarusuarioUIFigure, 'push');
            app.ActualizarRol.ButtonPushedFcn = createCallbackFcn(app, @ActualizarRolButtonPushed, true);
            app.ActualizarRol.Icon = fullfile(pathToMLAPP, 'editaricon.png');
            app.ActualizarRol.Text = '';
            app.RolLabel = uilabel(app.EditarusuarioUIFigure);
            app.RolLabel.Text = 'Rol:';
            app.ContraseaLabel = uilabel(app.EditarusuarioUIFigure);
            app.ContraseaLabel.Text = 'Contraseña:';
            app.HerramientadeanlisisCheckBox = uicheckbox(app.EditarusuarioUIFigure);
            app.HerramientadeanlisisCheckBox.ValueChangedFcn = createCallbackFcn(app, @HerramientadeanlisisCheckBoxValueChanged, true);
            app.HerramientadeanlisisCheckBox.Enable = 'off';
            app.HerramientadeanlisisCheckBox.Text = 'Herramienta de análisis';
            app.ClasificadoresCheckBox = uicheckbox(app.EditarusuarioUIFigure);
            app.ClasificadoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClasificadoresCheckBoxValueChanged, true);
            app.ClasificadoresCheckBox.Text = 'Clasificadores';
            app.AnotacionesautomticasCheckBox = uicheckbox(app.EditarusuarioUIFigure);
            app.AnotacionesautomticasCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnotacionesautomticasCheckBoxValueChanged, true);
            app.AnotacionesautomticasCheckBox.Text = 'Anotaciones automáticas';
            app.CorreoLabel = uilabel(app.EditarusuarioUIFigure);
            app.CorreoLabel.Text = 'Correo:';
            app.CorreoEditFieldLabel = uilabel(app.EditarusuarioUIFigure);
            app.CorreoEditFieldLabel.Text = 'Correo';
            app.CorreoEditField = uieditfield(app.EditarusuarioUIFigure, 'text');
            app.ActualizarCorreo = uibutton(app.EditarusuarioUIFigure, 'push');
            app.ActualizarCorreo.ButtonPushedFcn = createCallbackFcn(app, @ActualizarCorreoButtonPushed, true);
            app.ActualizarCorreo.Icon = fullfile(pathToMLAPP, 'editaricon.png');
            app.ActualizarCorreo.Text = '';
            % Show the figure after all components are created
            app.EditarusuarioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = editaru(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EditarusuarioUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EditarusuarioUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditarusuarioUIFigure)
        end
    end
end