classdef features < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExtraccindeCaractersticasUIFigure  matlab.ui.Figure
        GENERARwavelet_5              matlab.ui.control.Button
        ANLISISWAVELETLabel           matlab.ui.control.Label
        ANLISISDIRECTOLabel           matlab.ui.control.Label
        SISEDESEANTODOSLOSCANALESLabel_2  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        GENERARwavelet_4              matlab.ui.control.Button
        GENERARwavelet_3              matlab.ui.control.Button
        CRUCESPORCEROCheckBox_2       matlab.ui.control.CheckBox
        MEDIACheckBox                 matlab.ui.control.CheckBox
        DESVIACINCheckBox_2           matlab.ui.control.CheckBox
        ASIMETRACheckBox              matlab.ui.control.CheckBox
        CURTOSISCheckBox_2            matlab.ui.control.CheckBox
        POTENCIACheckBox              matlab.ui.control.CheckBox
        MUESTRASPORVENTANAEditField_wavelet  matlab.ui.control.EditField
        MUESTRASPORVENTANAEditField_2Label  matlab.ui.control.Label
        SELECCIONARCANALSpinner       matlab.ui.control.Spinner
        SELECCIONARCANALSpinnerLabel  matlab.ui.control.Label
        GENERARwavelet                matlab.ui.control.Button
        CANALESAANALIZAREditField_2   matlab.ui.control.EditField
        CANALESAANALIZAREditField_2Label  matlab.ui.control.Label
        COLOCAR0SISEDESEANTODOSLOSCANALESLabel  matlab.ui.control.Label
        VALORMEDIOABSOLUTODCheckBox   matlab.ui.control.CheckBox
        CURTOSISCheckBox              matlab.ui.control.CheckBox
        CRUCESPORCEROCheckBox         matlab.ui.control.CheckBox
        DESVIACINESTNDARCheckBox      matlab.ui.control.CheckBox
        NIVELESDEDESCOMPOSICINLabel   matlab.ui.control.Label
        CALCULARPARAButtonGroup       matlab.ui.container.ButtonGroup
        DropDown                      matlab.ui.control.DropDown
        BANDAESPECFICAButton          matlab.ui.control.RadioButton
        TODASLASBANDASButton          matlab.ui.control.RadioButton
        WAVELETMADREDropDown          matlab.ui.control.DropDown
        WAVELETMADREDropDownLabel     matlab.ui.control.Label
        GENERARButton                 matlab.ui.control.Button
        CARACTERSTICASWAVELETLabel    matlab.ui.control.Label
        MUESTRASPORVENTANAEditField   matlab.ui.control.EditField
        MUESTRASPORVENTANALabel       matlab.ui.control.Label
        NMERODECANALESSpinner         matlab.ui.control.Spinner
        NMERODECANALESLabel           matlab.ui.control.Label
        GENERARVECTORDECARACTERSTICASLabel  matlab.ui.control.Label
    end

    
   properties (Access = private)
    CallingApp % GUI1 object
    SecondApp  % GUI2 object
    ThirdApp %GUI3 object
    Grafica %GUI 4 object
    Graficawave %GUI 5 object
   end  
    
    properties (Access = public)
        %Variables compartidas entre ventanas
        edf 
        header
        canales
        muestras
        c
        Fs
        op
        Matriz_features
        Matriz_features_wavelet
        coeficientes_wavelet
        label
        featureop
        labelwave
        channel_ventana
        Wavelet_data
        archivo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            movegui(app.ExtraccindeCaractersticasUIFigure,"center");
            app.CallingApp = mainapp;
            app.GENERARwavelet_3.Enable = 'off';
            app.GENERARwavelet_4.Enable = 'off';
        end

        % Close request function: ExtraccindeCaractersticasUIFigure
        function ExtraccindeCaractersticasUIFigureCloseRequest(app, event)

            delete(app)
        end

        % Button pushed function: GENERARButton
        function GENERARButtonPushed(app, event)
            %Deshabilitar botones para evitar errores
            app.GENERARButton.Enable = 'off';
            app.GENERARwavelet.Enable = 'off';
            app.GENERARwavelet_3.Enable = 'off';
            app.GENERARwavelet_4.Enable = 'off';
            %Mensaje de advertencia 
            message = sprintf('Esta operación puede tomar algunos minutos');
            fig = app.ExtraccindeCaractersticasUIFigure;    
            message = 'Esto puede tardar varios minutos...';
            d=uiprogressdlg(fig,'Message',message,...
            'Icon','info','Cancelable','off','Indeterminate','on'); % Barra de progreso

            %Guardar vector caracteristicas con analisis directo
            signal = app.CallingApp.datos;
            app.edf = signal;
            app.Fs = app.CallingApp.freq;
            app.canales = app.NMERODECANALESSpinner.Value;
            a = app.CANALESAANALIZAREditField_2.Value;
            app.c=double(string(strsplit(a,{' ',','})));
            app.muestras = double(string(app.MUESTRASPORVENTANAEditField.Value));
            app.op =[0,0,0,0];
            seleccion1 = '';seleccion2 = '';seleccion3 = '';seleccion4 = '';
            if app.DESVIACINESTNDARCheckBox.Value
                app.op(1) =1;
                seleccion1= 'DesviaciónEstándar, '; 
            end
            if app.CURTOSISCheckBox.Value
                app.op(2) =1;
                seleccion2= 'Curtosis, '; 
            end
            if app.CRUCESPORCEROCheckBox.Value
                app.op(3) =1;
                seleccion3= 'CrucesPorCero, '; 
            end
            if app.VALORMEDIOABSOLUTODCheckBox.Value
                app.op(4) =1;
                seleccion4= 'ValorMedioAbsoluto, '; 
            end
            if size(signal,1) == 1
                app.NMERODECANALESSpinner.Value =1;
                app.c = app.c(randi([1, size(signal,1)]));
            elseif app.canales> size(signal,1)
                app.NMERODECANALESSpinner.Value =size(signal,1);
                app.c = randi([1, size(signal,1)],1,size(signal,1));
            end
            %Calcular caracteristicas seleccionadas (ver funcion features)
            [vector,ventanadatos,~]=Features(signal,app.Fs,app.canales,app.muestras,app.c,app.op);
            app.Matriz_features = vector;
            app.channel_ventana = ventanadatos;
            
            %Guardar archivo MAT
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('VectorFeatures.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            Features_result.Vector_Caracteristicas = vector;
            Features_result.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4];
            Features_result.canales = app.c;
            Features_result.data = ventanadatos;
            Features_result.FMuestreo = app.Fs;
            Features_result.Ventana = app.muestras;
            
            
            %Generar etiquetas balanceadas 
            if  rem(length(vector),2)==0
               Etiquetas = [zeros(length(vector)/2,1);ones(length(vector)/2,1)];
            else
               dimension = (length(vector)+1)/2;
               Etiquetas = [zeros((length(vector)+1)/2,1);ones(length(dimension+1:length(vector)),1)];
            end
            
            %habilitar botones
            app.label = Etiquetas;
            app.GENERARwavelet_3.Enable = 'on';
            app.GENERARwavelet_4.Enable = 'on';
            app.GENERARButton.Enable = 'on';
            app.GENERARwavelet.Enable = 'on';
            
            %Guardar archivo en la ruta seleccionada
            fullFileName = fullfile(path, file)
            save(fullFileName,'Features_result','Etiquetas')
            %Mensaje de exito 
            close(d);
            message = sprintf(['Archivo ',file,' guardado exitosamente']);
            uialert(app.ExtraccindeCaractersticasUIFigure,message,'Aviso',...
            'Icon','success');
            
        end

        % Button pushed function: GENERARwavelet
        function GENERARwaveletButtonPushed(app, event)
            %Deshabilitar botones para evitar errores
            app.GENERARButton.Enable = 'off';
            app.GENERARwavelet.Enable = 'off';
            app.GENERARwavelet_3.Enable = 'off';
            app.GENERARwavelet_4.Enable = 'off';
            %Mensaje de advertencia 
             message = sprintf('Esta operación puede tomar algunos minutos');
            fig = app.ExtraccindeCaractersticasUIFigure;    
            message = 'Esto puede tardar varios minutos...';
            d=uiprogressdlg(fig,'Message',message,...
            'Icon','info','Cancel');
        
            %Guardar vector de caracteristicas con analisis wavelet
            signal = app.CallingApp.datos;
            app.edf = signal;
            app.Fs = app.CallingApp.freq;
            madre = app.WAVELETMADREDropDown.Value;
            canal = app.SELECCIONARCANALSpinner.Value;
            app.muestras = double(string(app.MUESTRASPORVENTANAEditField_wavelet.Value));
            if app.TODASLASBANDASButton.Value 
                opcion =1;
            elseif app.BANDAESPECFICAButton.Value
                opcion=0;
            end
            
            app.featureop =[0,0,0,0,0,0];
            sel1='';sel2='';sel3='';sel4='';sel5='';sel6='';
            if app.DESVIACINCheckBox_2.Value 
                app.featureop(3)=1;
                sel1 = 'DesviaciónEstándar';
            end
            if app.ASIMETRACheckBox.Value
                app.featureop(5) =1;
                sel2 = 'Asimetría';
            end
            if app.MEDIACheckBox.Value
                app.featureop(2)=1;
                sel3 = 'Media';
            end
            if app.POTENCIACheckBox.Value
                app.featureop(1)=1;
                sel4 = 'Potencia';
            end
            if app.CURTOSISCheckBox_2.Value
                app.featureop(4)=1;  
                sel5 = 'Curtosis';
            end
            if app.CRUCESPORCEROCheckBox_2.Value
                app.featureop(6) =1;
                sel6 = 'CrucesPorCero';
            end
            %Utiizar funcion features_wavelet para calcular caracteristicas
            %segun la banda seleccionada
            if opcion==0
                app.DropDown.ItemsData = 1:length(app.DropDown.Items) ;
                Num = app.DropDown.Value;
                if Num==1
                    %theta
                    [M_features1,~,coeff1,~] = Features_wavelet(signal,app.muestras,canal,madre,2,app.featureop);
                    [M_features2,~,coeff2,~] = Features_wavelet(signal,app.muestras,canal,madre,3,app.featureop);
                    M_features = [M_features1,M_features2];
                    coeff = [coeff1;coeff2];
                    ritmo ='Gamma';
                elseif Num==2
                    %beta
                    [M_features3,~,coeff3,~] = Features_wavelet(signal,app.muestras,canal,madre,3,app.featureop);
                    [M_features4,~,coeff4,~] = Features_wavelet(signal,app.muestras,canal,madre,4,app.featureop);
                    M_features = [M_features3,M_features4];
                    coeff = [coeff3;coeff4];
                    ritmo ='Gamma/Beta';
                elseif Num==3
                    %alpha
                    [M_features5,~,coeff5,~] = Features_wavelet(signal,app.muestras,canal,madre,4,app.featureop);
                    [M_features6,~,coeff6,~] = Features_wavelet(signal,app.muestras,canal,madre,5,app.featureop);
                    M_features = [M_features5,M_features6];
                    coeff = [coeff5;coeff6];
                    ritmo ='Beta/Alpha';
                elseif Num==4
                    %gamma
                    [M_features7,~,coeff7,~] = Features_wavelet(signal,app.muestras,canal,madre,5,app.featureop);
                    [M_features8,~,coeff8,~] = Features_wavelet(signal,app.muestras,canal,madre,6,app.featureop);
                    M_features = [M_features7,M_features8];
                    coeff = [coeff7;coeff8];
                    ritmo ='Alpha/Theta';
                elseif Num==5
                    %delta
                    [M_features9,~,coeff9,~] = Features_wavelet(signal,app.muestras,canal,madre,7,app.featureop);
                    [M_features10,~,coeff10,~] = Features_wavelet(signal,app.muestras,canal,madre,8,app.featureop);
                    M_features = [M_features9,M_features10];
                    coeff = [coeff9;coeff10];
                    ritmo ='Theta/Delta';
                end
                 app.Matriz_features_wavelet = M_features;
                 app.coeficientes_wavelet = coeff;
            elseif opcion==1
                [M_features1,~,coeff1,tag] = Features_wavelet(signal,app.muestras,canal,madre,2,app.featureop); %theta
                [M_features2,~,coeff2,~] = Features_wavelet(signal,app.muestras,canal,madre,3,app.featureop); %theta,beta
                [M_features3,~,coeff3,~] = Features_wavelet(signal,app.muestras,canal,madre,4,app.featureop); %beta, alpha
                [M_features4,~,coeff4,~] = Features_wavelet(signal,app.muestras,canal,madre,5,app.featureop); %alpha, gamma
                [M_features5,~,coeff5,~] = Features_wavelet(signal,app.muestras,canal,madre,6,app.featureop); %gamma, delta
                [M_features6,~,coeff6,~] = Features_wavelet(signal,app.muestras,canal,madre,7,app.featureop); %delta
                [M_features7,~,coeff7,~] = Features_wavelet(signal,app.muestras,canal,madre,8,app.featureop);%aproximacion
                M_features = [M_features1,M_features2,M_features3,M_features4,M_features5,M_features6,M_features7];
                ritmo = 'Theta, Theta/Beta, Beta/Alpha, Alpha/Gamma, Gamma/Delta, Delta, Aprox';
                coeff = [coeff1;coeff2;coeff3;coeff4;coeff5;coeff6;coeff7];
                app.Matriz_features_wavelet = M_features;
                app.coeficientes_wavelet = coeff;
            end


            %habilitar botones
            app.GENERARwavelet_3.Enable = 'on';
            app.GENERARwavelet_4.Enable = 'on';
            app.GENERARButton.Enable = 'on';
            app.GENERARwavelet.Enable = 'on';
            
            %Guardar archivo MAT
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Colocar el nombre del archivo, o utilizar default
            % Wavelets.mat
            [file,path] = uiputfile('Wavelets.mat','Save file name');
            if file == 0
            %Regresar si usuario presiona cancelar
             return;
            end
            Features_Wavelet_result.VectorCaracteristicas = M_features;
            Features_Wavelet_result.Caracteristicas = [sel1,',',sel2,',',sel3,',',sel4,',',sel5,',',sel6];
            Features_Wavelet_result.Canal = canal;
            Features_Wavelet_result.fbanda = ritmo;
            Features_Wavelet_result.CoeficientesWavelet = coeff;
            Features_Wavelet_result.FMuestreo = app.Fs;
            Features_Wavelet_result.Ventana = app.muestras;
            app.Wavelet_data = Features_Wavelet_result;
            
            %Generar vector etiquetas (balanceadas)
            if  rem(length(M_features),2)==0
               Etiquetas1 = [zeros(length(M_features)/2,1);ones(length(M_features)/2,1)];
            else
               dimension = (length(M_features)+1)/2;
               Etiquetas1 = [zeros((length(M_features)+1)/2,1);ones(length(dimension+1:length(M_features)),1)];
            end
            app.labelwave = Etiquetas1;
     
            %Guardar archivo en ruta seleccionada
            fullFileName = fullfile(path, file)
            save(fullFileName,'Features_Wavelet_result','Etiquetas1')
            %Mensaje de exito 
            close(d);
            message = sprintf(['Archivo ',file,' guardado exitosamente']);
            uialert(app.ExtraccindeCaractersticasUIFigure,message,'Aviso',...
            'Icon','success');
        end

        % Button pushed function: GENERARwavelet_3
        function GENERARwavelet_3ButtonPushed(app, event)
            app.SecondApp =  clasificadorSVM(app);
        end

        % Button pushed function: GENERARwavelet_4
        function GENERARwavelet_4ButtonPushed(app, event)
            app.ThirdApp =  clasificadorRN(app);
        end

        % Callback function
        function GRAFICARButtonPushed(app, event)
            app.Grafica =  graficarfeatures(app);
        end

        % Callback function
        function GRAFICARButton_2Pushed(app, event)
            app.Graficawave = graficarfeatureswavelet(app);
        end

        % Button pushed function: GENERARwavelet_5
        function GENERARwavelet_5ButtonPushed(app, event)
             delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ExtraccindeCaractersticasUIFigure = uifigure('Visible', 'off');
            app.ExtraccindeCaractersticasUIFigure.Name = 'Extracción de Características';
            app.ExtraccindeCaractersticasUIFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.ExtraccindeCaractersticasUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExtraccindeCaractersticasUIFigureCloseRequest, true);
            app.GENERARVECTORDECARACTERSTICASLabel = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.GENERARVECTORDECARACTERSTICASLabel.Text = 'GENERAR  VECTOR DE CARACTERÍSTICAS';
            app.NMERODECANALESLabel = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.NMERODECANALESLabel.Text = {'   NÚMERO DE'; 'CANALES '};
            app.NMERODECANALESSpinner = uispinner(app.ExtraccindeCaractersticasUIFigure);
            app.NMERODECANALESSpinner.Limits = [1 4];
            app.NMERODECANALESSpinner.Value = 2;
            app.MUESTRASPORVENTANALabel = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.MUESTRASPORVENTANALabel.Text = {' MUESTRAS'; ' POR VENTANA'};
            app.MUESTRASPORVENTANAEditField = uieditfield(app.ExtraccindeCaractersticasUIFigure, 'text');
            app.MUESTRASPORVENTANAEditField.Value = '2500';
            app.CARACTERSTICASWAVELETLabel = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.CARACTERSTICASWAVELETLabel.Text = 'CARACTERÍSTICAS WAVELET';
            app.GENERARButton = uibutton(app.ExtraccindeCaractersticasUIFigure, 'push');
            app.GENERARButton.ButtonPushedFcn = createCallbackFcn(app, @GENERARButtonPushed, true);
            app.GENERARButton.Text = 'GENERAR';
            app.WAVELETMADREDropDownLabel = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.WAVELETMADREDropDownLabel.Text = {'WAVELET'; 'MADRE'};
            app.WAVELETMADREDropDown = uidropdown(app.ExtraccindeCaractersticasUIFigure);
            app.WAVELETMADREDropDown.Items = {'db3', 'db4', 'db5', 'db10'};
            app.WAVELETMADREDropDown.Value = 'db3';
            app.CALCULARPARAButtonGroup = uibuttongroup(app.ExtraccindeCaractersticasUIFigure);
            app.CALCULARPARAButtonGroup.Title = 'CALCULAR PARA';
            app.TODASLASBANDASButton = uiradiobutton(app.CALCULARPARAButtonGroup);
            app.TODASLASBANDASButton.Text = {' TODAS LAS '; 'BANDAS'};
            app.TODASLASBANDASButton.Value = true;
            app.BANDAESPECFICAButton = uiradiobutton(app.CALCULARPARAButtonGroup);
            app.BANDAESPECFICAButton.Text = {'BANDA '; 'ESPECÍFICA'};
            app.DropDown = uidropdown(app.CALCULARPARAButtonGroup);
            app.DropDown.Items = {'GAMMA  ', 'BETA   ', 'ALPHA ', 'THETA  ', 'DELTA'};
            app.DropDown.Value = 'GAMMA  ';
            app.NIVELESDEDESCOMPOSICINLabel = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.NIVELESDEDESCOMPOSICINLabel.Text = '[7 NIVELES DE DESCOMPOSICIÓN]';
            app.DESVIACINESTNDARCheckBox = uicheckbox(app.ExtraccindeCaractersticasUIFigure);
            app.DESVIACINESTNDARCheckBox.Text = {'DESVIACIÓN'; 'ESTÁNDAR'};
            app.CRUCESPORCEROCheckBox = uicheckbox(app.ExtraccindeCaractersticasUIFigure);
            app.CRUCESPORCEROCheckBox.Text = {'CRUCES '; 'POR CERO'};
            app.CURTOSISCheckBox = uicheckbox(app.ExtraccindeCaractersticasUIFigure);
            app.CURTOSISCheckBox.Text = 'CURTOSIS';
            app.CURTOSISCheckBox.Value = true;
            app.VALORMEDIOABSOLUTODCheckBox = uicheckbox(app.ExtraccindeCaractersticasUIFigure);
            app.VALORMEDIOABSOLUTODCheckBox.Text = {'VALOR MEDIO'; 'ABSOLUTOD'};
            app.COLOCAR0SISEDESEANTODOSLOSCANALESLabel = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.COLOCAR0SISEDESEANTODOSLOSCANALESLabel.Text = '* COLOCAR 0 SI SE DESEAN TODOS LOS CANALES';
            app.CANALESAANALIZAREditField_2Label = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.CANALESAANALIZAREditField_2Label.Text = {'CANALES A'; 'ANALIZAR'};
            app.CANALESAANALIZAREditField_2 = uieditfield(app.ExtraccindeCaractersticasUIFigure, 'text');
            app.CANALESAANALIZAREditField_2.Value = '1,2';
            app.GENERARwavelet = uibutton(app.ExtraccindeCaractersticasUIFigure, 'push');
            app.GENERARwavelet.ButtonPushedFcn = createCallbackFcn(app, @GENERARwaveletButtonPushed, true);
            app.GENERARwavelet.Text = 'GENERAR';
            app.SELECCIONARCANALSpinnerLabel = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.SELECCIONARCANALSpinnerLabel.Text = {'SELECCIONAR'; 'CANAL'};
            app.SELECCIONARCANALSpinner = uispinner(app.ExtraccindeCaractersticasUIFigure);
            app.SELECCIONARCANALSpinner.Limits = [1 23];
            app.SELECCIONARCANALSpinner.Value = 1;
            app.MUESTRASPORVENTANAEditField_2Label = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.MUESTRASPORVENTANAEditField_2Label.Text = {'MUESTRAS'; ' POR VENTANA'};
            app.MUESTRASPORVENTANAEditField_wavelet = uieditfield(app.ExtraccindeCaractersticasUIFigure, 'text');
            app.MUESTRASPORVENTANAEditField_wavelet.Value = '2500';
            app.POTENCIACheckBox = uicheckbox(app.ExtraccindeCaractersticasUIFigure);
            app.POTENCIACheckBox.Text = 'POTENCIA';
            app.CURTOSISCheckBox_2 = uicheckbox(app.ExtraccindeCaractersticasUIFigure);
            app.CURTOSISCheckBox_2.Text = 'CURTOSIS';
            app.ASIMETRACheckBox = uicheckbox(app.ExtraccindeCaractersticasUIFigure);
            app.ASIMETRACheckBox.Text = 'ASIMETRÍA';
            app.DESVIACINCheckBox_2 = uicheckbox(app.ExtraccindeCaractersticasUIFigure);
            app.DESVIACINCheckBox_2.Text = 'DESVIACIÓN';
            app.MEDIACheckBox = uicheckbox(app.ExtraccindeCaractersticasUIFigure);
            app.MEDIACheckBox.Text = 'MEDIA';
            app.MEDIACheckBox.Value = true;
            app.CRUCESPORCEROCheckBox_2 = uicheckbox(app.ExtraccindeCaractersticasUIFigure);
            app.CRUCESPORCEROCheckBox_2.Text = {'CRUCES '; 'POR CERO'};
            app.GENERARwavelet_3 = uibutton(app.ExtraccindeCaractersticasUIFigure, 'push');
            app.GENERARwavelet_3.ButtonPushedFcn = createCallbackFcn(app, @GENERARwavelet_3ButtonPushed, true);
            app.GENERARwavelet_3.Text = {'CLASIFICADOR'; 'SVM'};
            app.GENERARwavelet_4 = uibutton(app.ExtraccindeCaractersticasUIFigure, 'push');
            app.GENERARwavelet_4.ButtonPushedFcn = createCallbackFcn(app, @GENERARwavelet_4ButtonPushed, true);
            app.GENERARwavelet_4.Text = {'CLASIFICADOR'; 'RED NEURONAL'};
            app.Label = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.Label.Text = '________________________________________________________';
            app.SISEDESEANTODOSLOSCANALESLabel_2 = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.SISEDESEANTODOSLOSCANALESLabel_2.Text = {'* EL VECTOR DE ETIQUETAS ASIGNA AUTOMÁTICAMENTE AL 50% DE LOS PRIMEROS  DATOS COMO  '; 'CLASE "0", Y AL 50% DE LOS DATOS RESTANTES COMO CLASE "1", MODIFICAR SEGÚN NECESIDAD'};
            app.ANLISISDIRECTOLabel = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.ANLISISDIRECTOLabel.Text = 'ANÁLISIS DIRECTO';
            app.ANLISISWAVELETLabel = uilabel(app.ExtraccindeCaractersticasUIFigure);
            app.ANLISISWAVELETLabel.Text = 'ANÁLISIS WAVELET';
            app.GENERARwavelet_5 = uibutton(app.ExtraccindeCaractersticasUIFigure, 'push');
            app.GENERARwavelet_5.ButtonPushedFcn = createCallbackFcn(app, @GENERARwavelet_5ButtonPushed, true);
            app.GENERARwavelet_5.Text = 'REGRESAR';
            % Show the figure after all components are created
            app.ExtraccindeCaractersticasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = features(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ExtraccindeCaractersticasUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ExtraccindeCaractersticasUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExtraccindeCaractersticasUIFigure)
        end
    end
end