classdef inicioanotacionesauto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClasificadorEEGInteres          matlab.ui.Figure
        CantidaddegruposEditField       matlab.ui.control.NumericEditField
        CantidaddegruposEditFieldLabel  matlab.ui.control.Label
        EscogercantidaddegruposCheckBox  matlab.ui.control.CheckBox
        ComponentesautilizarDropDown    matlab.ui.control.DropDown
        ComponentesautilizarDropDownLabel  matlab.ui.control.Label
        SanoEpilepsiaCheckBox           matlab.ui.control.CheckBox
        AgrupadorDropDown               matlab.ui.control.DropDown
        AgrupadorDropDownLabel          matlab.ui.control.Label
        VATButton                       matlab.ui.control.Button
        NoSupervisadoCheckBox           matlab.ui.control.CheckBox
        SupervisadoCheckBox             matlab.ui.control.CheckBox
        MontajeButton                   matlab.ui.control.Button
        PruebaEditField                 matlab.ui.control.EditField
        PruebaEditFieldLabel            matlab.ui.control.Label
        PacienteEditField               matlab.ui.control.EditField
        PacienteEditFieldLabel          matlab.ui.control.Label
        BuscarButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        CargadeBaseDatosButton          matlab.ui.control.Button
        Menos                           matlab.ui.control.StateButton
        Mas                             matlab.ui.control.StateButton
        CanalesaprocesarListBox         matlab.ui.control.ListBox
        CanalesaprocesarLabel           matlab.ui.control.Label
        CanalesdisponiblesListBox       matlab.ui.control.ListBox
        CanalesdisponiblesLabel         matlab.ui.control.Label
        CargarSealEEGaAnalizarLabel     matlab.ui.control.Label
        ObservaranotacionesButton       matlab.ui.control.Button
        TiempodeventanaEditField        matlab.ui.control.NumericEditField
        TiempodeventanaEditFieldLabel   matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        ProcesarlasealEEGButton         matlab.ui.control.Button
        FreqMuestreoFsIctalEditField    matlab.ui.control.NumericEditField
        FreqMuestreoFsEditFieldLabel    matlab.ui.control.Label
        TextoTiempoEEG                  matlab.ui.control.Label
        TextoDimensionesEEG             matlab.ui.control.Label
        HzIctal                         matlab.ui.control.Label
        EPILEPTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        CargadelasealEEGLabel           matlab.ui.control.Label
        CargarClasificadorButton        matlab.ui.control.Button
        CargarEDFButton                 matlab.ui.control.Button
        CargarMATButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp
        Conexion
        
        %
        EEG_a_analizar
        Header_EEG
        Fs        
        
        %Caracteristicas
        cEEG
        muestras_eeg
        
        %Estructura con los resultados de un clasificador
        Struct_Clasificador
        
        %Aplicación de Ventanas de interes
        InterestWindows
        
        %Vector de caracteristicas para la app de ventanas de interes
        Vector_Car_EEGVI
        %Vector de nombres de los canales
        Nombres_Canales
        %Etiquetas de morfología
        Vector_Car_EEGMOR
        EEG_VI
        Tiempo_Inicio
        Intervalo_Ventana_s
        %Canales de interés
        ch_int
        %Nombre de los canales
        canales_listado
        %Indice del canal en el array
        canales_index
    
        %Variable para guardar si los canales vienen en un montaje o 
        %individuales--DI
        isBipolar
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            movegui(app.ClasificadorEEGInteres,"center");
            app.CallingApp = mainapp;
            app.Conexion = con;
        end

        % Button pushed function: CargarEDFButton
        function CargarEDFButtonPushed(app, event)
            %Abrir archivo EDF ICTAL
                [file,path,~] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                disp('Usuario canceló la operacion');
                fig = app.ClasificadorEEGInteres;
                message = 'Usuario canceló la operacion';
                uialert(fig ,message,'Aviso','Icon','warning');%Acualizacion a ultimo formato
            else
                fullFileName = fullfile(path, file);  % Usar fullfile para crear la ruta completa
                disp(['EL usuario seleccionó ', fullFileName]);
                % Verificar que el archivo existe en la ruta especificada
                if ~isfile(fullFileName)
                    error('No se encontró el archivo en la ruta especificada.');
                end
                app.CanalesdisponiblesListBox.Items ={};
                app.CanalesdisponiblesListBox.Value ={};
                app.CanalesaprocesarListBox.Items ={};
                app.CanalesaprocesarListBox.Value ={}; 
                [hdr, record] = openedf(fullFileName);
                %Cargamos la señal EEG y su header a la variable de datos respectiva
                app.EEG_a_analizar = record';
                app.Header_EEG = hdr;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.Fs = hdr.Fs(1);
                
                if ~isa(app.Fs,"numeric")
                    app.Fs = double(string(app.Fs));
                end

                app.FreqMuestreoFsIctalEditField.Value = app.Fs;
                %Nombres de los canales incluídos en el .MAT
                app.Nombres_Canales = hdr.SignalLabels';
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 %Se hace el cálculo para dejar que la persona seleccione 1 de
%                 %todos los canales que tiene
%                 datasize = size(app.EEG_a_analizar);
%                 numero_canalesEEG = min(datasize);
%                 ListBoxEEG  = num2cell(zeros(1,numero_canalesEEG));                                
%                 for i=1:numero_canalesEEG
%                     ListBoxEEG{i} = num2str(i);
%                 end
%                 app.CanalesdisponiblesListBox.Items = ListBoxEEG;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.EEG_a_analizar);              
                app.CanalesdisponiblesListBox.Items = app.Nombres_Canales;
                

                %Evalua si los canales vienen en un montaje o individuales
                %---DI
                app.isBipolar = detectMontage(app.Nombres_Canales);
                disp(size(app.EEG_a_analizar));
                
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesEEG.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = (length(app.EEG_a_analizar))/app.Fs;
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempoEEG.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempoEEG.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempoEEG.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempoEEG.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end  
                HorasMinutosSegundosEEG=strsplit(app.Header_EEG.StartTime,{'.',','});
                app.Tiempo_Inicio = append(HorasMinutosSegundosEEG{1},':',...
                                           HorasMinutosSegundosEEG{2},':',HorasMinutosSegundosEEG{3});
                
                app.SupervisadoCheckBox.Enable = 'on';
                app.NoSupervisadoCheckBox.Enable = 'on';
                app.MontajeButton.Enable = 'on';
                app.CanalesdisponiblesListBox.Enable = 'on';
                app.CanalesaprocesarListBox.Enable = 'on';
                app.CargarClasificadorButton.Enable = 'off';
                app.ProcesarlasealEEGButton.Enable = 'off';
                app.Mas.Enable = 'on';
                app.Menos.Enable = 'on';
                app.ObservaranotacionesButton.Enable = 'off';
                app.canales_listado = app.CanalesdisponiblesListBox.Items;
            end
        end

        % Button pushed function: CargarMATButton
        function CargarMATButtonPushed(app, event)
            %Seleccionar archivo MAT ICTAL
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo M');
            if isequal(file,0)
                disp('Usuario canceló operación');
                 
                fig = app.ClasificadorEEGInteres;
                message = 'Usuario canceló la operacion';
                uialert(fig ,message,'Aviso','Icon','warning');%Acualizacion a ultimo formato
            else
                app.CanalesdisponiblesListBox.Items ={};
                app.CanalesdisponiblesListBox.Value ={};
                app.CanalesaprocesarListBox.Items ={};
                app.CanalesaprocesarListBox.Value ={};
                 disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'eeg_struct'
                workspace = load(strcat(path,file),'eeg_struct');
                %Cargamos la señal EEG a la variable de datos respectiva
                app.EEG_a_analizar = workspace.eeg_struct.data;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.Fs = workspace.eeg_struct.sampling_frequency;
                app.FreqMuestreoFsIctalEditField.Value = app.Fs;
                %Nombres de los canales incluídos en el .MAT
                app.Nombres_Canales = workspace.eeg_struct.channels;
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.EEG_a_analizar); %Tamaño de la señal filas son canales          
                app.CanalesdisponiblesListBox.Items = app.Nombres_Canales;
                
                
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesEEG.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.eeg_struct.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempoEEG.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempoEEG.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempoEEG.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempoEEG.Text = append("Duración: ",num2str(s)," s");
                        end
                    end                    
                end   
                app.Tiempo_Inicio = '00:00:00';
                app.CanalesdisponiblesListBox.Enable = 'on';
                app.CanalesaprocesarListBox.Enable = 'on';
                app.CargarClasificadorButton.Enable = 'on';
                app.ProcesarlasealEEGButton.Enable = 'off';
                app.Mas.Enable = 'on';
                app.Menos.Enable = 'on';
                app.ObservaranotacionesButton.Enable = 'off';
                app.canales_listado = app.CanalesdisponiblesListBox.Items;
            end            
        end

        % Button pushed function: CargadeBaseDatosButton
        function CargadeBaseDatosButtonPushed(app, event)
            id_paciente = app.PacienteEditField.Value;
            id_prueba = app.PruebaEditField.Value;
            %Con la tabla seleccionamos la señal
            
            
            if (isempty(id_paciente))
                fig = app.ClasificadorEEGInteres;
                message = 'Ingrese código de paciente.';%Si no se ha escrito el codigo del paciente
                uialert(fig ,message,'Error','Icon','error');%Acualizacion a ultimo formato 
            elseif(isempty(id_prueba))  
                %No ocurre nada
                fig = app.ClasificadorEEGInteres;
                message = 'Seleccione la prueba a analizar.';%Si no se ha seleccionado la prueba a realizar
                uialert(fig ,message,'Error','Icon','error');%Acualizacion a ultimo formato
            else
                fig = app.ClasificadorEEGInteres;
           
                message = 'Esto puede tardar varios minutos...';
                d=uiprogressdlg(fig,'Message',message,...
                'Icon','info','Cancelable','off','Indeterminate','on'); % Barra de progreso
                %---Comunicación con la base de datos
                conn = app.Conexion;
                query = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '"'];
                data = fetch(conn,query); %Solicitamos la informacion general de la prubea

                query_datos = ['SELECT * FROM humana.pruebas_datos WHERE pruebas_datos.id_prueba ="' ...
                        id_prueba ...
                        '"'];
                data_datos = fetch(conn,query_datos); %Solicitamos los datos de la prueba
                %---Fin de la Comunicación con la base de datos
                
                %Variables de incializacion
                app.CanalesdisponiblesListBox.Items ={};
                app.CanalesdisponiblesListBox.Value ={};
                app.CanalesaprocesarListBox.Items ={};
                app.CanalesaprocesarListBox.Value ={}; 
                
                value = app.UITable.Selection(1); %Utilizamos los valores que ya estan en la tabla
                
                numcanales = str2double((cell2mat(app.UITable.Data(value, 4)))); %Obtenemos el numero de canales de la tabla UI

                %Recortar la tabla obtenida de la base de datos
                data_datos = removevars(data_datos, {'id_prueba'}); %Quitamos id_pruebas de la tabla obtenida
                
                for i = numcanales+1:35 %35 es la capacidad máxima de canales que puede almacenar la base de datos.
                    remove = sprintf('canal_%d', i);
                    data_datos = removevars(data_datos, remove);
                end
                app.Nombres_Canales = data_datos.Properties.VariableNames; %Hacemos asignacion de los titulos de variables
                app.CanalesdisponiblesListBox.Items = app.Nombres_Canales;

                data_datos_recortados = transpose(table2cell(data_datos)); 
                
                datasize = size(data_datos_recortados); %Obtenemos las dimensiones de los datos
                app.FreqMuestreoFsIctalEditField.Value = data.frecuencia(value);

                app.EEG_a_analizar = cell2mat(data_datos_recortados); % Datos de la señal
                app.Fs = data.frecuencia(value); %Frecuencia de la señal
                app.Header_EEG.StartTime = strcat(data.hora_inicio(value)); %Hora de incio de la señal
                app.CanalesdisponiblesListBox.Items = app.Nombres_Canales;
                               
                
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesEEG.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = (length(app.EEG_a_analizar))/app.Fs;
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempoEEG.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempoEEG.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempoEEG.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempoEEG.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end  
                app.Tiempo_Inicio = app.Header_EEG.StartTime;
                app.CanalesdisponiblesListBox.Enable = 'on';
                app.CanalesaprocesarListBox.Enable = 'on';
                app.CargarClasificadorButton.Enable = 'on';
                app.ProcesarlasealEEGButton.Enable = 'off';
                app.Mas.Enable = 'on';
                app.Menos.Enable = 'on';
                app.ObservaranotacionesButton.Enable = 'off';
                app.canales_listado = app.CanalesdisponiblesListBox.Items;
                
                close(d);
                fig = app.ClasificadorEEGInteres;
                message = 'Los canales se han cargado de forma exitosa.';%Avisamos la finalización de la carga
                uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato 

            end            
        end

        % Button pushed function: ProcesarlasealEEGButton
        function ProcesarlasealEEGButtonPushed(app, event)
            
            if (isempty(app.CanalesaprocesarListBox.Items)) %Verificamos que se haya seleccionado un canal por lo menos
                fig = app.ClasificadorEEGInteres; %Mostramos error si no se selecciono ninguno
                message = 'Seleccione uno o más canales para continuar. ';
                uialert(fig ,message,'Aviso','Icon','warning');
            else
                fig = app.ClasificadorEEGInteres; %Mostramos error si no se selecciono ninguno
                message = 'Esto puede tardar varios minutos...';
                d=uiprogressdlg(fig,'Message',message,...
                'Icon','info','Cancelable','off','Indeterminate','on'); % Barra de progreso
                size(app.EEG_a_analizar)
                %Aseguramos que sea una Fs entera
                fs=round(app.Fs);
                if (app.Fs-fs)<0
                    app.Fs = fs-1;
                end
                %Extracción de Características
                s_ventana = app.TiempodeventanaEditField.Value;
                app.Intervalo_Ventana_s = s_ventana;
                
                %Encontramos el índice correspondiente a los canales
                %seleccionados
                array_chanalizar = app.CanalesaprocesarListBox.Items; %A procesar
                indices_chanalizar = ismember(app.canales_listado,array_chanalizar);
                array_numero_ch = zeros(1,length(array_chanalizar));
                cont = 1;
                for n=1:length(indices_chanalizar)
                    if indices_chanalizar(n)==1
                        array_numero_ch(cont) = n;
                        cont = cont+1;
                    end
                end
                canales_a_analizar = array_numero_ch;
                app.canales_index = array_numero_ch;
                canales = 1; %1 canal por iteración
                app.muestras_eeg =round(s_ventana*app.Fs);
                if((s_ventana*app.Fs-app.muestras_eeg)<0)
                    app.muestras_eeg = app.muestras_eeg-1;
                end
                %Struct_Clasificador
                Vector_Opciones = app.Struct_Clasificador.Vector_op;
                AlgoritmoAA = app.Struct_Clasificador.Clasificador;
                
                %Aseguramos que sea un número entero de ventanas
                ventanas = length(app.EEG_a_analizar)/(app.Fs*s_ventana);
                ventanas_r = round(ventanas);
                if (ventanas-ventanas_r)<0
                    ventanas = ventanas_r-1;
                else
                    ventanas = ventanas_r;
                end
                VectorCaracteristicas_Todo_Canal = zeros(sum(Vector_Opciones)*length(canales_a_analizar),ventanas);
                %OJO, el "round" hará que datos del final sean removidos, esto
                %es gracias a la frecuencia de muestreo no entera.
                Vector_Etiquetas_Completo = zeros(length(canales_a_analizar),ventanas);
                %Clasificación para cada canal seleccionado
                vcsup = 0;
                
                %Obtención de la morfología por medio de agrupamiento K-means
                %(2da versión: espectro de potencia)
                Matriz_featuresMorf = FeaturesMorf(app.EEG_a_analizar,app.Fs,canales_a_analizar,...
                                                   app.muestras_eeg);
                [~,No_ventanas] = size(Matriz_featuresMorf);
                Vector_Etiquetas_Morfologia =  zeros(length(canales_a_analizar),No_ventanas);
                m = 2; %Número de clusters
                
                for j=1:length(canales_a_analizar)
                app.cEEG = canales_a_analizar(j);%Se establece el canal a analizar en esta iteración
                    if(app.Struct_Clasificador.Caracteristicas.Tipo_Caracteristicas == 0)
                        vecopt = Vector_Opciones
                        [VectorCaracteristicas_i,Ventanadatos,~]=FeaturesV2(app.EEG_a_analizar,app.Fs,canales,app.muestras_eeg,app.cEEG,Vector_Opciones);
                        vcbot = vcsup+1;
                        vcsup = vcbot+sum(Vector_Opciones)-1;
                        VectorCaracteristicas_Todo_Canal(vcbot:vcsup,:) = VectorCaracteristicas_i';
                    else
                        WaveletMadre = app.Struct_Clasificador.Caracteristicas.Madre;
                        Niveles_des = app.Struct_Clasificador.Caracteristicas.Descomp;
                        [VectorCaracteristicas_i,~,coeffEEG,~] = FeaturesV2wavelet(app.EEG_a_analizar,app.muestras_eeg,app.cEEG,WaveletMadre,Niveles_des,Vector_Opciones);
                        vcbot = vcsup+1;
                        vcsup = vcbot+sum(Vector_Opciones)-1;
                        VectorCaracteristicas_Todo_Canal(vcbot:vcsup,:) = VectorCaracteristicas_i';
                    end
                    
                    %Clasificación con el clasificador realizado
                    if(app.Struct_Clasificador.Tipo_Clasificador == 0)
                        %RNN
                        VectorCaracteristicas = VectorCaracteristicas_i';
                        Etiquetas_Generadas_EEGONEHOT = AlgoritmoAA(VectorCaracteristicas);
                        %Se pasan las etiquetas de One-Hot a un vector con números
                        %1, 2, 3 o 4, dependiendo de la cantidad de clases
                        nwmax = length(VectorCaracteristicas);
                        Etiquetas_Generadas_EEG = zeros(1,nwmax);
                        for i=1:nwmax
                            ventana_interes = Etiquetas_Generadas_EEGONEHOT(:,i);
                            indice_max = find(ventana_interes==max(ventana_interes));
                            if 1 == indice_max
                                Etiquetas_Generadas_EEG(1,i) = 1;
                            elseif 2 == indice_max
                                Etiquetas_Generadas_EEG(1,i) = 2;
                            elseif 3 == indice_max
                                Etiquetas_Generadas_EEG(1,i) = 3;
                            elseif 4 == indice_max
                                Etiquetas_Generadas_EEG(1,i) = 4;
                            end
                        end
                        Vector_Etiquetas_Completo(j,:) = Etiquetas_Generadas_EEG;
                    elseif(app.Struct_Clasificador.Tipo_Clasificador == 1)
                        %SVM
                        VectorCaracteristicas = VectorCaracteristicas_i;
                        size(VectorCaracteristicas)
                        [Etiquetas_Generadas_EEG, puntajes]= predict(AlgoritmoAA, VectorCaracteristicas);                
                        Vector_Etiquetas_Completo(j,:) = Etiquetas_Generadas_EEG';
                    end   
                    
                    %Clustering: K-means en función .m
                    [E, ~] = k_means(Matriz_featuresMorf',m,0);
                    Vector_Etiquetas_Morfologia(j,:) = E';
                end                           
                %Se guarda la matriz de etiquetas a compartir entre pantallas        
                app.Vector_Car_EEGMOR = Vector_Etiquetas_Morfologia;
                app.EEG_VI = app.EEG_a_analizar;
                %Se guarda la matriz de etiquetas en la matriz que se
                %compartirá entre ventanas, así como la señal EEG            
                %Matriz_etiquetas =Vector_Etiquetas_Completo
                app.Vector_Car_EEGVI = Vector_Etiquetas_Completo;           
                app.ObservaranotacionesButton.Enable = 'on';
                close(d); %Cerrar barra de progreso
                fig = app.ClasificadorEEGInteres;
                message = 'Las señales se han procesado de forma exitosa.';%Avisamos la finalización de la carga
                uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato 

            end
        end

        % Button pushed function: CargarClasificadorButton
        function CargarClasificadorButtonPushed(app, event)
            %Seleccionar archivo MAT con vector de caracteristicas y
            %etiquetas
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
                fig = app.ClasificadorEEGInteres;
                message = 'Usuario canceló la operacion';
                uialert(fig ,message,'Aviso','Icon','warning');%Acualizacion a ultimo formato
            else
                disp(['User selected ', fullfile(path,file)]);
                espacio_trabajo_temp = load(strcat(path,file),'Clasificador_Entrenado');
                app.Struct_Clasificador = espacio_trabajo_temp.Clasificador_Entrenado;
                app.ObservaranotacionesButton.Enable = 'off';
                app.ProcesarlasealEEGButton.Enable = 'on';
            end            
        end

        % Button pushed function: ObservaranotacionesButton
        function ObservaranotacionesButtonPushed(app, event)
            %app.ch_int=canales_cell


            if app.NoSupervisadoCheckBox.Value == 1
                % Definir el tamaño de ventana (en segundos)
                window_size = 30;  % Puedes cambiarlo si deseas un tamaño de ventana diferente
                % Obtener los nombres de los canales seleccionados en la lista de canales a procesar
                selectedChannels = app.CanalesaprocesarListBox.Items;
                
                % Inicializar una estructura para almacenar las características de cada canal
                all_channel_features = struct();
                all_channel_clusters = struct();
                all_channel_data = struct();
                % Extraer características para cada canal seleccionado
                for i = 1:length(selectedChannels)
                    channelName = selectedChannels{i};
                    
                    % Convertir el nombre del canal a un nombre de campo válido
                    validChannelName = strrep(channelName, '-', '_');
                    
                    % Obtener el índice del canal en la matriz de datos (app.EEG_a_analizar)
                    channelIndex = find(strcmp(app.Nombres_Canales, channelName));
                    
                    if isempty(channelIndex)
                        % Si no se encuentra el canal, mostrar un mensaje y saltar a la siguiente iteración
                        disp(['Canal ' channelName ' no encontrado en los datos']);
                        continue;
                    end
                    
                    % Obtener los datos del canal específico
                    channelData = app.EEG_a_analizar(channelIndex, :);
                    
                    % Extraer características del canal
                    features = ExtraerCaracteristicas(channelData, app.Fs, window_size);
                    
                    % Guardar las características en la estructura con el nombre de campo válido
                    all_channel_features.(validChannelName) = features;
                end
            
                % IMPLEMENTACIÓN DE PCA
                % Concatenar todas las características de todos los canales seleccionados
                concatenated_features = [];
                channel_names = fieldnames(all_channel_features);
                
                for i = 1:numel(channel_names)
                    concatenated_features = [concatenated_features; all_channel_features.(channel_names{i})];
                end
               
                % Calcular la matriz de covarianza
                N = size(concatenated_features, 1);
    
                % Calcular la matriz de covarianza
                Sdisp = (N-1) * cov(concatenated_features);
    
                % Descomposición en valores propios
                [Phi, Lambda] = eig(Sdisp);
                [lambda_sorted, indices] = sort(diag(Lambda), 'descend');
                Phi_ordenada = Phi(:, indices);
            
                % Transformar los datos a las componentes principales
                X_pca = (Phi_ordenada' * concatenated_features')';
                
                % Determinar número de clusters
                if app.SanoEpilepsiaCheckBox.Value == 1
                    num_clusters = 2;
                else
                    num_clusters = app.CantidaddegruposEditField.Value;
                end
            
                % Selección del método de agrupación
                selectedOption = app.AgrupadorDropDown.Value;
                PCA_Option = app.ComponentesautilizarDropDown.Value;
                % Usar switch para manejar diferentes casos basados en la selección del DropDown
                switch selectedOption
                    case 'Jerarquíco'
                        switch PCA_Option
                            case '1er componente'
                                cluster_labels = clusterdata(X_pca(:, 1), 'Linkage', 'ward', 'Maxclust', num_clusters);
                            case '2do componente'
                                cluster_labels = clusterdata(X_pca(:, 2), 'Linkage', 'ward', 'Maxclust', num_clusters);
                            case '3er componente'
                                cluster_labels = clusterdata(X_pca(:, 3), 'Linkage', 'ward', 'Maxclust', num_clusters);
                            case 'Los 3 componentes'
                                cluster_labels = clusterdata(X_pca(:, 1:3), 'Linkage', 'ward', 'Maxclust', num_clusters);
                        end
                        
                    case 'Fuzzy-C'
                        q = 2;
                        switch PCA_Option
                            case '1er componente'
                                [cluster_labels, centros1_fcm] = fcm(X_pca(:, 1), num_clusters, q, 2);
                            case '2do componente'
                                [cluster_labels, centros1_fcm] = fcm(X_pca(:, 2), num_clusters, q, 2);
                            case '3er componente'
                                [cluster_labels, centros1_fcm] = fcm(X_pca(:, 3), num_clusters, q, 2);
                            case 'Los 3 componentes'
                                [cluster_labels, centros1_fcm] = fcm(X_pca(:, 1:3), num_clusters, q, 2);
                        end
                        
                    case 'K-means'
                        
                        switch PCA_Option
                            case '1er componente'
                                % K-means
                                [cluster_labels, ~] = kmeans(X_pca(:, 1), num_clusters);
                            case '2do componente'
                                 % K-means
                                [cluster_labels, ~] = kmeans(X_pca(:, 2), num_clusters);
                            case '3er componente'
                                 % K-means
                                [cluster_labels, ~] = kmeans(X_pca(:, 3), num_clusters);
                            case 'Los 3 componentes'
                                 % K-means
                                [cluster_labels, ~] = kmeans(X_pca(:, 1:3), num_clusters);
                        end
                end

                % Separar los resultados de los clusters por canal y almacenar en la estructura all_channel_clusters
                cluster_start_idx = 1;
                
                for i = 1:numel(channel_names)
                    validChannelName = channel_names{i};
                    num_windows = size(all_channel_features.(validChannelName), 1);  % Número de ventanas para este canal
                    
                    % Extraer etiquetas de clusters correspondientes a este canal
                    channel_clusters = cluster_labels(cluster_start_idx : cluster_start_idx + num_windows - 1);
                    
                    % Guardar los resultados en la estructura con el nombre de canal válido
                    all_channel_clusters.(validChannelName) = channel_clusters;
                    
                    % Actualizar índice de inicio para el siguiente canal
                    cluster_start_idx = cluster_start_idx + num_windows;
                end
            
                % Ahora `all_channel_clusters` contiene los resultados de los clusters para cada canal por separado
                disp('Resultados de los clusters por canal:');
                disp(all_channel_data); % Mostrar para verificar que contiene datos
                disp(all_channel_features); % Mostrar para verificar que contiene datos
                disp(all_channel_clusters); % Mostrar para verificar que contiene datos
            else
                app.ch_int = app.CanalesaprocesarListBox.Items;
                app.InterestWindows = anotacionesautomaticas(app);
            end
            % Crear una instancia de Anotaciones_no_supervisado y pasar los datos
            anotacionesApp = Anotaciones_no_supervisado;
            anotacionesApp.Fs = app.Fs;  % Pasa el valor de Fs
       
            % Pasar las estructuras de características y clusters a la app de anotaciones
            anotacionesApp.all_channel_features = all_channel_features;
            anotacionesApp.all_channel_clusters = all_channel_clusters;
            anotacionesApp.all_channel_data = all_channel_data;
            
            % Configurar los nombres de los canales en el DropDown de Anotaciones_no_supervisado
            channelNames = fieldnames(all_channel_features);
            anotacionesApp.CanalavisualizarDropDown.Items = channelNames;
            anotacionesApp.CanalavisualizarDropDown.Value = channelNames{1}; % Establecer el primer canal como seleccionado
            
            % Llamar a la función para graficar el canal inicial en Anotaciones_no_supervisado
            anotacionesApp.actualizarGrafica();
        end

        % Value changed function: Mas
        function MasValueChanged(app, event)
            array_chdisponible = app.CanalesdisponiblesListBox.Items;%Disponibles
            array_chselecionado = app.CanalesdisponiblesListBox.Value;%Selección
            array_chanalizar = app.CanalesaprocesarListBox.Items;%A procesar
            cnt = 1;
            %Borrar de list1 los elegidos
            indices_elegidos = ismember(array_chdisponible,array_chselecionado);%Indices a revisar
            nueva_disponible = cell(1,length(array_chdisponible)-length(array_chselecionado));
            for s=1:length(indices_elegidos)
                if indices_elegidos(s) == 0
                    nueva_disponible(cnt) = array_chdisponible(s);
                    cnt = cnt+1;
                end
            end
            %Agregar en orden en la lista de seleccionados
            nuevo_array_chanalizar = [array_chselecionado,array_chanalizar];
            indices_sel = ismember(app.canales_listado,nuevo_array_chanalizar);%Indices a revisar
            nueva_seleccion = cell(1,length(nuevo_array_chanalizar));
            cnt = 1;
            for s=1:length(indices_sel)
                if indices_sel(s) == 1
                    nueva_seleccion(cnt) = app.canales_listado(s);
                    cnt = cnt+1;
                end
            end            
            app.CanalesdisponiblesListBox.Items = nueva_disponible;
            app.CanalesaprocesarListBox.Items = nueva_seleccion;
        end

        % Value changed function: Menos
        function MenosValueChanged(app, event)
            array_chdisponible = app.CanalesdisponiblesListBox.Items;%Disponibles
            array_chselecionado = app.CanalesaprocesarListBox.Value;%Selección
            array_chanalizar = app.CanalesaprocesarListBox.Items;%A procesar
            cnt = 1;
            %Borrar de list5 los elegidos
            indices_elegidos = ismember(array_chanalizar,array_chselecionado);%Indices a revisar
            nueva_seleccion = cell(1,length(array_chanalizar)-length(array_chselecionado));
            for s=1:length(indices_elegidos)
                if indices_elegidos(s) == 0
                    nueva_seleccion(cnt) = array_chanalizar(s);
                    cnt = cnt+1;
                end
            end
            %Agregar en orden en la lista de seleccionados
            nuevo_array_disponible = [array_chselecionado,array_chdisponible];
            indices_sel = ismember(app.canales_listado,nuevo_array_disponible);%Indices a revisar
            nueva_disponible = cell(1,length(nuevo_array_disponible));
            cnt = 1;
            for s=1:length(indices_sel)
                if indices_sel(s) == 1
                    nueva_disponible(cnt) = app.canales_listado(s);
                    cnt = cnt+1;
                end
            end
            app.CanalesdisponiblesListBox.Items = nueva_disponible;
            app.CanalesaprocesarListBox.Items = nueva_seleccion;
        end

        % Value changed function: PacienteEditField
        function PacienteEditFieldValueChanged(app, event)
           app.BuscarButtonPushed(); % Si se cambia correr actualizar
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            id_paciente = app.PacienteEditField.Value;
            
            MyData=[];
           
            app.UITable.Data = MyData;
            
            conn = app.Conexion;
            
            %Set query to execute on the database
            query = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '"'];
            
            data = fetch(conn,query);

            if(isempty(data)==1)
                fig = app.ClasificadorEEGInteres;
                message = 'Paciente no encontrado o sin datos para analizar';
                uialert(fig ,message,'Error','Icon','error');%Acualizacion a ultimo formato 
            end
            dimension=size(data);
            for i=1:dimension(1)
                prueba = sprintf('%d',data.id_prueba(i));
                freq = sprintf('%d',data.frecuencia(i));
                canales = sprintf('%d',data.no_canales(i));
                MyData=[MyData; [{i} {prueba} {freq} {canales}]];
            end
            app.UITable.Data = MyData;
            
            %Clear variables
            clear query;
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            if (~isempty(selection)) %Evaluamos si se selecciono un dato

                data_prueba = app.UITable.Data(selection(1), 2); %Dato seleccionado
                data_prueba = cell2mat(data_prueba); %Transformacion de cell a que contiene string 
                
                if (selection(2) == 2) %Evaluamos si se selecciono un dato de la columna pruebas
                    app.PruebaEditField.Value = data_prueba;
                    %Hacemos que numero de prueba salga en el recuadro  
                end
            end
        end

        % Button pushed function: MontajeButton
        function MontajeButtonPushed(app, event)
            if app.isBipolar == 1
                % Definir el número de canales por grupo
                numChannels = size(app.EEG_a_analizar, 1);
                numSamples = size(app.EEG_a_analizar, 2);
                midPoint = ceil(numChannels / 2);  % Punto medio para dividir los canales
        
                % Crear el vector de tiempo en segundos y convertirlo a formato HH:MM:SS
                timeVector = (0:numSamples-1) / app.Fs;
                timeDuration = seconds(timeVector);
                timeDuration.Format = 'hh:mm:ss';
        
                % Convertir los datos de EEG a tabla con las muestras en filas y canales como columnas
                dataTable = array2table(app.EEG_a_analizar', 'VariableNames', app.Nombres_Canales);
                dataTable.Time = timeDuration';  % Asegurarse de que el tiempo tiene la misma cantidad de filas que dataTable
        
                % Crear el primer stackedplot (primer grupo de canales)
                figure;  % Abre una nueva figura
                stackedplot(dataTable(:, [{'Time'}, app.Nombres_Canales(1:midPoint)]), 'XVariable', 'Time');
                title('Grupo 1 de Canales');
        
                % Crear el segundo stackedplot (segundo grupo de canales)
                figure;  % Abre otra figura para el segundo grupo
                stackedplot(dataTable(:, [{'Time'}, app.Nombres_Canales(midPoint+1:end)]), 'XVariable', 'Time');
                title('Grupo 2 de Canales');
            else
                % Obtener los nombres de los canales disponibles y la data de EEG
                availableChannels = app.Nombres_Canales;
                eegData = app.EEG_a_analizar;  % Data de EEG completo
                
                % Crear una instancia de Seleccion_montaje y pasar los datos
                seleccionMontajeApp = Seleccion_montaje();
                seleccionMontajeApp.setAvailableChannelsAndData(availableChannels, eegData, app.Fs);  % Llama al método para pasar ambos datos
                seleccionMontajeApp.UIFigure.Visible = 'on';  % Asegurarse de que la nueva ventana esté visible
            end
        end

        % Value changed function: SupervisadoCheckBox
        function SupervisadoCheckBoxValueChanged(app, event)
            value = app.SupervisadoCheckBox.Value;
            if app.SupervisadoCheckBox.Value
                % Deselecciona "No Supervisado" si se selecciona "Supervisado"
                app.NoSupervisadoCheckBox.Value = false;
            end
            if value == 1
                app.CargarClasificadorButton.Enable = 'on';
                app.VATButton.Enable = 'off';
                app.AgrupadorDropDown.Enable = 'off';
                app.ComponentesautilizarDropDown.Enable = 'off';
                app.SanoEpilepsiaCheckBox.Enable = 'off';
                app.EscogercantidaddegruposCheckBox.Enable = 'off';
            else
                app.CargarClasificadorButton.Enable = 'off';
            end
        end

        % Value changed function: NoSupervisadoCheckBox
        function NoSupervisadoCheckBoxValueChanged(app, event)
            value = app.NoSupervisadoCheckBox.Value;
             if app.NoSupervisadoCheckBox.Value
                % Deselecciona "Supervisado" si se selecciona "No Supervisado"
                app.SupervisadoCheckBox.Value = false;
             end

             if value == 1   
                 app.VATButton.Enable = 'on';
                 app.AgrupadorDropDown.Enable = 'on';
                 app.ComponentesautilizarDropDown.Enable = 'on';
                 app.SanoEpilepsiaCheckBox.Enable = 'on';
                 app.EscogercantidaddegruposCheckBox.Enable = 'on';
                 app.ObservaranotacionesButton.Enable = 'on';
                 app.CargarClasificadorButton.Enable = 'off';
             else
                 app.VATButton.Enable = 'off';
                 app.AgrupadorDropDown.Enable = 'off';
                 app.ComponentesautilizarDropDown.Enable = 'off';
                 app.SanoEpilepsiaCheckBox.Enable = 'off';
                 app.EscogercantidaddegruposCheckBox.Enable = 'off';
             end
        end

        % Value changed function: SanoEpilepsiaCheckBox
        function SanoEpilepsiaCheckBoxValueChanged(app, event)
            value = app.SanoEpilepsiaCheckBox.Value;
            if app.SanoEpilepsiaCheckBox.Value
                app.CantidaddegruposEditField.Enable = 'off'
                app.EscogercantidaddegruposCheckBox.Value = false;
             end
        end

        % Value changed function: EscogercantidaddegruposCheckBox
        function EscogercantidaddegruposCheckBoxValueChanged(app, event)
            value = app.EscogercantidaddegruposCheckBox.Value;
            if app.EscogercantidaddegruposCheckBox.Value
                app.CantidaddegruposEditField.Enable = 'on'
                app.SanoEpilepsiaCheckBox.Value = false;
            end

        end

        % Button pushed function: VATButton
        function VATButtonPushed(app, event)
            % Definir el tamaño de ventana (en segundos)
            window_size = 30;  % Puedes cambiarlo si deseas un tamaño de ventana diferente
            
            % Llamar a la función para extraer características
            features = ExtraerCaracteristicas(app.EEG_a_analizar, app.Fs, window_size);
            VAT(features);
            % Llamar a la función VAT para visualizar la tendencia de los clusters
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ClasificadorEEGInteres = uifigure('Visible', 'off');
            app.ClasificadorEEGInteres.Name = 'Clasificador del EEG';
            app.ClasificadorEEGInteres.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.CargarMATButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.CargarMATButton.ButtonPushedFcn = createCallbackFcn(app, @CargarMATButtonPushed, true);
            app.CargarMATButton.Text = 'Cargar MAT';
            app.CargarEDFButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.CargarEDFButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEDFButtonPushed, true);
            app.CargarEDFButton.Text = 'Cargar EDF';
            app.CargarClasificadorButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.CargarClasificadorButton.ButtonPushedFcn = createCallbackFcn(app, @CargarClasificadorButtonPushed, true);
            app.CargarClasificadorButton.Enable = 'off';
            app.CargarClasificadorButton.Text = 'Cargar Clasificador';
            app.CargadelasealEEGLabel = uilabel(app.ClasificadorEEGInteres);
            app.CargadelasealEEGLabel.Text = 'Carga de la señal EEG';
            app.EPILEPTICEEGANALYSISTOOLBOXLabel = uilabel(app.ClasificadorEEGInteres);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel.Text = 'EPILEPTIC EEG ANALYSIS TOOLBOX';
            app.HzIctal = uilabel(app.ClasificadorEEGInteres);
            app.HzIctal.Text = 'Hz';
            app.TextoDimensionesEEG = uilabel(app.ClasificadorEEGInteres);
            app.TextoDimensionesEEG.Text = 'Tamaño: - x -';
            app.TextoTiempoEEG = uilabel(app.ClasificadorEEGInteres);
            app.TextoTiempoEEG.Text = 'Duración: -';
            app.FreqMuestreoFsEditFieldLabel = uilabel(app.ClasificadorEEGInteres);
            app.FreqMuestreoFsEditFieldLabel.Text = 'Freq. Muestreo (Fs)';
            app.FreqMuestreoFsIctalEditField = uieditfield(app.ClasificadorEEGInteres, 'numeric');
            app.ProcesarlasealEEGButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.ProcesarlasealEEGButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarlasealEEGButtonPushed, true);
            app.ProcesarlasealEEGButton.Enable = 'off';
            app.ProcesarlasealEEGButton.Text = 'Procesar la señal EEG';
            app.sLabel = uilabel(app.ClasificadorEEGInteres);
            app.sLabel.Visible = 'off';
            app.sLabel.Text = 's';
            app.TiempodeventanaEditFieldLabel = uilabel(app.ClasificadorEEGInteres);
            app.TiempodeventanaEditFieldLabel.Visible = 'off';
            app.TiempodeventanaEditFieldLabel.Text = 'Tiempo de ventana';
            app.TiempodeventanaEditField = uieditfield(app.ClasificadorEEGInteres, 'numeric');
            app.TiempodeventanaEditField.Visible = 'off';
            app.TiempodeventanaEditField.Value = 1;
            app.ObservaranotacionesButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.ObservaranotacionesButton.ButtonPushedFcn = createCallbackFcn(app, @ObservaranotacionesButtonPushed, true);
            app.ObservaranotacionesButton.Enable = 'off';
            app.ObservaranotacionesButton.Text = 'Observar anotaciones';
            app.CargarSealEEGaAnalizarLabel = uilabel(app.ClasificadorEEGInteres);
            app.CargarSealEEGaAnalizarLabel.Text = 'Cargar Señal EEG a Analizar';
            app.CanalesdisponiblesLabel = uilabel(app.ClasificadorEEGInteres);
            app.CanalesdisponiblesLabel.Text = {'Canales '; 'disponibles'};
            app.CanalesdisponiblesListBox = uilistbox(app.ClasificadorEEGInteres);
            app.CanalesdisponiblesListBox.Items = {''};
            app.CanalesdisponiblesListBox.Multiselect = 'on';
            app.CanalesdisponiblesListBox.Enable = 'off';
            app.CanalesdisponiblesListBox.Value = {};
            app.CanalesaprocesarLabel = uilabel(app.ClasificadorEEGInteres);
            app.CanalesaprocesarLabel.Text = {'Canales'; 'a procesar'};
            app.CanalesaprocesarListBox = uilistbox(app.ClasificadorEEGInteres);
            app.CanalesaprocesarListBox.Items = {};
            app.CanalesaprocesarListBox.Multiselect = 'on';
            app.CanalesaprocesarListBox.Enable = 'off';
            app.CanalesaprocesarListBox.Value = {};
            app.Mas = uibutton(app.ClasificadorEEGInteres, 'state');
            app.Mas.ValueChangedFcn = createCallbackFcn(app, @MasValueChanged, true);
            app.Mas.Enable = 'off';
            app.Mas.Text = '+';
            app.Menos = uibutton(app.ClasificadorEEGInteres, 'state');
            app.Menos.ValueChangedFcn = createCallbackFcn(app, @MenosValueChanged, true);
            app.Menos.Enable = 'off';
            app.Menos.Text = '-';
            app.CargadeBaseDatosButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.CargadeBaseDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargadeBaseDatosButtonPushed, true);
            app.CargadeBaseDatosButton.Text = 'Carga de Base Datos';
            app.UITable = uitable(app.ClasificadorEEGInteres);
            app.UITable.ColumnName = {'No.'; 'Prueba'; 'Frecuencia (Hz)'; 'No. Canales'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.BuscarButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.PacienteEditFieldLabel = uilabel(app.ClasificadorEEGInteres);
            app.PacienteEditFieldLabel.Text = 'Paciente';
            app.PacienteEditField = uieditfield(app.ClasificadorEEGInteres, 'text');
            app.PacienteEditField.ValueChangedFcn = createCallbackFcn(app, @PacienteEditFieldValueChanged, true);
            app.PruebaEditFieldLabel = uilabel(app.ClasificadorEEGInteres);
            app.PruebaEditFieldLabel.Text = 'Prueba';
            app.PruebaEditField = uieditfield(app.ClasificadorEEGInteres, 'text');
            app.PruebaEditField.Editable = 'off';
            app.MontajeButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.MontajeButton.ButtonPushedFcn = createCallbackFcn(app, @MontajeButtonPushed, true);
            app.MontajeButton.Enable = 'off';
            app.MontajeButton.Text = 'Montaje';
            app.SupervisadoCheckBox = uicheckbox(app.ClasificadorEEGInteres);
            app.SupervisadoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SupervisadoCheckBoxValueChanged, true);
            app.SupervisadoCheckBox.Enable = 'off';
            app.SupervisadoCheckBox.Text = 'Supervisado';
            app.NoSupervisadoCheckBox = uicheckbox(app.ClasificadorEEGInteres);
            app.NoSupervisadoCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoSupervisadoCheckBoxValueChanged, true);
            app.NoSupervisadoCheckBox.Enable = 'off';
            app.NoSupervisadoCheckBox.Text = 'No Supervisado';
            app.VATButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.VATButton.ButtonPushedFcn = createCallbackFcn(app, @VATButtonPushed, true);
            app.VATButton.Enable = 'off';
            app.VATButton.Text = 'VAT';
            app.AgrupadorDropDownLabel = uilabel(app.ClasificadorEEGInteres);
            app.AgrupadorDropDownLabel.Enable = 'off';
            app.AgrupadorDropDownLabel.Text = 'Agrupador';
            app.AgrupadorDropDown = uidropdown(app.ClasificadorEEGInteres);
            app.AgrupadorDropDown.Items = {'Jerarquíco', 'Fuzzy-C', 'K-means'};
            app.AgrupadorDropDown.Enable = 'off';
            app.AgrupadorDropDown.Value = 'Jerarquíco';
            app.SanoEpilepsiaCheckBox = uicheckbox(app.ClasificadorEEGInteres);
            app.SanoEpilepsiaCheckBox.ValueChangedFcn = createCallbackFcn(app, @SanoEpilepsiaCheckBoxValueChanged, true);
            app.SanoEpilepsiaCheckBox.Enable = 'off';
            app.SanoEpilepsiaCheckBox.Text = 'Sano - Epilepsia';
            app.SanoEpilepsiaCheckBox.Value = true;
            app.ComponentesautilizarDropDownLabel = uilabel(app.ClasificadorEEGInteres);
            app.ComponentesautilizarDropDownLabel.Enable = 'off';
            app.ComponentesautilizarDropDownLabel.Text = 'Componentes a utilizar';
            app.ComponentesautilizarDropDown = uidropdown(app.ClasificadorEEGInteres);
            app.ComponentesautilizarDropDown.Items = {'1er componente', '2do componente', '3er componente', 'Los 3 componentes'};
            app.ComponentesautilizarDropDown.Enable = 'off';
            app.ComponentesautilizarDropDown.Value = '1er componente';
            app.EscogercantidaddegruposCheckBox = uicheckbox(app.ClasificadorEEGInteres);
            app.EscogercantidaddegruposCheckBox.ValueChangedFcn = createCallbackFcn(app, @EscogercantidaddegruposCheckBoxValueChanged, true);
            app.EscogercantidaddegruposCheckBox.Enable = 'off';
            app.EscogercantidaddegruposCheckBox.Text = 'Escoger cantidad de grupos';
            app.CantidaddegruposEditFieldLabel = uilabel(app.ClasificadorEEGInteres);
            app.CantidaddegruposEditFieldLabel.Enable = 'off';
            app.CantidaddegruposEditFieldLabel.Text = 'Cantidad de grupos';
            app.CantidaddegruposEditField = uieditfield(app.ClasificadorEEGInteres, 'numeric');
            app.CantidaddegruposEditField.Enable = 'off';
            % Show the figure after all components are created
            app.ClasificadorEEGInteres.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inicioanotacionesauto(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ClasificadorEEGInteres)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ClasificadorEEGInteres)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClasificadorEEGInteres)
        end
    end
end