classdef multigrafico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        VentanaMultigrficoLabel   matlab.ui.control.Label
        TiempoMaxsEditField       matlab.ui.control.NumericEditField
        TiempoMaxsEditFieldLabel  matlab.ui.control.Label
        TiempoMinsEditField       matlab.ui.control.NumericEditField
        TiempoMinsEditFieldLabel  matlab.ui.control.Label
        CanalSpinner_12           matlab.ui.control.Spinner
        CanalSpinner_12Label      matlab.ui.control.Label
        CanalSpinner_9            matlab.ui.control.Spinner
        CanalSpinner_9Label       matlab.ui.control.Label
        CanalSpinner_6            matlab.ui.control.Spinner
        CanalSpinner_6Label       matlab.ui.control.Label
        CanalSpinner_3            matlab.ui.control.Spinner
        CanalSpinner_3Label       matlab.ui.control.Label
        CanalSpinner_11           matlab.ui.control.Spinner
        CanalSpinner_11Label      matlab.ui.control.Label
        CanalSpinner_8            matlab.ui.control.Spinner
        CanalSpinner_8Label       matlab.ui.control.Label
        CanalSpinner_5            matlab.ui.control.Spinner
        CanalSpinner_5Label       matlab.ui.control.Label
        CanalSpinner_2            matlab.ui.control.Spinner
        CanalSpinner_2Label       matlab.ui.control.Label
        CanalSpinner_10           matlab.ui.control.Spinner
        CanalSpinner_10Label      matlab.ui.control.Label
        CanalSpinner_7            matlab.ui.control.Spinner
        CanalSpinner_7Label       matlab.ui.control.Label
        CanalSpinner_4            matlab.ui.control.Spinner
        CanalSpinner_4Label       matlab.ui.control.Label
        CanalSpinner_1            matlab.ui.control.Spinner
        CanalSpinnerLabel         matlab.ui.control.Label
        VisualizarButton          matlab.ui.control.Button
        UIAxes_12                 matlab.ui.control.UIAxes
        UIAxes_11                 matlab.ui.control.UIAxes
        UIAxes_10                 matlab.ui.control.UIAxes
        UIAxes_9                  matlab.ui.control.UIAxes
        UIAxes_8                  matlab.ui.control.UIAxes
        UIAxes_7                  matlab.ui.control.UIAxes
        UIAxes_6                  matlab.ui.control.UIAxes
        UIAxes_5                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes_1                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Aplicacion principal
        record % Datos de las señales
        no_canales % Numero de canales en record
        Ts % Periodo de muestreo
        tiempo % Vector de tiempo para graficar
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
             app.CallingApp = mainapp;
             app.record = app.CallingApp.datos;
             app.no_canales = size(app.record, 1);
             app.Ts = 1/str2double(app.CallingApp.freq); %Periodo de muestreo
             app.tiempo = (1:length(app.record))*app.Ts;
          

             app.CanalSpinner_1.Limits=[1,app.no_canales];
             app.CanalSpinner_2.Limits=[1,app.no_canales];
             app.CanalSpinner_3.Limits=[1,app.no_canales];
             app.CanalSpinner_4.Limits=[1,app.no_canales];
             app.CanalSpinner_5.Limits=[1,app.no_canales];
             app.CanalSpinner_6.Limits=[1,app.no_canales];
             app.CanalSpinner_7.Limits=[1,app.no_canales];
             app.CanalSpinner_8.Limits=[1,app.no_canales];
             app.CanalSpinner_9.Limits=[1,app.no_canales];
             app.CanalSpinner_10.Limits=[1,app.no_canales];
             app.CanalSpinner_11.Limits=[1,app.no_canales];
             app.CanalSpinner_12.Limits=[1,app.no_canales];

            
            %Graficar canal y tiempo seleccionado
            app.UIAxes_1.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_1,app.tiempo,app.record(app.CanalSpinner_1.Value,:));
           
            app.UIAxes_2.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_2,app.tiempo,app.record(app.CanalSpinner_2.Value,:));
            
            app.UIAxes_3.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_3,app.tiempo,app.record(app.CanalSpinner_3.Value,:));

            app.UIAxes_4.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_4,app.tiempo,app.record(app.CanalSpinner_4.Value,:));

            app.UIAxes_5.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_5,app.tiempo,app.record(app.CanalSpinner_5.Value,:));

            app.UIAxes_6.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_6,app.tiempo,app.record(app.CanalSpinner_6.Value,:));

            app.UIAxes_7.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_7,app.tiempo,app.record(app.CanalSpinner_7.Value,:));

            app.UIAxes_8.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_8,app.tiempo,app.record(app.CanalSpinner_8.Value,:));

            app.UIAxes_9.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_9,app.tiempo,app.record(app.CanalSpinner_9.Value,:));

            app.UIAxes_10.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_10,app.tiempo,app.record(app.CanalSpinner_10.Value,:));

            app.UIAxes_11.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_11,app.tiempo,app.record(app.CanalSpinner_11.Value,:));

            app.UIAxes_12.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_12,app.tiempo,app.record(app.CanalSpinner_12.Value,:));
        end

        % Button pushed function: VisualizarButton
        function VisualizarButtonPushed(app, event)
  
        %Generar el vector tiempo
        app.tiempo = (1:length(app.record))*app.Ts;
        tmin = app.TiempoMinsEditField.Value;
        tmax = app.TiempoMaxsEditField.Value;
        %Acotar gráfica según tamaño del archivo EDF
        if tmax>length(app.record)*app.Ts
            tmax = length(app.record)*app.Ts;
            app.TiempoMaxsEditField.Value = round(length(app.record)*app.Ts);
        elseif mod(tmax,1)~=0 || mod(tmin,1)~=0 %si es decimal
            tmax = round(tmax);
            tmin = round(tmin);
        % elseif tmin==0
        %     app.TiempoMinsEditField.Value = 1;
        %     tmin=1;
        end
        
            %Graficar canal y tiempo seleccionado
            app.UIAxes_1.XLim=[tmin, tmax];
            plot(app.UIAxes_1,app.tiempo,app.record(app.CanalSpinner_1.Value,:));
           
            app.UIAxes_2.XLim=[tmin, tmax];
            plot(app.UIAxes_2,app.tiempo,app.record(app.CanalSpinner_2.Value,:));
            
            app.UIAxes_3.XLim=[tmin, tmax];
            plot(app.UIAxes_3,app.tiempo,app.record(app.CanalSpinner_3.Value,:));

            app.UIAxes_4.XLim=[tmin, tmax];
            plot(app.UIAxes_4,app.tiempo,app.record(app.CanalSpinner_4.Value,:));

            app.UIAxes_5.XLim=[tmin, tmax];
            plot(app.UIAxes_5,app.tiempo,app.record(app.CanalSpinner_5.Value,:));

            app.UIAxes_6.XLim=[tmin, tmax];
            plot(app.UIAxes_6,app.tiempo,app.record(app.CanalSpinner_6.Value,:));

            app.UIAxes_7.XLim=[tmin, tmax];
            plot(app.UIAxes_7,app.tiempo,app.record(app.CanalSpinner_7.Value,:));

            app.UIAxes_8.XLim=[tmin, tmax];
            plot(app.UIAxes_8,app.tiempo,app.record(app.CanalSpinner_8.Value,:));

            app.UIAxes_9.XLim=[tmin, tmax];
            plot(app.UIAxes_9,app.tiempo,app.record(app.CanalSpinner_9.Value,:));

            app.UIAxes_10.XLim=[tmin, tmax];
            plot(app.UIAxes_10,app.tiempo,app.record(app.CanalSpinner_10.Value,:));

            app.UIAxes_11.XLim=[tmin, tmax];
            plot(app.UIAxes_11,app.tiempo,app.record(app.CanalSpinner_11.Value,:));

            app.UIAxes_12.XLim=[tmin, tmax];
            plot(app.UIAxes_12,app.tiempo,app.record(app.CanalSpinner_12.Value,:));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_1 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_1, 'Tiempo (s)')
            ylabel(app.UIAxes_1, 'Amplitud')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_2, 'Tiempo (s)')
            ylabel(app.UIAxes_2, 'Amplitud')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_3, 'Tiempo (s)')
            ylabel(app.UIAxes_3, 'Amplitud')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_4, 'Tiempo (s)')
            ylabel(app.UIAxes_4, 'Amplitud')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_5, 'Tiempo (s)')
            ylabel(app.UIAxes_5, 'Amplitud')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_6, 'Tiempo (s)')
            ylabel(app.UIAxes_6, 'Amplitud')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_7, 'Tiempo (s)')
            ylabel(app.UIAxes_7, 'Amplitud')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_8, 'Tiempo (s)')
            ylabel(app.UIAxes_8, 'Amplitud')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_9, 'Tiempo (s)')
            ylabel(app.UIAxes_9, 'Amplitud')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_10 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_10, 'Tiempo (s)')
            ylabel(app.UIAxes_10, 'Amplitud')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_11 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_11, 'Tiempo (s)')
            ylabel(app.UIAxes_11, 'Amplitud')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_12 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_12, 'Tiempo (s)')
            ylabel(app.UIAxes_12, 'Amplitud')
            zlabel(app.UIAxes_12, 'Z')
            app.VisualizarButton = uibutton(app.GridLayout, 'push');
            app.VisualizarButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarButtonPushed, true);
            app.VisualizarButton.Text = 'Visualizar';
            app.CanalSpinnerLabel = uilabel(app.GridLayout);
            app.CanalSpinnerLabel.Text = 'Canal';
            app.CanalSpinner_1 = uispinner(app.GridLayout);
            app.CanalSpinner_1.Limits = [1 35];
            app.CanalSpinner_1.Value = 1;
            app.CanalSpinner_4Label = uilabel(app.GridLayout);
            app.CanalSpinner_4Label.Text = 'Canal';
            app.CanalSpinner_4 = uispinner(app.GridLayout);
            app.CanalSpinner_4.Limits = [1 35];
            app.CanalSpinner_4.Value = 4;
            app.CanalSpinner_7Label = uilabel(app.GridLayout);
            app.CanalSpinner_7Label.Text = 'Canal';
            app.CanalSpinner_7 = uispinner(app.GridLayout);
            app.CanalSpinner_7.Limits = [1 35];
            app.CanalSpinner_7.Value = 7;
            app.CanalSpinner_10Label = uilabel(app.GridLayout);
            app.CanalSpinner_10Label.Text = 'Canal';
            app.CanalSpinner_10 = uispinner(app.GridLayout);
            app.CanalSpinner_10.Limits = [1 35];
            app.CanalSpinner_10.Value = 10;
            app.CanalSpinner_2Label = uilabel(app.GridLayout);
            app.CanalSpinner_2Label.Text = 'Canal';
            app.CanalSpinner_2 = uispinner(app.GridLayout);
            app.CanalSpinner_2.Limits = [1 35];
            app.CanalSpinner_2.Value = 2;
            app.CanalSpinner_5Label = uilabel(app.GridLayout);
            app.CanalSpinner_5Label.Text = 'Canal';
            app.CanalSpinner_5 = uispinner(app.GridLayout);
            app.CanalSpinner_5.Limits = [1 35];
            app.CanalSpinner_5.Value = 5;
            app.CanalSpinner_8Label = uilabel(app.GridLayout);
            app.CanalSpinner_8Label.Text = 'Canal';
            app.CanalSpinner_8 = uispinner(app.GridLayout);
            app.CanalSpinner_8.Limits = [1 35];
            app.CanalSpinner_8.Value = 8;
            app.CanalSpinner_11Label = uilabel(app.GridLayout);
            app.CanalSpinner_11Label.Text = 'Canal';
            app.CanalSpinner_11 = uispinner(app.GridLayout);
            app.CanalSpinner_11.Limits = [1 35];
            app.CanalSpinner_11.Value = 11;
            app.CanalSpinner_3Label = uilabel(app.GridLayout);
            app.CanalSpinner_3Label.Text = 'Canal';
            app.CanalSpinner_3 = uispinner(app.GridLayout);
            app.CanalSpinner_3.Limits = [1 35];
            app.CanalSpinner_3.Value = 3;
            app.CanalSpinner_6Label = uilabel(app.GridLayout);
            app.CanalSpinner_6Label.Text = 'Canal';
            app.CanalSpinner_6 = uispinner(app.GridLayout);
            app.CanalSpinner_6.Limits = [1 35];
            app.CanalSpinner_6.Value = 6;
            app.CanalSpinner_9Label = uilabel(app.GridLayout);
            app.CanalSpinner_9Label.Text = 'Canal';
            app.CanalSpinner_9 = uispinner(app.GridLayout);
            app.CanalSpinner_9.Limits = [1 35];
            app.CanalSpinner_9.Value = 9;
            app.CanalSpinner_12Label = uilabel(app.GridLayout);
            app.CanalSpinner_12Label.Text = 'Canal';
            app.CanalSpinner_12 = uispinner(app.GridLayout);
            app.CanalSpinner_12.Limits = [1 35];
            app.CanalSpinner_12.Value = 12;
            app.TiempoMinsEditFieldLabel = uilabel(app.GridLayout);
            app.TiempoMinsEditFieldLabel.Text = 'Tiempo Min (s)';
            app.TiempoMinsEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempoMaxsEditFieldLabel = uilabel(app.GridLayout);
            app.TiempoMaxsEditFieldLabel.Text = 'Tiempo Max (s)';
            app.TiempoMaxsEditField = uieditfield(app.GridLayout, 'numeric');
            app.TiempoMaxsEditField.Value = 10;
            app.VentanaMultigrficoLabel = uilabel(app.GridLayout);
            app.VentanaMultigrficoLabel.Text = 'Ventana Multigráfico';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multigrafico(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end