classdef multigrafico_vertical < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MultigraficoVerticalUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        CanalSpinner_16               matlab.ui.control.Spinner
        CanalSpinner_16Label          matlab.ui.control.Label
        CanalSpinner_15               matlab.ui.control.Spinner
        CanalSpinner_15Label          matlab.ui.control.Label
        CanalSpinner_14               matlab.ui.control.Spinner
        CanalSpinner_14Label          matlab.ui.control.Label
        CanalSpinner_13               matlab.ui.control.Spinner
        CanalSpinner_13Label          matlab.ui.control.Label
        SiguientescanalesButton       matlab.ui.control.Button
        VisualizarButton              matlab.ui.control.Button
        TiempoMaxsEditField           matlab.ui.control.NumericEditField
        TiempoMaxsEditFieldLabel      matlab.ui.control.Label
        TiempoMinsEditField           matlab.ui.control.NumericEditField
        TiempoMinsEditFieldLabel      matlab.ui.control.Label
        CanalSpinner_12               matlab.ui.control.Spinner
        CanalSpinner_12Label          matlab.ui.control.Label
        CanalSpinner_11               matlab.ui.control.Spinner
        CanalSpinner_11Label          matlab.ui.control.Label
        CanalSpinner_10               matlab.ui.control.Spinner
        CanalSpinner_10Label          matlab.ui.control.Label
        CanalSpinner_9                matlab.ui.control.Spinner
        CanalSpinner_9Label           matlab.ui.control.Label
        CanalSpinner_8                matlab.ui.control.Spinner
        CanalSpinner_8Label           matlab.ui.control.Label
        CanalSpinner_7                matlab.ui.control.Spinner
        CanalSpinner_7Label           matlab.ui.control.Label
        CanalSpinner_6                matlab.ui.control.Spinner
        CanalSpinner_6Label           matlab.ui.control.Label
        CanalSpinner_4                matlab.ui.control.Spinner
        CanalSpinner_4Label           matlab.ui.control.Label
        CanalSpinner_3                matlab.ui.control.Spinner
        CanalSpinner_3Label           matlab.ui.control.Label
        CanalSpinner_5                matlab.ui.control.Spinner
        CanalSpinner_5Label           matlab.ui.control.Label
        CanalSpinner_2                matlab.ui.control.Spinner
        CanalSpinner_2Label           matlab.ui.control.Label
        CanalSpinner_1                matlab.ui.control.Spinner
        CanalSpinnerLabel             matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        VentanaMultigrficoLabel       matlab.ui.control.Label
        UIAxes_1                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp % Aplicacion principal
        record % Datos de las señales
        no_canales % Numero de canales en record
        Ts % Periodo de muestreo
        tiempo % Vector de tiempo para graficar
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
             app.CallingApp = mainapp;
             app.record = app.CallingApp.datos;
             app.no_canales = size(app.record, 1);
             app.Ts = 1/str2double(app.CallingApp.freq); %Periodo de muestreo
             app.tiempo = (1:length(app.record))*app.Ts;
          

             app.CanalSpinner_1.Limits=[1,app.no_canales];
             app.CanalSpinner_2.Limits=[1,app.no_canales];
             app.CanalSpinner_3.Limits=[1,app.no_canales];
             app.CanalSpinner_4.Limits=[1,app.no_canales];
             app.CanalSpinner_5.Limits=[1,app.no_canales];
             app.CanalSpinner_6.Limits=[1,app.no_canales];
             app.CanalSpinner_7.Limits=[1,app.no_canales];
             app.CanalSpinner_8.Limits=[1,app.no_canales];
             app.CanalSpinner_9.Limits=[1,app.no_canales];
             app.CanalSpinner_10.Limits=[1,app.no_canales];
             app.CanalSpinner_11.Limits=[1,app.no_canales];
             app.CanalSpinner_12.Limits=[1,app.no_canales];


              %Graficar canal y tiempo seleccionado
            app.UIAxes_1.YLim=[-100,1600];
            app.UIAxes_1.YTick = [0,100, 200,300,400,500,600,700,800,900,1000,1100,1200,1300,1400,1500];
            app.UIAxes_1.YTickLabel{1}=sprintf("Canal %d",app.CanalSpinner_1.Value);
            app.UIAxes_1.YTickLabel{2}=sprintf("Canal %d",app.CanalSpinner_2.Value);
            app.UIAxes_1.YTickLabel{3}=sprintf("Canal %d",app.CanalSpinner_3.Value);
            app.UIAxes_1.YTickLabel{4}=sprintf("Canal %d",app.CanalSpinner_4.Value);
            app.UIAxes_1.YTickLabel{5}=sprintf("Canal %d",app.CanalSpinner_5.Value);
            app.UIAxes_1.YTickLabel{6}=sprintf("Canal %d",app.CanalSpinner_6.Value);
            app.UIAxes_1.YTickLabel{7}=sprintf("Canal %d",app.CanalSpinner_7.Value);
            app.UIAxes_1.YTickLabel{8}=sprintf("Canal %d",app.CanalSpinner_8.Value);
            app.UIAxes_1.YTickLabel{9}=sprintf("Canal %d",app.CanalSpinner_9.Value);
            app.UIAxes_1.YTickLabel{10}=sprintf("Canal %d",app.CanalSpinner_10.Value);
            app.UIAxes_1.YTickLabel{11}=sprintf("Canal %d",app.CanalSpinner_11.Value);
            app.UIAxes_1.YTickLabel{12}=sprintf("Canal %d",app.CanalSpinner_12.Value);
            app.UIAxes_1.YTickLabel{13}=sprintf("Canal %d",app.CanalSpinner_13.Value);
            app.UIAxes_1.YTickLabel{14}=sprintf("Canal %d",app.CanalSpinner_14.Value);
            app.UIAxes_1.YTickLabel{15}=sprintf("Canal %d",app.CanalSpinner_15.Value);
            app.UIAxes_1.YTickLabel{16}=sprintf("Canal %d",app.CanalSpinner_16.Value);
          
            %Se desfasan verticalmente las señales
            app.UIAxes_1.XLim=[app.CallingApp.indexinf, app.CallingApp.indexsup];
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_1.Value,:)+0));
            hold(app.UIAxes_1, 'on' );%
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_2.Value,:)+100));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_3.Value,:)+200));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_4.Value,:)+300));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_5.Value,:)+400));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_6.Value,:)+500));  
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_7.Value,:)+600));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_8.Value,:)+700));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_9.Value,:)+800));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_10.Value,:)+900));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_11.Value,:)+1000));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_12.Value,:)+1100));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_13.Value,:)+1200));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_14.Value,:)+1300));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_15.Value,:)+1400));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_16.Value,:)+1500));
            hold(app.UIAxes_1, 'off' );
        end

        % Button pushed function: VisualizarButton
        function VisualizarButtonPushed(app, event)
  
        %Generar el vector tiempo
        app.tiempo = (0:length(app.record)-1)*app.Ts;
        tmin = app.TiempoMinsEditField.Value;
        tmax = app.TiempoMaxsEditField.Value;
        %Acotar gráfica según tamaño del archivo EDF
        if tmax>length(app.record)*app.Ts
            tmax = length(app.record)*app.Ts;
            app.TiempoMaxsEditField.Value = round(length(app.record)*app.Ts);
        elseif mod(tmax,1)~=0 || mod(tmin,1)~=0 %si es decimal
            tmax = round(tmax);
            tmin = round(tmin);
        % elseif tmin==0
        %     app.TiempoMinsEditField.Value = 1;
        %     tmin=1;
        end
        
            app.UIAxes_1.YLim=[-100,1600];
            app.UIAxes_1.YTick = [0,100, 200,300,400,500,600,700,800,900,1000,1100,1200,1300,1400,1500];
            app.UIAxes_1.YTickLabel{1}=sprintf("Canal %d",app.CanalSpinner_1.Value);
            app.UIAxes_1.YTickLabel{2}=sprintf("Canal %d",app.CanalSpinner_2.Value);
            app.UIAxes_1.YTickLabel{3}=sprintf("Canal %d",app.CanalSpinner_3.Value);
            app.UIAxes_1.YTickLabel{4}=sprintf("Canal %d",app.CanalSpinner_4.Value);
            app.UIAxes_1.YTickLabel{5}=sprintf("Canal %d",app.CanalSpinner_5.Value);
            app.UIAxes_1.YTickLabel{6}=sprintf("Canal %d",app.CanalSpinner_6.Value);
            app.UIAxes_1.YTickLabel{7}=sprintf("Canal %d",app.CanalSpinner_7.Value);
            app.UIAxes_1.YTickLabel{8}=sprintf("Canal %d",app.CanalSpinner_8.Value);
            app.UIAxes_1.YTickLabel{9}=sprintf("Canal %d",app.CanalSpinner_9.Value);
            app.UIAxes_1.YTickLabel{10}=sprintf("Canal %d",app.CanalSpinner_10.Value);
            app.UIAxes_1.YTickLabel{11}=sprintf("Canal %d",app.CanalSpinner_11.Value);
            app.UIAxes_1.YTickLabel{12}=sprintf("Canal %d",app.CanalSpinner_12.Value);
            app.UIAxes_1.YTickLabel{13}=sprintf("Canal %d",app.CanalSpinner_13.Value);
            app.UIAxes_1.YTickLabel{14}=sprintf("Canal %d",app.CanalSpinner_14.Value);
            app.UIAxes_1.YTickLabel{15}=sprintf("Canal %d",app.CanalSpinner_15.Value);
            app.UIAxes_1.YTickLabel{16}=sprintf("Canal %d",app.CanalSpinner_16.Value);
           
            app.UIAxes_1.XLim=[tmin,tmax];
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_1.Value,:)+0));
            hold(app.UIAxes_1, 'on' );%Desfase de las señales verticalmente
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_2.Value,:)+100));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_3.Value,:)+200));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_4.Value,:)+300));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_5.Value,:)+400));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_6.Value,:)+500));  
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_7.Value,:)+600));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_8.Value,:)+700));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_9.Value,:)+800));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_10.Value,:)+900));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_11.Value,:)+1000));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_12.Value,:)+1100));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_13.Value,:)+1200));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_14.Value,:)+1300));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_15.Value,:)+1400));
            plot(app.UIAxes_1,app.tiempo,(app.record(app.CanalSpinner_16.Value,:)+1500));
            hold(app.UIAxes_1, 'off' );
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MultigraficoVerticalUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {715, 715};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {156, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MultigraficoVerticalUIFigure = uifigure('Visible', 'off');
            app.MultigraficoVerticalUIFigure.Name = 'Multigrafico Vertical';
            app.MultigraficoVerticalUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MultigraficoVerticalUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CanalSpinnerLabel = uilabel(app.LeftPanel);
            app.CanalSpinnerLabel.Text = 'Canal';
            app.CanalSpinner_1 = uispinner(app.LeftPanel);
            app.CanalSpinner_1.Limits = [1 35];
            app.CanalSpinner_1.Value = 1;
            app.CanalSpinner_2Label = uilabel(app.LeftPanel);
            app.CanalSpinner_2Label.Text = 'Canal';
            app.CanalSpinner_2 = uispinner(app.LeftPanel);
            app.CanalSpinner_2.Limits = [1 35];
            app.CanalSpinner_2.Value = 2;
            app.CanalSpinner_5Label = uilabel(app.LeftPanel);
            app.CanalSpinner_5Label.Text = 'Canal';
            app.CanalSpinner_5 = uispinner(app.LeftPanel);
            app.CanalSpinner_5.Limits = [1 35];
            app.CanalSpinner_5.Value = 5;
            app.CanalSpinner_3Label = uilabel(app.LeftPanel);
            app.CanalSpinner_3Label.Text = 'Canal';
            app.CanalSpinner_3 = uispinner(app.LeftPanel);
            app.CanalSpinner_3.Limits = [1 35];
            app.CanalSpinner_3.Value = 3;
            app.CanalSpinner_4Label = uilabel(app.LeftPanel);
            app.CanalSpinner_4Label.Text = 'Canal';
            app.CanalSpinner_4 = uispinner(app.LeftPanel);
            app.CanalSpinner_4.Limits = [1 35];
            app.CanalSpinner_4.Value = 4;
            app.CanalSpinner_6Label = uilabel(app.LeftPanel);
            app.CanalSpinner_6Label.Text = 'Canal';
            app.CanalSpinner_6 = uispinner(app.LeftPanel);
            app.CanalSpinner_6.Limits = [1 35];
            app.CanalSpinner_6.Value = 6;
            app.CanalSpinner_7Label = uilabel(app.LeftPanel);
            app.CanalSpinner_7Label.Text = 'Canal';
            app.CanalSpinner_7 = uispinner(app.LeftPanel);
            app.CanalSpinner_7.Limits = [1 35];
            app.CanalSpinner_7.Value = 7;
            app.CanalSpinner_8Label = uilabel(app.LeftPanel);
            app.CanalSpinner_8Label.Text = 'Canal';
            app.CanalSpinner_8 = uispinner(app.LeftPanel);
            app.CanalSpinner_8.Limits = [1 35];
            app.CanalSpinner_8.Value = 8;
            app.CanalSpinner_9Label = uilabel(app.LeftPanel);
            app.CanalSpinner_9Label.Text = 'Canal';
            app.CanalSpinner_9 = uispinner(app.LeftPanel);
            app.CanalSpinner_9.Limits = [1 35];
            app.CanalSpinner_9.Value = 9;
            app.CanalSpinner_10Label = uilabel(app.LeftPanel);
            app.CanalSpinner_10Label.Text = 'Canal';
            app.CanalSpinner_10 = uispinner(app.LeftPanel);
            app.CanalSpinner_10.Limits = [1 35];
            app.CanalSpinner_10.Value = 10;
            app.CanalSpinner_11Label = uilabel(app.LeftPanel);
            app.CanalSpinner_11Label.Text = 'Canal';
            app.CanalSpinner_11 = uispinner(app.LeftPanel);
            app.CanalSpinner_11.Limits = [1 35];
            app.CanalSpinner_11.Value = 11;
            app.CanalSpinner_12Label = uilabel(app.LeftPanel);
            app.CanalSpinner_12Label.Text = 'Canal';
            app.CanalSpinner_12 = uispinner(app.LeftPanel);
            app.CanalSpinner_12.Limits = [1 35];
            app.CanalSpinner_12.Value = 12;
            app.TiempoMinsEditFieldLabel = uilabel(app.LeftPanel);
            app.TiempoMinsEditFieldLabel.Text = 'Tiempo Min (s)';
            app.TiempoMinsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TiempoMaxsEditFieldLabel = uilabel(app.LeftPanel);
            app.TiempoMaxsEditFieldLabel.Text = 'Tiempo Max (s)';
            app.TiempoMaxsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TiempoMaxsEditField.Value = 10;
            app.VisualizarButton = uibutton(app.LeftPanel, 'push');
            app.VisualizarButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarButtonPushed, true);
            app.VisualizarButton.Text = 'Visualizar';
            app.SiguientescanalesButton = uibutton(app.LeftPanel, 'push');
            app.SiguientescanalesButton.Text = 'Siguientes canales';
            app.CanalSpinner_13Label = uilabel(app.LeftPanel);
            app.CanalSpinner_13Label.Text = 'Canal';
            app.CanalSpinner_13 = uispinner(app.LeftPanel);
            app.CanalSpinner_13.Limits = [1 35];
            app.CanalSpinner_13.Value = 13;
            app.CanalSpinner_14Label = uilabel(app.LeftPanel);
            app.CanalSpinner_14Label.Text = 'Canal';
            app.CanalSpinner_14 = uispinner(app.LeftPanel);
            app.CanalSpinner_14.Limits = [1 35];
            app.CanalSpinner_14.Value = 15;
            app.CanalSpinner_15Label = uilabel(app.LeftPanel);
            app.CanalSpinner_15Label.Text = 'Canal';
            app.CanalSpinner_15 = uispinner(app.LeftPanel);
            app.CanalSpinner_15.Limits = [1 35];
            app.CanalSpinner_15.Value = 14;
            app.CanalSpinner_16Label = uilabel(app.LeftPanel);
            app.CanalSpinner_16Label.Text = 'Canal';
            app.CanalSpinner_16 = uispinner(app.LeftPanel);
            app.CanalSpinner_16.Limits = [1 35];
            app.CanalSpinner_16.Value = 16;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes_1 = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes_1, 'Tiempo (s)')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_1.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 600];
            app.UIAxes_1.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '600'};
            app.VentanaMultigrficoLabel = uilabel(app.GridLayout2);
            app.VentanaMultigrficoLabel.Text = 'Ventana Multigráfico';
            % Show the figure after all components are created
            app.MultigraficoVerticalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multigrafico_vertical(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MultigraficoVerticalUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MultigraficoVerticalUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MultigraficoVerticalUIFigure)
        end
    end
end