classdef nuevou < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NuevousuarioUIFigure           matlab.ui.Figure
        RolDropDown                    matlab.ui.control.DropDown
        RolDropDownLabel               matlab.ui.control.Label
        CorreoEditField                matlab.ui.control.EditField
        CorreoEditFieldLabel           matlab.ui.control.Label
        AnotacionesautomticasCheckBox  matlab.ui.control.CheckBox
        ClasificadoresCheckBox         matlab.ui.control.CheckBox
        HerramientadeanlisisCheckBox   matlab.ui.control.CheckBox
        Pass2EditField                 matlab.ui.control.EditField
        Pass2EditFieldLabel            matlab.ui.control.Label
        Pass1EditField                 matlab.ui.control.EditField
        Pass1EditFieldLabel            matlab.ui.control.Label
        RolEditField                   matlab.ui.control.EditField
        RolEditFieldLabel              matlab.ui.control.Label
        AnalizarpruebasCheckBox        matlab.ui.control.CheckBox
        ConsultadepacientesCheckBox    matlab.ui.control.CheckBox
        IngresodepacientesCheckBox     matlab.ui.control.CheckBox
        ConsultadeinformacinconfidencialCheckBox  matlab.ui.control.CheckBox
        PermisosLabel                  matlab.ui.control.Label
        AdministracindeusuariosCheckBox  matlab.ui.control.CheckBox
        ConfirmarcontraseaEditField    matlab.ui.control.EditField
        ConfirmarcontraseaEditFieldLabel  matlab.ui.control.Label
        CancelarButton                 matlab.ui.control.Button
        CrearButton                    matlab.ui.control.Button
        CrearusuarionuevoLabel         matlab.ui.control.Label
        ContraseaEditField             matlab.ui.control.EditField
        ContraseaEditFieldLabel        matlab.ui.control.Label
        UsuarioEditField               matlab.ui.control.EditField
        UsuarioEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Description
        Conexion;
    end
    
    methods (Access = private)
        
        function entero = toentero(~, logico)
            if (logico)
                entero = 1;
            else
                entero = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            movegui(app.NuevousuarioUIFigure,"center");
            app.CallingApp = mainapp;
            app.Conexion = con;
            
            app.RolEditField.Enable =  'off';
            app.RolEditFieldLabel.Enable = 'off';
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            ActivarNuevoU(app.CallingApp);
            delete(app)
        end

        % Button pushed function: CrearButton
        function CrearButtonPushed(app, event)
            campos1 = false;
            campos2 = false;
            campos3 = false;
            %%Verificar campos requeridos 
            if (strcmp(app.UsuarioEditField.Value,'') || strcmp(app.ContraseaEditField.Value,'') || strcmp(app.ConfirmarcontraseaEditField.Value,'') || strcmp(app.RolEditField.Value,'') || strcmp(app.CorreoEditField.Value,''))
                fig = app.NuevousuarioUIFigure;
                message = ['Error. Campos: Usuario, Corre, Contraseña, Confirmar contraseña ' ...
                            'y Rol son requeridos.'];
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
            else
                campos1 = true; % Si se llenaron todos los valores, se acepta.
            end

            %%Verificar permisos
            if (~app.AdministracindeusuariosCheckBox.Value && ~app.AnalizarpruebasCheckBox.Value && ~app.IngresodepacientesCheckBox.Value && ~app.ConsultadepacientesCheckBox.Value && ~app.ConsultadeinformacinconfidencialCheckBox.Value && ~app.HerramientadeanlisisCheckBox.Value && ~app.ClasificadoresCheckBox.Value && ~app.AnotacionesautomticasCheckBox.Value)
                fig = app.NuevousuarioUIFigure;
                message = 'Error. Por lo menos 1 permiso debe ser seleccionado. ';
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas  
            else
                campos2 = true; %Si se selecciono uno o mas permisos se acepta
            end

            %%Verificar que contrasenas sean iguales
            if (strcmp(app.Pass1EditField.Value, app.Pass2EditField.Value))
                campos3 = true; %Si son iguales, se acepta.
            else
                fig = app.NuevousuarioUIFigure;
                message = 'Error. Contraseña y Confirmar contraseña no coinciden.';
                uialert(fig ,message,'Error','Icon','error'); %Cambiamos a un anuncio en pantalla, evitamos mas ventanas abiertas
            end
            
            %% Al cumplir las 3 condiciones, se crea el usuario en la Base de datos
            if (campos1 && campos2 && campos3)
                conn = app.Conexion;
                %crear usuario
                crear = ['CREATE USER IF NOT EXISTS ' app.UsuarioEditField.Value];
                execute(conn, crear);
                %asignar contraseña
                crearpass = strcat("ALTER USER ", app.UsuarioEditField.Value);
                crearpass = strcat(crearpass, " IDENTIFIED BY '");
                crearpass = strcat(crearpass, app.Pass1EditField.Value);
                crearpass = strcat(crearpass, "'");
                execute(conn, crearpass);

                %Asignar accesos utilizamos nueva instrucción sqlwrite, en
                %vez de insert.
                nuevo={app.UsuarioEditField.Value, app.CorreoEditField.Value, app.RolEditField.Value, toentero(app,app.AdministracindeusuariosCheckBox.Value), toentero(app,app.AnalizarpruebasCheckBox.Value), toentero(app,app.IngresodepacientesCheckBox.Value), toentero(app,app.ConsultadepacientesCheckBox.Value), toentero(app,app.ConsultadeinformacinconfidencialCheckBox.Value), 1, toentero(app,app.HerramientadeanlisisCheckBox.Value), toentero(app,app.ClasificadoresCheckBox.Value), toentero(app,app.AnotacionesautomticasCheckBox.Value),0};
                datawrite = cell2table(nuevo);
                datawrite.Properties.VariableNames=["usuario","correo","tipo","admin_usuarios","analizar_prueba","ingreso_pacientes","consulta_pacientes","consulta_confidencial", "primer_login", "anotaciones_automaticas", "crear_algoritmo", "anotaciones_automaticas2","recuperar_contrasena"];
                sqlwrite(conn,'humana.usuarios',datawrite);
                %Nuevo formato para trabajar sqlwrite

           
                %Asignar permisos a nivel de base de datos
                %consultar tabla de acceso a todos los usuarios creados sin
                %importar los permisos seleccionados
                permiso = ['GRANT SELECT ON humana.usuarios to ' app.UsuarioEditField.Value];
                execute(conn, permiso);
                %permitir a todos los usuarios cambiar el estado de la bandera
                %primer_login en humana.usuarios
                permiso = ['GRANT UPDATE (primer_login) ON humana.usuarios to ' app.UsuarioEditField.Value];
                execute(conn, permiso);
                %Asisgnar permisos seleccionados
                %Administrar usuarios
                if (app.AdministracindeusuariosCheckBox.Value)
                    permiso1 = ['GRANT ALL PRIVILEGES ON humana.usuarios TO ' app.UsuarioEditField.Value ' WITH GRANT OPTION'];
                    execute(conn, permiso1);
                    permiso1_1 = ['GRANT SELECT ON mysql.user TO ' app.UsuarioEditField.Value ' WITH GRANT OPTION'];
                    execute(conn, permiso1_1);
                    permiso1_2 = ['GRANT CREATE USER ON *.* TO ' app.UsuarioEditField.Value ' WITH GRANT OPTION'];
                    execute(conn, permiso1_2);
                end
                %analizar pruebas
                if (app.AnalizarpruebasCheckBox.Value)
                    permiso2 = ['GRANT SELECT ON humana.pacientes TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2);
                    permiso2_1 = ['GRANT SELECT ON humana.pruebas TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_1);
                    permiso2_2 = ['GRANT SELECT ON humana.pruebas_datos TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_2);
                    permiso2_3 = ['GRANT ALL PRIVILEGES ON humana.modelo TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_3);
                    permiso2_4 = ['GRANT ALL PRIVILEGES ON humana.resultados_modelo TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_4);
                    permiso2_5 = ['GRANT ALL PRIVILEGES ON humana.analisis TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_5);
                    permiso2_6 = ['GRANT ALL PRIVILEGES ON humana.resultados_analisis TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso2_6);
                end
                %Ingresar paciente
                if (app.IngresodepacientesCheckBox.Value)
                    permiso3 = ['GRANT ALL PRIVILEGES ON humana.pacientes TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso3);
                end
                %Consultar pacientes
                if (app.ConsultadepacientesCheckBox.Value)
                    permiso4 = ['GRANT SELECT (id_paciente, sexo, fecha_nacimiento, antecedentes, diagnostico, condicion), UPDATE (id_paciente, sexo, fecha_nacimiento, antecedentes, diagnostico, condicion), SELECT (id_paciente, sexo, fecha_nacimiento, antecedentes, diagnostico, condicion) ON humana.pacientes TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso4);
                    permiso4_1 = ['GRANT ALL PRIVILEGES ON humana.pruebas TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso4_1);
                    permiso4_2 = ['GRANT ALL PRIVILEGES ON humana.pruebas_datos TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso4_2);
                    permiso4_3 = ['GRANT FILE ON *.* TO ' app.UsuarioEditField.Value]; %Permitimos al usuario insertar datos en BULTO
                    execute(conn, permiso4_3);
                end
                %Consultar información confidencial
                if (app.ConsultadeinformacinconfidencialCheckBox.Value)
                    permiso5 = ['GRANT ALL PRIVILEGES ON humana.pacientes TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso5);
                    permiso5_1 = ['GRANT ALL PRIVILEGES ON humana.pruebas TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso5_1);
                    permiso5_2 = ['GRANT ALL PRIVILEGES ON humana.pruebas_datos TO ' app.UsuarioEditField.Value];
                    execute(conn, permiso5_2);
                end
                
                
                actualizart(app.CallingApp);
                delete(app);
            end
        end

        % Value changed function: ConsultadeinformacinconfidencialCheckBox
        function ConsultadeinformacinconfidencialCheckBoxValueChanged(app, event)
            value = app.ConsultadeinformacinconfidencialCheckBox.Value;
            if (~app.ConsultadepacientesCheckBox.Value && value)
                app.ConsultadepacientesCheckBox.Value = value;
            end
        end

        % Value changed function: ConsultadepacientesCheckBox
        function ConsultadepacientesCheckBoxValueChanged(app, event)
            value = app.ConsultadepacientesCheckBox.Value;
            if (app.ConsultadeinformacinconfidencialCheckBox.Value && ~value)
                app.ConsultadeinformacinconfidencialCheckBox.Value = value;
            end
        end

        % Value changing function: ContraseaEditField
        function ContraseaEditFieldValueChanging(app, event)
            temp = "*";
            changingValue = event.Value;
            if strlength(app.Pass1EditField.Value) ~= 0
                if strlength(changingValue) < strlength(app.Pass1EditField.Value)
                    if strlength(changingValue) == 0
                        app.Pass1EditField.Value = "";
                    else
                        app.Pass1EditField.Value = app.Pass1EditField.Value(1:strlength(changingValue));
                    end
                else
                    app.Pass1EditField.Value = strcat(app.Pass1EditField.Value, changingValue(end));
                end
            else
                app.Pass1EditField.Value = changingValue;
            end
            if strlength(changingValue)>0
                for i=1:strlength(changingValue)-1
                    temp = strcat(temp,"*");
                end
                app.ContraseaEditField.Value = temp;
            else
                app.ContraseaEditField.Value = "";
            end
        end

        % Value changing function: ConfirmarcontraseaEditField
        function ConfirmarcontraseaEditFieldValueChanging(app, event)
            temp = "*";
            changingValue = event.Value;
            if strlength(app.Pass2EditField.Value) ~= 0
                if strlength(changingValue) < strlength(app.Pass2EditField.Value)
                    if strlength(changingValue) == 0
                        app.Pass2EditField.Value = "";
                    else
                        app.Pass2EditField.Value = app.Pass2EditField.Value(1:strlength(changingValue));
                    end
                else
                    app.Pass2EditField.Value = strcat(app.Pass2EditField.Value, changingValue(end));
                end
            else
                app.Pass2EditField.Value = changingValue;
            end
            if strlength(changingValue)>0
                for i=1:strlength(changingValue)-1
                    temp = strcat(temp,"*");
                end
                app.ConfirmarcontraseaEditField.Value = temp;
            else
                app.ConfirmarcontraseaEditField.Value = "";
            end
        end

        % Value changed function: HerramientadeanlisisCheckBox
        function HerramientadeanlisisCheckBoxValueChanged(app, event)
            value = app.HerramientadeanlisisCheckBox.Value;
            if value == 0
                app.ClasificadoresCheckBox.Value = 0;
                app.AnotacionesautomticasCheckBox.Value = 0;
            end
        end

        % Value changed function: ClasificadoresCheckBox
        function ClasificadoresCheckBoxValueChanged(app, event)
            value = app.ClasificadoresCheckBox.Value;
            value2 = app.AnotacionesautomticasCheckBox.Value;
            if value == 1
                app.HerramientadeanlisisCheckBox.Value = 1;
            end
            if value == 0 && value2 == 0
                app.HerramientadeanlisisCheckBox.Value = 0;
            end
        end

        % Value changed function: AnotacionesautomticasCheckBox
        function AnotacionesautomticasCheckBoxValueChanged(app, event)
            value = app.AnotacionesautomticasCheckBox.Value;
            value2 = app.ClasificadoresCheckBox.Value;
            if value == 1
                app.HerramientadeanlisisCheckBox.Value = 1;
            end
            if value == 0 && value2 == 0
                app.HerramientadeanlisisCheckBox.Value = 0;
            end
        end

        % Value changed function: RolDropDown
        function RolDropDownValueChanged(app, event)
            value = app.RolDropDown.Value;
            if strcmp(value,'Super User')
                %Bloquear todo
                app.AdministracindeusuariosCheckBox.Enable = 'off';
                app.AnalizarpruebasCheckBox.Enable = 'off';
                app.IngresodepacientesCheckBox.Enable = 'off';
                app.ConsultadepacientesCheckBox.Enable = 'off';
                app.ConsultadeinformacinconfidencialCheckBox.Enable = 'off';
                app.ClasificadoresCheckBox.Enable = 'off';
                app.AnotacionesautomticasCheckBox.Enable = 'off';
                app.RolEditField.Enable = 'off';
                %Asignar estado a checkboxes e input
                app.AdministracindeusuariosCheckBox.Value = 1;
                app.AnalizarpruebasCheckBox.Value = 1;
                app.IngresodepacientesCheckBox.Value = 1;
                app.ConsultadepacientesCheckBox.Value = 1;
                app.ConsultadeinformacinconfidencialCheckBox.Value = 1;
                app.HerramientadeanlisisCheckBox.Value = 1;
                app.ClasificadoresCheckBox.Value = 1;
                app.AnotacionesautomticasCheckBox.Value = 1;
                app.RolEditField.Enable = 'off';
                app.RolEditFieldLabel.Enable = 'off';
                app.RolEditField.Value = 'Super User';
            elseif strcmp(value,'Analista')
                %Bloquear todo
                app.AdministracindeusuariosCheckBox.Enable = 'off';
                app.AnalizarpruebasCheckBox.Enable = 'off';
                app.IngresodepacientesCheckBox.Enable = 'off';
                app.ConsultadepacientesCheckBox.Enable = 'off';
                app.ConsultadeinformacinconfidencialCheckBox.Enable = 'off';
                app.ClasificadoresCheckBox.Enable = 'off';
                app.AnotacionesautomticasCheckBox.Enable = 'off';
                app.RolEditField.Enable = 'off';
                app.RolEditFieldLabel.Enable = 'off';
                %Limpiar todos los checkboxes
                app.AdministracindeusuariosCheckBox.Value = 0;
                app.AnalizarpruebasCheckBox.Value = 0;
                app.IngresodepacientesCheckBox.Value = 0;
                app.ConsultadepacientesCheckBox.Value = 0;
                app.ConsultadeinformacinconfidencialCheckBox.Value = 0;
                app.HerramientadeanlisisCheckBox.Value = 0;
                app.ClasificadoresCheckBox.Value = 0;
                app.AnotacionesautomticasCheckBox.Value = 0;
                %Asignar estado a checkboxes e input
                app.AnalizarpruebasCheckBox.Value = 1;
                app.HerramientadeanlisisCheckBox.Value = 1;
                app.ClasificadoresCheckBox.Value = 1;
                app.AnotacionesautomticasCheckBox.Value = 1;
                app.RolEditField.Enable = 'off';
                app.RolEditField.Value = 'Analista';
            elseif strcmp(value,'Personalizado')
                %Desbloquear todos los checkboxes
                app.AdministracindeusuariosCheckBox.Enable = 'on';
                app.AnalizarpruebasCheckBox.Enable = 'on';
                app.IngresodepacientesCheckBox.Enable = 'on';
                app.ConsultadepacientesCheckBox.Enable = 'on';
                app.ConsultadeinformacinconfidencialCheckBox.Enable = 'on';
                app.ClasificadoresCheckBox.Enable = 'on';
                app.AnotacionesautomticasCheckBox.Enable = 'on';
                app.RolEditField.Enable = 'on';
%                 app.RolEditFieldLabel.Enable = 'on';
                %Limpiar todos los checkboxes e intput
                app.AdministracindeusuariosCheckBox.Value = 0;
                app.AnalizarpruebasCheckBox.Value = 0;
                app.IngresodepacientesCheckBox.Value = 0;
                app.ConsultadepacientesCheckBox.Value = 0;
                app.ConsultadeinformacinconfidencialCheckBox.Value = 0;
                app.HerramientadeanlisisCheckBox.Value = 0;
                app.ClasificadoresCheckBox.Value = 0;
                app.AnotacionesautomticasCheckBox.Value = 0;
                app.RolEditField.Value = '';
            else
                %Limpiar todos los checkboxes e input
                app.AdministracindeusuariosCheckBox.Value = 0;
                app.AnalizarpruebasCheckBox.Value = 0;
                app.IngresodepacientesCheckBox.Value = 0;
                app.ConsultadepacientesCheckBox.Value = 0;
                app.ConsultadeinformacinconfidencialCheckBox.Value = 0;
                app.HerramientadeanlisisCheckBox.Value = 0;
                app.ClasificadoresCheckBox.Value = 0;
                app.AnotacionesautomticasCheckBox.Value = 0;
                app.RolEditField.Value = '';
                app.AdministracindeusuariosCheckBox.Enable = 'off';
                app.AnalizarpruebasCheckBox.Enable = 'off';
                app.IngresodepacientesCheckBox.Enable = 'off';
                app.ConsultadepacientesCheckBox.Enable = 'off';
                app.ConsultadeinformacinconfidencialCheckBox.Enable = 'off';
                app.ClasificadoresCheckBox.Enable = 'off';
                app.AnotacionesautomticasCheckBox.Enable = 'off';
                app.RolEditField.Enable = 'off';
                app.RolEditFieldLabel.Enable = 'off';
                app.RolEditField.Value = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NuevousuarioUIFigure = uifigure('Visible', 'off');
            app.NuevousuarioUIFigure.Name = 'Nuevo usuario';
            app.NuevousuarioUIFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.UsuarioEditFieldLabel = uilabel(app.NuevousuarioUIFigure);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.NuevousuarioUIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.NuevousuarioUIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.NuevousuarioUIFigure, 'text');
            app.ContraseaEditField.ValueChangingFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanging, true);
            app.CrearusuarionuevoLabel = uilabel(app.NuevousuarioUIFigure);
            app.CrearusuarionuevoLabel.Text = 'Crear usuario nuevo';
            app.CrearButton = uibutton(app.NuevousuarioUIFigure, 'push');
            app.CrearButton.ButtonPushedFcn = createCallbackFcn(app, @CrearButtonPushed, true);
            app.CrearButton.Text = 'Crear';
            app.CancelarButton = uibutton(app.NuevousuarioUIFigure, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
            app.ConfirmarcontraseaEditFieldLabel = uilabel(app.NuevousuarioUIFigure);
            app.ConfirmarcontraseaEditFieldLabel.Text = 'Confirmar contraseña';
            app.ConfirmarcontraseaEditField = uieditfield(app.NuevousuarioUIFigure, 'text');
            app.ConfirmarcontraseaEditField.ValueChangingFcn = createCallbackFcn(app, @ConfirmarcontraseaEditFieldValueChanging, true);
            app.AdministracindeusuariosCheckBox = uicheckbox(app.NuevousuarioUIFigure);
            app.AdministracindeusuariosCheckBox.Enable = 'off';
            app.AdministracindeusuariosCheckBox.Text = 'Administración de usuarios';
            app.PermisosLabel = uilabel(app.NuevousuarioUIFigure);
            app.PermisosLabel.Text = 'Permisos:';
            app.ConsultadeinformacinconfidencialCheckBox = uicheckbox(app.NuevousuarioUIFigure);
            app.ConsultadeinformacinconfidencialCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsultadeinformacinconfidencialCheckBoxValueChanged, true);
            app.ConsultadeinformacinconfidencialCheckBox.Enable = 'off';
            app.ConsultadeinformacinconfidencialCheckBox.Text = 'Consulta de información confidencial';
            app.IngresodepacientesCheckBox = uicheckbox(app.NuevousuarioUIFigure);
            app.IngresodepacientesCheckBox.Enable = 'off';
            app.IngresodepacientesCheckBox.Text = 'Ingreso de pacientes';
            app.ConsultadepacientesCheckBox = uicheckbox(app.NuevousuarioUIFigure);
            app.ConsultadepacientesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsultadepacientesCheckBoxValueChanged, true);
            app.ConsultadepacientesCheckBox.Enable = 'off';
            app.ConsultadepacientesCheckBox.Text = 'Consulta de pacientes';
            app.AnalizarpruebasCheckBox = uicheckbox(app.NuevousuarioUIFigure);
            app.AnalizarpruebasCheckBox.Enable = 'off';
            app.AnalizarpruebasCheckBox.Text = 'Analizar pruebas';
            app.RolEditFieldLabel = uilabel(app.NuevousuarioUIFigure);
            app.RolEditFieldLabel.Visible = 'off';
            app.RolEditFieldLabel.Text = 'Rol';
            app.RolEditField = uieditfield(app.NuevousuarioUIFigure, 'text');
            app.Pass1EditFieldLabel = uilabel(app.NuevousuarioUIFigure);
            app.Pass1EditFieldLabel.Enable = 'off';
            app.Pass1EditFieldLabel.Visible = 'off';
            app.Pass1EditFieldLabel.Text = 'Pass1';
            app.Pass1EditField = uieditfield(app.NuevousuarioUIFigure, 'text');
            app.Pass1EditField.Editable = 'off';
            app.Pass1EditField.Enable = 'off';
            app.Pass1EditField.Visible = 'off';
            app.Pass2EditFieldLabel = uilabel(app.NuevousuarioUIFigure);
            app.Pass2EditFieldLabel.Enable = 'off';
            app.Pass2EditFieldLabel.Visible = 'off';
            app.Pass2EditFieldLabel.Text = 'Pass2';
            app.Pass2EditField = uieditfield(app.NuevousuarioUIFigure, 'text');
            app.Pass2EditField.Editable = 'off';
            app.Pass2EditField.Enable = 'off';
            app.Pass2EditField.Visible = 'off';
            app.HerramientadeanlisisCheckBox = uicheckbox(app.NuevousuarioUIFigure);
            app.HerramientadeanlisisCheckBox.ValueChangedFcn = createCallbackFcn(app, @HerramientadeanlisisCheckBoxValueChanged, true);
            app.HerramientadeanlisisCheckBox.Enable = 'off';
            app.HerramientadeanlisisCheckBox.Text = 'Herramienta de análisis';
            app.ClasificadoresCheckBox = uicheckbox(app.NuevousuarioUIFigure);
            app.ClasificadoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClasificadoresCheckBoxValueChanged, true);
            app.ClasificadoresCheckBox.Enable = 'off';
            app.ClasificadoresCheckBox.Text = 'Clasificadores';
            app.AnotacionesautomticasCheckBox = uicheckbox(app.NuevousuarioUIFigure);
            app.AnotacionesautomticasCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnotacionesautomticasCheckBoxValueChanged, true);
            app.AnotacionesautomticasCheckBox.Enable = 'off';
            app.AnotacionesautomticasCheckBox.Text = 'Anotaciones automáticas';
            app.CorreoEditFieldLabel = uilabel(app.NuevousuarioUIFigure);
            app.CorreoEditFieldLabel.Text = 'Correo';
            app.CorreoEditField = uieditfield(app.NuevousuarioUIFigure, 'text');
            app.RolDropDownLabel = uilabel(app.NuevousuarioUIFigure);
            app.RolDropDownLabel.Text = 'Rol';
            app.RolDropDown = uidropdown(app.NuevousuarioUIFigure);
            app.RolDropDown.Items = {'Seleccionar', 'Super User', 'Analista', 'Personalizado'};
            app.RolDropDown.ValueChangedFcn = createCallbackFcn(app, @RolDropDownValueChanged, true);
            app.RolDropDown.Value = 'Seleccionar';
            % Show the figure after all components are created
            app.NuevousuarioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nuevou(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NuevousuarioUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.NuevousuarioUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NuevousuarioUIFigure)
        end
    end
end