classdef principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Principal                     matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PacientesTab                  matlab.ui.container.Tab
        ConsultarButton               matlab.ui.control.Button
        AgregarnuevopacienteButton    matlab.ui.control.Button
        AnlisisTab                    matlab.ui.container.Tab
        AnotacionesFlexButton         matlab.ui.control.Button
        AnlisisdeestudioButton        matlab.ui.control.Button
        AnotacionesautomaticasButton  matlab.ui.control.Button
        ModelosTab                    matlab.ui.container.Tab
        ClasificadoresButton          matlab.ui.control.Button
        DBTab                         matlab.ui.container.Tab
        ContraseaDBEditField          matlab.ui.control.EditField
        ConstraseaLabel               matlab.ui.control.Label
        UsuariorootLabel              matlab.ui.control.Label
        RegenerarBaseButton           matlab.ui.control.Button
        recuperarc                    matlab.ui.control.Button
        Conf                          matlab.ui.control.Button
        AdministrarusuariosButton     matlab.ui.control.Button
        DesconectarButton             matlab.ui.control.Button
        ConectarButton                matlab.ui.control.StateButton
        Image                         matlab.ui.control.Image
        EPILEPTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Dialogo;
        Conexion;
        AgregarPaciente;
        RealizarConsulta;
        AnalizarPrueba;
        Administrar;
        user;
        Configurar;
        pos = 0;
        CrearAlgoritmo;
        AnotacionesAutomaticas;
        Recon;
        VentanaAFlexibles; % Objeto para guardar handle para ventana de anotaciones flexibles -JP24
    end
    
    methods (Access = public)
        
        function activar(app, connect, admin) % Nos permite la conexión con el servidor SQL
            app.Conexion = connect;
            app.user = admin; 
            query = ['SELECT * ' ...
                'FROM humana.usuarios WHERE usuario= "' ...
                admin...
                '"'];
            data = fetch(app.Conexion,query);
            if (data.admin_usuarios == 1)
                app.AdministrarusuariosButton.Enable = 'on';
            end
            if (data.analizar_prueba == 1)
                app.AnlisisdeestudioButton.Enable = 'on';
            end
            if (data.ingreso_pacientes == 1)
                app.AgregarnuevopacienteButton.Enable = 'on';
            end
            if (data.consulta_pacientes == 1)
                app.ConsultarButton.Enable = 'on';
            end
            
            
            if (data.crear_algoritmo == 1)
                app.ClasificadoresButton.Enable = 'on';
            end
            
            if (data.anotaciones_automaticas2 == 1)
                app.AnotacionesautomaticasButton.Enable = 'on';
            end
            
            if (data.recuperar_contrasena == 1)
                app.recuperarc.Enable = 'on';
            end

            app.DesconectarButton.Enable = 'on';
            if (~strcmp(app.user,'resetpass'))
                app.Conf.Enable ="on";
            end
            
            %mostrar ventana para cambio de contraseña si es primer inicio
            %de sesión o nunca se ha cambiado la contraseña
            if (data.primer_login == 1)
                app.Configurar = configuracion(app, app.Conexion, app.user, true);
            end
            
            app.AnotacionesFlexButton.Enable = "on"; % Una vez iniciada la sesión, se habilitan anotaciones flexibles -JP24

            fig = app.Principal;
            message = 'Conexión realizada correctamente. ';
            uialert(fig ,message,'Aviso','Icon','success');
        end
        
        function cancelarLogin(app)
            app.ConectarButton.Enable = 'on';
        end
        
        function ActivarAdminU(app)
            app.AdministrarusuariosButton.Enable = 'on';
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.Principal,"center");
            app.DesconectarButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnlisisdeestudioButton.Enable = 'off';
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.AdministrarusuariosButton.Enable = 'off';
            app.Conf.Enable = 'off';
            app.ClasificadoresButton.Enable = 'off';
            app.AnotacionesautomaticasButton.Enable = 'off';
            app.AnotacionesFlexButton.Enable = 'off'; % Se inhabilita botón anotaciones flexibles
        end

        % Button pushed function: AgregarnuevopacienteButton
        function AgregarnuevopacienteButtonPushed(app, event)
            % Abrimos la pestaña agregar paciente y enviamos la información
            % de conexión con el servidor a la pestaña. --DM
            app.AgregarPaciente = agregarpac(app,app.Conexion);
            
        end

        % Close request function: Principal
        function PrincipalCloseRequest(app, event)
            
            % Listado de todas las ventanas secundarias antes de cerrar la
            % % principal --DI 
            ventanasSecundarias = {app.AgregarPaciente, app.RealizarConsulta, ...
                app.AnalizarPrueba, app.Administrar, app.Configurar, app.CrearAlgoritmo, ...
                app.AnotacionesAutomaticas, app.Recon, app.Dialogo,app.VentanaAFlexibles};

            % Alerta que se cerrarán todas las ventanas --JP24
            fig = app.Principal;
            msg =  ['Va a cerrar la ventana principal, esta acción cerrará' ...
                    ' todas las ventanas de la Herramienta. ¿Desea continuar?'];
            sel = uiconfirm(fig,msg, 'Cierre ventana principal', ...
                                'Options',{ 'Aceptar', 'Cancelar'}, ...
                                'DefaultOption', 'Cancelar', "CancelOption", ...
                                'Cancelar','icon','warning');

            % Función anónima para cerrar ventanas --JP24 & DI
            if strcmp(sel, 'Aceptar')
                cellfun(@delete,ventanasSecundarias);

                %Para cerrar la aplicacion, siempre se debe de cerrar la
                %conexion con la base de datos. --DM
                close(app.Conexion); % Close database connection

                delete(app)
            end
        end

        % Button pushed function: ConsultarButton
        function ConsultarButtonPushed(app, event)
            % Abrimos la pestaña consultas y enviamos la información de
            % conexión con el servidor a la pestaña. Tambien se incluye la
            % la información del tipo de usuario para determinar la confidencialidad
            % --DM--
            app.RealizarConsulta = consultas(app,app.Conexion, app.user);
        end

        % Button pushed function: AnlisisdeestudioButton
        function AnlisisdeestudioButtonPushed(app, event)
            % Abrimos la pestaña consultas y enviamos la información de
            % conexión con el servidor a la pestaña. --DM
            app.AnalizarPrueba = procesamiento(app,app.Conexion);
        end

        % Value changed function: ConectarButton
        function ConectarButtonValueChanged(app, event)
            app.ConectarButton.Enable = 'off';
            app.Dialogo = credenciales(app);
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            % Close database connection
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnlisisdeestudioButton.Enable = 'off';
            close(app.Conexion);
            clear app.Conexion
            app.ConectarButton.Enable = 'on';
            app.DesconectarButton.Enable = 'off';
            app.AdministrarusuariosButton.Enable = 'off';
            app.Conf.Enable = 'off';
            
            app.AnotacionesautomaticasButton.Enable = 'off';
            app.ClasificadoresButton.Enable = 'off';
            app.recuperarc.Enable = 'off';
         
            app.pos = 0;
        end

        % Button pushed function: AdministrarusuariosButton
        function AdministrarusuariosButtonPushed(app, event)
%             app.AdministrarusuariosButton.Enable = 'off';
            app.Administrar = admin(app, app.Conexion, app.user);
        end

        % Button pushed function: Conf
        function ConfButtonPushed(app, event)
            app.Configurar = configuracion(app, app.Conexion, app.user, false);
        end

        % Button pushed function: ClasificadoresButton
        function ClasificadoresButtonPushed(app, event)
            app.CrearAlgoritmo = generacionalgoritmo(app);
        end

        % Button pushed function: AnotacionesautomaticasButton
        function AnotacionesautomaticasButtonPushed(app, event)
            app.AnotacionesAutomaticas = inicioanotacionesauto(app, app.Conexion);
        end

        % Button pushed function: recuperarc
        function recuperarcButtonPushed(app, event)
            app.Recon = recuperar(app, app.Conexion);
        end

        % Button pushed function: RegenerarBaseButton
        function RegenerarBaseButtonPushed(app, event)
           fig = app.Principal;
           message = '¡Precaución! Si actualmente existe una base de datos los datos serán borrados permanentemente. ¿Desea continuar?';
           
           borrar = uiconfirm(fig,message,'Aviso', ...
           'Options',{'No','Si'}, ...
           'DefaultOption',1,'CancelOption',1);
           if strcmp(borrar,'Si') %Corremos la función de matlab que genera la base de datos, -Corrección a strcmp JP24
               try
                   sql_matlab_create_consolidado(app.ContraseaDBEditField.Value);
                   message = 'La base de datos ha sido regenerada.'; 
                   uialert(fig ,message,'Exito','Icon','success');%En caso regrese un error
               catch
                   message = 'Algo fallo, revise la contraseña o que el conector OBDC se encuentre correctamente instalado.'; 
                   uialert(fig ,message,'Error','Icon','error');%En caso regrese un error
               end
           else

           end
        end

        % Button pushed function: AnotacionesFlexButton
        function AnotacionesFlexButtonPushed(app, event)
            % Se limita a una ventana a la vez
            app.AnotacionesFlexButton.Enable = "off";
            
            app.VentanaAFlexibles = AnotacionesFlexibles(app, app.Conexion);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Principal = uifigure('Visible', 'off');
            app.Principal.Name = 'Epileptic EEG Analysis Toolbox';
            app.Principal.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.Principal.CloseRequestFcn = createCallbackFcn(app, @PrincipalCloseRequest, true);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel = uilabel(app.Principal);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel.Text = 'EPILEPTIC EEG ANALYSIS TOOLBOX';
            app.Image = uiimage(app.Principal);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logouvg.jpg');
            app.ConectarButton = uibutton(app.Principal, 'state');
            app.ConectarButton.ValueChangedFcn = createCallbackFcn(app, @ConectarButtonValueChanged, true);
            app.ConectarButton.Text = 'Conectar';
            app.DesconectarButton = uibutton(app.Principal, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.AdministrarusuariosButton = uibutton(app.Principal, 'push');
            app.AdministrarusuariosButton.ButtonPushedFcn = createCallbackFcn(app, @AdministrarusuariosButtonPushed, true);
            app.AdministrarusuariosButton.Tooltip = {''};
            app.AdministrarusuariosButton.Text = 'Administrar usuarios';
            app.Conf = uibutton(app.Principal, 'push');
            app.Conf.ButtonPushedFcn = createCallbackFcn(app, @ConfButtonPushed, true);
            app.Conf.Icon = fullfile(pathToMLAPP, 'config.svg');
            app.Conf.Text = '';
            app.recuperarc = uibutton(app.Principal, 'push');
            app.recuperarc.ButtonPushedFcn = createCallbackFcn(app, @recuperarcButtonPushed, true);
            app.recuperarc.Icon = fullfile(pathToMLAPP, 'recuperar.png');
            app.recuperarc.Enable = 'off';
            app.recuperarc.Text = '';
            app.TabGroup = uitabgroup(app.Principal);
            app.PacientesTab = uitab(app.TabGroup);
            app.PacientesTab.Title = 'Pacientes';
            app.AgregarnuevopacienteButton = uibutton(app.PacientesTab, 'push');
            app.AgregarnuevopacienteButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarnuevopacienteButtonPushed, true);
            app.AgregarnuevopacienteButton.Text = 'Agregar nuevo paciente';
            app.ConsultarButton = uibutton(app.PacientesTab, 'push');
            app.ConsultarButton.ButtonPushedFcn = createCallbackFcn(app, @ConsultarButtonPushed, true);
            app.ConsultarButton.Text = 'Consultar';
            app.AnlisisTab = uitab(app.TabGroup);
            app.AnlisisTab.Title = 'Análisis';
            app.AnotacionesautomaticasButton = uibutton(app.AnlisisTab, 'push');
            app.AnotacionesautomaticasButton.ButtonPushedFcn = createCallbackFcn(app, @AnotacionesautomaticasButtonPushed, true);
            app.AnotacionesautomaticasButton.Text = 'Anotaciones por canal';
            app.AnlisisdeestudioButton = uibutton(app.AnlisisTab, 'push');
            app.AnlisisdeestudioButton.ButtonPushedFcn = createCallbackFcn(app, @AnlisisdeestudioButtonPushed, true);
            app.AnlisisdeestudioButton.Text = 'Análisis de estudio';
            app.AnotacionesFlexButton = uibutton(app.AnlisisTab, 'push');
            app.AnotacionesFlexButton.ButtonPushedFcn = createCallbackFcn(app, @AnotacionesFlexButtonPushed, true);
            app.AnotacionesFlexButton.Text = 'Anotaciones por montaje';
            app.ModelosTab = uitab(app.TabGroup);
            app.ModelosTab.Title = 'Modelos';
            app.ClasificadoresButton = uibutton(app.ModelosTab, 'push');
            app.ClasificadoresButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificadoresButtonPushed, true);
            app.ClasificadoresButton.Text = 'Clasificadores';
            app.DBTab = uitab(app.TabGroup);
            app.DBTab.Title = 'DB';
            app.RegenerarBaseButton = uibutton(app.DBTab, 'push');
            app.RegenerarBaseButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerarBaseButtonPushed, true);
            app.RegenerarBaseButton.Text = 'Regenerar Base';
            app.UsuariorootLabel = uilabel(app.DBTab);
            app.UsuariorootLabel.Text = 'Usuario :            root';
            app.ConstraseaLabel = uilabel(app.DBTab);
            app.ConstraseaLabel.Text = 'Constraseña:';
            app.ContraseaDBEditField = uieditfield(app.DBTab, 'text');
            % Show the figure after all components are created
            app.Principal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = principal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Principal)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Principal)
        end
    end
end