classdef procesamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Carac                     matlab.ui.Figure
        VentanaMultigrficosVerticalButton  matlab.ui.control.Button
        VentanaMultigrficosSimpleButton  matlab.ui.control.Button
        CargarArchivoMATButton    matlab.ui.control.Button
        RegresarButton            matlab.ui.control.Button
        SeleccionarEEGdeBasededatosButton  matlab.ui.control.Button
        CalcularButton            matlab.ui.control.Button
        CaractersticasTpicasButtonGroup  matlab.ui.container.ButtonGroup
        CurtosisButton            matlab.ui.control.RadioButton
        DesviacinestndarButton    matlab.ui.control.RadioButton
        PicosAmplitudMxButton     matlab.ui.control.RadioButton
        CrucesporCeroButton       matlab.ui.control.RadioButton
        MAVButton                 matlab.ui.control.RadioButton
        MediaButton               matlab.ui.control.RadioButton
        ExtraerVectordeCaractersticasButton  matlab.ui.control.Button
        TiempoMaxsEditField       matlab.ui.control.NumericEditField
        TiempoMaxsEditFieldLabel  matlab.ui.control.Label
        TiempoMinsEditField       matlab.ui.control.NumericEditField
        TiempoMinsEditFieldLabel  matlab.ui.control.Label
        VisualizarButton          matlab.ui.control.Button
        CanalSpinner              matlab.ui.control.Spinner
        CanalSpinnerLabel         matlab.ui.control.Label
        CargarEDFButton           matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %Variables compartidas entre ventanas
        Minvalue 
        Maxvalue
        tiempo
        datos
        indexsup
        indexinf
        header
        freq
        Matriz_features
        channel_ventana
        c
        op
    end
    
    properties (Access = private)
        %Objetos GUI
        DialogApp 
        PruebaApp
        Conexion
        CallingApp
        MultiApp
        MultiAppv
    end
    
    
    methods (Access = public)
      function features_data(app,data,hdr)
            app.datos = data;
            app.header = hdr;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            features_data(app,app.datos,app.header);
            app.ExtraerVectordeCaractersticasButton.Enable = 'off';
            app.CalcularButton.Enable = 'off';
            app.VisualizarButton.Enable = 'off';
            app.VentanaMultigrficosSimpleButton.Enable = 'off'; 
            app.VentanaMultigrficosVerticalButton.Enable = 'off';
            %Establecer coneccion con ventana de base de datos
            app.CallingApp = mainapp;
            app.Conexion = con;
            if (isempty(app.Conexion)||~isopen(app.Conexion))
                app.SeleccionarEEGdeBasededatosButton.Enable = 'off';
            else
                app.SeleccionarEEGdeBasededatosButton.Enable = 'on';
            end
        end

        % Button pushed function: CargarEDFButton
        function CargarEDFButtonPushed(app, event)
            %Abrir archivo EDF
            [file,path,~] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                disp('Usuario canceló la operacion');
            else
                disp(['EL usuario seleccionó ', fullfile(path,file)]);
            end
            [hdr, record] = openedf(strcat(path,file));
            app.datos = record';
            app.header = hdr;
            app.freq = hdr.Fs(1);
            
            % Si la frecuencia no es un double, se convierte a double -JP24
            if ~isa(app.freq,"numeric")
                app.freq = double(string(app.freq));
            end

            app.VisualizarButton.Enable = 'on';
            app.ExtraerVectordeCaractersticasButton.Enable ='on';
             app.VentanaMultigrficosSimpleButton.Enable = 'on';
            fig = app.Carac;
            message = 'Prueba cargada satisfactoriamente'; %Aviso nuevo
            uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato 
        end

        % Button pushed function: VisualizarButton
        function VisualizarButtonPushed(app, event)
        %ventana= app.EditField_2.Value;
        if isempty(app.datos)
            figure = app.Carac;
            message = 'Antes de poder visualizar cargue una señal';
            uialert(figure,message,'Aviso','Icon','warning');
        end
        value = app.CanalSpinner.Value;
        record = app.datos;
        %Verificar que el canal se encuentre dentro de los datos
        no_canales = size(record,1);
        Ts = 1/app.freq; %Periodo de muestreo --JP24
        if(value <= no_canales)
        
            app.tiempo = (1:length(record))*Ts;
            tmin = app.TiempoMinsEditField.Value;
            tmax = app.TiempoMaxsEditField.Value;
            %Acotar gráfica según tamaño del archivo EDF
            if tmax>length(record)*Ts
                tmax = length(record)*Ts;
                app.TiempoMaxsEditField.Value = round(length(record)*Ts);
            elseif mod(tmax,1)~=0 || mod(tmin,1)~=0 %si es decimal
                tmax = round(tmax);
                tmin = round(tmin);
            % elseif tmin==0
            %     app.TiempoMinsEditField.Value = 1;
            %     tmin=1;
            end
            
            %Graficar canal y tiempo seleccionado
            plot(app.UIAxes,app.tiempo,record(value,:));

            app.UIAxes.XLim=[tmin, tmax];
            app.indexinf = tmin;
            app.indexsup = tmax;
            
            app.CalcularButton.Enable = 'on';
            %app.UIAxes_2.cla; %(CP)
            cla(app.UIAxes_2);
            
        else
            fig = app.Carac;
            message = sprintf('El archivo EEG seleccionado cuenta con %d canales, por favor seleccione un canal entre 1 y %d.', no_canales(1), no_canales(1));
            uialert(fig,message,'Aviso',Icon='error');
        end
        end

        % Button pushed function: ExtraerVectordeCaractersticasButton
        function ExtraerVectordeCaractersticasButtonPushed(app, event)
             % Abrir ventana de caracteristicas
            app.DialogApp = features(app);
        end

        % Close request function: Carac
        function CaracCloseRequest(app, event)
        if (isempty(app.Conexion)||~isopen(app.Conexion))
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'off';
            app.CallingApp.ConsultarButton.Enable = 'off';
            app.CallingApp.AnlisisdeestudioButton.Enable = 'on';
            app.CallingApp.DesconectarButton.Enable = 'off';
        else
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
            app.CallingApp.ConsultarButton.Enable = 'on';
            app.CallingApp.AnlisisdeestudioButton.Enable = 'on';
            app.CallingApp.DesconectarButton.Enable = 'on';
        end
            delete(app)
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
           %app.UIAxes_2.cla; %(CP)
           cla(app.UIAxes_2);
           if ((app.TiempoMaxsEditField.Value-app.TiempoMinsEditField.Value)>=60)
                %Mensaje de advertencia 
                message = sprintf('Esta operación puede tomar algunos segundos');
                uialert(app.Carac,message,'Aviso',...
                'Icon','warning');
           end
            
            %Calcular características típicas en tiempo real
            canal = app.CanalSpinner.Value;
            muestras = app.freq; % Cambio por JP24
            signal = app.datos;
            app.op = [0,0,0,0,0,0];
            app.indexinf = 256*(app.TiempoMinsEditField.Value);
            app.indexsup = 256*(app.TiempoMaxsEditField.Value);
            %Acotar tamaño de ventana según gráfica
            if app.indexinf==0
                app.indexinf=1;
            end
            if mod(app.indexinf*256/2,1)~=0 || mod(app.indexsup*256/2,1)~=0  %si es decimal 
                app.indexsup = round( app.indexsup);
                app.indexinf = round(app.indexinf);
            end
            if app.MediaButton.Value
                app.op(1)=1;
            elseif app.MAVButton.Value
                app.op(2)=1;     
            elseif app.CrucesporCeroButton.Value
                app.op(3)=1;
            elseif app.PicosAmplitudMxButton.Value
                app.op(4)=1;
            elseif app.DesviacinestndarButton.Value
                app.op(5)=1;
            elseif app.CurtosisButton.Value
                app.op(6)=1;
            end
            %Utilizar funcion features_tipicas
            [vector,~] = Features_tipicas(signal(canal,app.indexinf:app.indexsup),app.freq,1,muestras,canal,app.op);
            if length(vector)==1
                app.UIAxes_2.XLim=[0, length(vector)];
            else
                app.UIAxes_2.XLim=[1, length(vector)];
            end
            
            %Desplegar grafica
            plot(app.UIAxes_2,1:length(vector),vector)
            
        end

        % Button pushed function: SeleccionarEEGdeBasededatosButton
        function SeleccionarEEGdeBasededatosButtonPushed(app, event)
            app.PruebaApp = selectprueba(app, app.Conexion);
            
           
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            if (isempty(app.Conexion)||~isopen(app.Conexion))
                app.CallingApp.AgregarnuevopacienteButton.Enable = 'off';
                app.CallingApp.ConsultarButton.Enable = 'off';
                app.CallingApp.AnlisisdeestudioButton.Enable = 'on';
                app.CallingApp.DesconectarButton.Enable = 'off';
            else
                app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
                app.CallingApp.ConsultarButton.Enable = 'on';
                app.CallingApp.AnlisisdeestudioButton.Enable = 'on';
                app.CallingApp.DesconectarButton.Enable = 'on';
            end
            delete(app)
        end

        % Button pushed function: CargarArchivoMATButton
        function CargarArchivoMATButtonPushed(app, event)
            %Seleccionar archivo MAT
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo M');
            if isequal(file,0)
                disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
            end
            %Asegurarse que el workspace cargado contenga la variable data
            workspace = load(strcat(path,file),'data');
            app.datos =  workspace.data;
            %Asegurarse que la señal este muestreada a 256Hz o cambiar
            %variable freq
            app.freq = 256;
            app.VisualizarButton.Enable = 'on';
            app.ExtraerVectordeCaractersticasButton.Enable ='on';
             app.VentanaMultigrficosSimpleButton.Enable = 'on';
            fig = app.Carac;
            message = 'Prueba cargada satisfactoriamente'; %Aviso nuevo
            uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato 
        end

        % Button pushed function: VentanaMultigrficosSimpleButton
        function VentanaMultigrficosSimpleButtonPushed(app, event)
            if isempty(app.datos)
                figure = app.Carac;
                message = 'Antes de poder visualizar cargue una señal'
                uialert(figure,message,'Aviso','Icon','warning');
            else
                Ts = 1/app.freq; %Periodo de muestreo
                app.tiempo = (1:length(app.datos))*Ts;
                tmin = app.TiempoMinsEditField.Value;
                tmax = app.TiempoMaxsEditField.Value;
                %Acotar gráfica según tamaño del archivo EDF
                if tmax>length(app.datos)*Ts
                    tmax = length(app.datos)*Ts;
                    app.TiempoMaxsEditField.Value = round(length(app.datos)*Ts);
                elseif mod(tmax,1)~=0 || mod(tmin,1)~=0 %si es decimal
                    tmax = round(tmax);
                    tmin = round(tmin);
                % elseif tmin==0
                %     app.TiempoMinsEditField.Value = 1;
                %     tmin=1;
                end
                app.UIAxes.XLim=[tmin, tmax];
                app.indexinf = tmin;
                app.indexsup = tmax;
                app.MultiApp = multigrafico(app);
            end
        end

        % Button pushed function: VentanaMultigrficosVerticalButton
        function VentanaMultigrficosVerticalButtonPushed(app, event)
               if isempty(app.datos)
                figure = app.Carac;
                message = 'Antes de poder visualizar cargue una señal'
                uialert(figure,message,'Aviso','Icon','warning');
            else
                Ts = 1/app.freq; %Periodo de muestreo JP24
                app.tiempo = (1:length(app.datos))*Ts;
                tmin = app.TiempoMinsEditField.Value;
                tmax = app.TiempoMaxsEditField.Value;
                %Acotar gráfica según tamaño del archivo EDF
                if tmax>length(app.datos)*Ts
                    tmax = length(app.datos)*Ts;
                    app.TiempoMaxsEditField.Value = round(length(app.datos)*Ts);
                elseif mod(tmax,1)~=0 || mod(tmin,1)~=0 %si es decimal
                    tmax = round(tmax);
                    tmin = round(tmin);
                % elseif tmin==0
                %     app.TiempoMinsEditField.Value = 1;
                %     tmin=1;
                end
                app.UIAxes.XLim=[tmin, tmax];
                app.indexinf = tmin;
                app.indexsup = tmax;
                app.MultiAppv = multigrafico_vertical(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Carac = uifigure('Visible', 'off');
            app.Carac.Name = 'Visualización de Señal';
            app.Carac.CloseRequestFcn = createCallbackFcn(app, @CaracCloseRequest, true);
            app.UIAxes_2 = uiaxes(app.Carac);
            xlabel(app.UIAxes_2, 'Ventanas')
            ylabel(app.UIAxes_2, 'Característica')
            app.UIAxes = uiaxes(app.Carac);
            title(app.UIAxes, 'Señal EEG')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            app.CargarEDFButton = uibutton(app.Carac, 'push');
            app.CargarEDFButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEDFButtonPushed, true);
            app.CargarEDFButton.Text = 'Cargar EDF';
            app.CanalSpinnerLabel = uilabel(app.Carac);
            app.CanalSpinnerLabel.Text = 'Canal';
            app.CanalSpinner = uispinner(app.Carac);
            app.CanalSpinner.Limits = [1 35];
            app.CanalSpinner.Value = 1;
            app.VisualizarButton = uibutton(app.Carac, 'push');
            app.VisualizarButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarButtonPushed, true);
            app.VisualizarButton.Text = 'Visualizar';
            app.TiempoMinsEditFieldLabel = uilabel(app.Carac);
            app.TiempoMinsEditFieldLabel.Text = 'Tiempo Min (s)';
            app.TiempoMinsEditField = uieditfield(app.Carac, 'numeric');
            app.TiempoMaxsEditFieldLabel = uilabel(app.Carac);
            app.TiempoMaxsEditFieldLabel.Text = 'Tiempo Max (s)';
            app.TiempoMaxsEditField = uieditfield(app.Carac, 'numeric');
            app.TiempoMaxsEditField.Value = 10;
            app.ExtraerVectordeCaractersticasButton = uibutton(app.Carac, 'push');
            app.ExtraerVectordeCaractersticasButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraerVectordeCaractersticasButtonPushed, true);
            app.ExtraerVectordeCaractersticasButton.Text = 'Extraer Vector de Características';
            app.CaractersticasTpicasButtonGroup = uibuttongroup(app.Carac);
            app.CaractersticasTpicasButtonGroup.Title = 'Características Típicas';
            app.MediaButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.MediaButton.Text = 'Media';
            app.MediaButton.Value = true;
            app.MAVButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.MAVButton.Text = 'MAV';
            app.CrucesporCeroButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.CrucesporCeroButton.Text = 'Cruces por Cero';
            app.PicosAmplitudMxButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.PicosAmplitudMxButton.Text = 'Picos/Amplitud Máx.';
            app.DesviacinestndarButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.DesviacinestndarButton.Text = 'Desviación estándar';
            app.CurtosisButton = uiradiobutton(app.CaractersticasTpicasButtonGroup);
            app.CurtosisButton.Text = 'Curtosis';
            app.CalcularButton = uibutton(app.Carac, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.SeleccionarEEGdeBasededatosButton = uibutton(app.Carac, 'push');
            app.SeleccionarEEGdeBasededatosButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarEEGdeBasededatosButtonPushed, true);
            app.SeleccionarEEGdeBasededatosButton.Text = 'Seleccionar EEG de Base de datos';
            app.RegresarButton = uibutton(app.Carac, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.CargarArchivoMATButton = uibutton(app.Carac, 'push');
            app.CargarArchivoMATButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoMATButtonPushed, true);
            app.CargarArchivoMATButton.Text = 'Cargar Archivo MAT';
            app.VentanaMultigrficosSimpleButton = uibutton(app.Carac, 'push');
            app.VentanaMultigrficosSimpleButton.ButtonPushedFcn = createCallbackFcn(app, @VentanaMultigrficosSimpleButtonPushed, true);
            app.VentanaMultigrficosSimpleButton.Text = 'Ventana Multigráficos Simple';
            app.VentanaMultigrficosVerticalButton = uibutton(app.Carac, 'push');
            app.VentanaMultigrficosVerticalButton.ButtonPushedFcn = createCallbackFcn(app, @VentanaMultigrficosVerticalButtonPushed, true);
            app.VentanaMultigrficosVerticalButton.Text = 'Ventana Multigráficos Vertical';
            % Show the figure after all components are created
            app.Carac.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = procesamiento(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Carac)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Carac)
        end
    end
end