classdef recuperar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigrecuperarcontraseaUIFigure  matlab.ui.Figure
        ServerEditField          matlab.ui.control.EditField
        ServerEditFieldLabel     matlab.ui.control.Label
        Mensaje2TextArea         matlab.ui.control.TextArea
        Mensaje2TextAreaLabel    matlab.ui.control.Label
        Mensaje1TextArea         matlab.ui.control.TextArea
        Mensaje1TextAreaLabel    matlab.ui.control.Label
        AsuntoEditField          matlab.ui.control.EditField
        AsuntoEditFieldLabel     matlab.ui.control.Label
        ContraseaEditField       matlab.ui.control.EditField
        ContraseaEditFieldLabel  matlab.ui.control.Label
        CorreoEditField          matlab.ui.control.EditField
        CorreoEditFieldLabel     matlab.ui.control.Label
        CerrarButton             matlab.ui.control.Button
        GuardarButton            matlab.ui.control.Button
        ConfiguracinRecuperarcontraseaLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
        Conexion;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            movegui(app.ConfigrecuperarcontraseaUIFigure,"center");
            app.CallingApp = mainapp;
            app.Conexion = con;

            query = ['SELECT * FROM humana.config'];

            data = fetch(app.Conexion,query);

            app.CorreoEditField.Value = char(data.correo);
            app.ContraseaEditField.Value = char(data.pass);
            app.AsuntoEditField.Value = char(data.asunto);
            app.Mensaje1TextArea.Value = char(data.mensaje_1);
            app.Mensaje2TextArea.Value = char(data.mensaje_2);
            app.ServerEditField.Value = char(data.server);
            
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            campos = false;
            if(strcmp(app.CorreoEditField.Value,'') || strcmp(app.ContraseaEditField.Value,'') || strcmp(app.AsuntoEditField.Value,'') || any(strcmp(app.Mensaje1TextArea.Value,'')) || any(strcmp(app.Mensaje2TextArea.Value,'')) || strcmp(app.ServerEditField.Value,''))
                errordlg('Error. Todos loa campos deben tener datos.','Error');
            else
                campos = true;
            end

            if(campos)
                %actualizar configuración de recuperar contraseña
                con = app.Conexion;

                updaterol = "UPDATE humana.config SET correo = '" ;
                updaterol = strcat(updaterol, app.CorreoEditField.Value);
                updaterol = strcat(updaterol, "', pass = '");
                updaterol = strcat(updaterol, app.ContraseaEditField.Value);
                updaterol = strcat(updaterol, "', asunto = '");
                updaterol = strcat(updaterol, app.AsuntoEditField.Value);
                updaterol = strcat(updaterol, "', mensaje_1 = '");
                updaterol = strcat(updaterol, app.Mensaje1TextArea.Value);
                updaterol = strcat(updaterol, "', mensaje_2 = '");
                updaterol = strcat(updaterol, app.Mensaje2TextArea.Value);
                updaterol = strcat(updaterol, "', server = '");
                updaterol = strcat(updaterol, app.ServerEditField.Value);
                updaterol = strcat(updaterol, "'");

                execute(con, updaterol);
                
                msgbox('Datos actualizado exitosamente.', 'Mensaje');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ConfigrecuperarcontraseaUIFigure = uifigure('Visible', 'off');
            app.ConfigrecuperarcontraseaUIFigure.Name = 'Config recuperar contraseña';
            app.ConfigrecuperarcontraseaUIFigure.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.ConfiguracinRecuperarcontraseaLabel = uilabel(app.ConfigrecuperarcontraseaUIFigure);
            app.ConfiguracinRecuperarcontraseaLabel.Text = {'Configuración '; 'Recuperar contraseña'};
            app.GuardarButton = uibutton(app.ConfigrecuperarcontraseaUIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.CerrarButton = uibutton(app.ConfigrecuperarcontraseaUIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.CorreoEditFieldLabel = uilabel(app.ConfigrecuperarcontraseaUIFigure);
            app.CorreoEditFieldLabel.Text = 'Correo';
            app.CorreoEditField = uieditfield(app.ConfigrecuperarcontraseaUIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.ConfigrecuperarcontraseaUIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.ConfigrecuperarcontraseaUIFigure, 'text');
            app.AsuntoEditFieldLabel = uilabel(app.ConfigrecuperarcontraseaUIFigure);
            app.AsuntoEditFieldLabel.Text = 'Asunto';
            app.AsuntoEditField = uieditfield(app.ConfigrecuperarcontraseaUIFigure, 'text');
            app.Mensaje1TextAreaLabel = uilabel(app.ConfigrecuperarcontraseaUIFigure);
            app.Mensaje1TextAreaLabel.Text = 'Mensaje 1';
            app.Mensaje1TextArea = uitextarea(app.ConfigrecuperarcontraseaUIFigure);
            app.Mensaje2TextAreaLabel = uilabel(app.ConfigrecuperarcontraseaUIFigure);
            app.Mensaje2TextAreaLabel.Text = 'Mensaje 2';
            app.Mensaje2TextArea = uitextarea(app.ConfigrecuperarcontraseaUIFigure);
            app.ServerEditFieldLabel = uilabel(app.ConfigrecuperarcontraseaUIFigure);
            app.ServerEditFieldLabel.Text = 'Server';
            app.ServerEditField = uieditfield(app.ConfigrecuperarcontraseaUIFigure, 'text');
            % Show the figure after all components are created
            app.ConfigrecuperarcontraseaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recuperar(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConfigrecuperarcontraseaUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConfigrecuperarcontraseaUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigrecuperarcontraseaUIFigure)
        end
    end
end