classdef selectprueba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        seleccprueba            matlab.ui.Figure
        BuscarButton            matlab.ui.control.Button
        CargarDatosButton       matlab.ui.control.Button
        PruebaEditField         matlab.ui.control.EditField
        PruebaEditFieldLabel    matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        PacienteEditField       matlab.ui.control.EditField
        PacienteEditFieldLabel  matlab.ui.control.Label
        BsquedadedatosenBasedeDatosLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        datos
        Conexion % Description
    end
    
    properties (Access = public)
        frecuencia
        no_canales
        datadb % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            movegui(app.seleccprueba,"center");
            app.CallingApp = mainapp;
            global p
            p.MyData=[];
            app.Conexion = con;
        end

        % Callback function: BuscarButton, PacienteEditField
        function BuscarButtonPushed(app, event)
            global p
            id_paciente=app.PacienteEditField.Value;
            %------ Conexion con la base de datos------------------------
            conn = app.Conexion;
            query = ['SELECT * ' ...
                'FROM humana.pacientes WHERE pacientes.id_paciente= "' ...
                id_paciente...
                '"'];
            
            query2 = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '"'];
            
            data = fetch(conn,query);
            data2 = fetch(conn,query2);
            
            p.MyData=[];
            app.UITable.Data = p.MyData;

            if(isempty(data)==1)
                fig = app.seleccprueba;
                message = 'Paciente no encontrado';
                uialert(fig ,message,'Error','Icon','error');%Acualizacion a ultimo formato 

                ini = char(' ');
                p.MyData=[];
                app.PacienteEditField.Value = ini;

                app.UITable.Data = p.MyData;
                
            else
                % Mostrar datos en tablas
                dimension=size(data2);
                for i=1:dimension(1)
                    prueba = sprintf('%d',data2.id_prueba(i));
                    frecuencia = sprintf('%d',data2.frecuencia(i));
                    canales = sprintf('%d',data2.no_canales(i));
                    p.MyData=[p.MyData; [{i} {prueba} {frecuencia} {canales}]];
                end
                app.UITable.Data = p.MyData;
            end
            
            %Clear variables
            clear query query2
        end

        % Button pushed function: CargarDatosButton
        function CargarDatosButtonPushed(app, event)
            id_paciente=app.PacienteEditField.Value;
            id_prueba = app.PruebaEditField.Value;
            fig = app.seleccprueba;
            if (isempty(id_prueba)) %Verificamos que se haya seleccionado una prueba -- DM --
                message = 'Prueba no seleccionada'; %Aviso nuevo
                uialert(fig ,message,'Aviso','Icon','warning');%Acualizacion a ultimo formato 
            else
                message = 'Esto puede tardar varios minutos...';
                d=uiprogressdlg(fig,'Message',message,...
                'Icon','info','Cancelable','off','Indeterminate','on'); % Barra de progreso 
                %------ Conexion con la base de datos------------------------
                conn = app.Conexion;
                query = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '" AND pruebas.id_prueba= "' ... 
                id_prueba...
                '"'];
                data = fetch(conn,query);

                query2 = ['SELECT * ' ...
                    'FROM humana.pruebas_datos WHERE pruebas_datos.id_prueba= "' ...
                    id_prueba...
                    '"'];
                data_datos = fetch(conn,query2);

                app.CallingApp.freq = sprintf('%d',data.frecuencia); %Se debe guardar como string, para usarse pasar por la funcion str2double 
                app.no_canales = sprintf('%d',data.no_canales);

                data_datos = removevars(data_datos,'id_prueba');
               
                %Evitamos cargar canales extras con 0.0
                for i = (data.no_canales + 1):35 %35 es la capacidad máxima de canales que puede almacenar la base de datos.
                    remove = sprintf('canal_%d', i);
                    data_datos = removevars(data_datos, remove);
                end
                data_datos = transpose(cell2mat(table2cell(data_datos)));
                app.CallingApp.datos = data_datos;

                clear query
                app.CallingApp.VisualizarButton.Enable = 'on';
                app.CallingApp.ExtraerVectordeCaractersticasButton.Enable = 'on';
                app.CallingApp.VentanaMultigrficosSimpleButton.Enable = 'on';
                app.CallingApp.VentanaMultigrficosVerticalButton.Enable = 'on';

            end
            close(d);
            fig = app.CallingApp.Carac;
            message = 'Datos cargados satisfactoriamente de base de datos.'; %Aviso nuevo
            uialert(fig ,message,'Aviso','Icon','success');%Acualizacion a ultimo formato 
            delete(app)
        end

        % Close request function: seleccprueba
        function seleccpruebaCloseRequest(app, event)
            delete(app)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;

            if (~isempty(selection)) %Evaluamos si se selecciono un dato

                data_prueba = app.UITable.Data(selection(1), 2); %Dato seleccionado
                data_prueba = cell2mat(data_prueba); %Transformacion de cell a que contiene string 

                
                if (selection(2) == 2) %Evaluamos si se selecciono un dato de la columna pruebas
                    app.PruebaEditField.Value = data_prueba;
                   
                    
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.seleccprueba = uifigure('Visible', 'off');
            app.seleccprueba.Name = 'Búsqueda desde la base de datos';
            app.seleccprueba.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.seleccprueba.CloseRequestFcn = createCallbackFcn(app, @seleccpruebaCloseRequest, true);
            app.BsquedadedatosenBasedeDatosLabel = uilabel(app.seleccprueba);
            app.BsquedadedatosenBasedeDatosLabel.Text = 'Búsqueda de datos en Base de Datos';
            app.PacienteEditFieldLabel = uilabel(app.seleccprueba);
            app.PacienteEditFieldLabel.Text = 'Paciente';
            app.PacienteEditField = uieditfield(app.seleccprueba, 'text');
            app.PacienteEditField.ValueChangedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.UITable = uitable(app.seleccprueba);
            app.UITable.ColumnName = {'No.'; 'Prueba'; 'Frecuencia (Hz)'; 'No. Canales'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.PruebaEditFieldLabel = uilabel(app.seleccprueba);
            app.PruebaEditFieldLabel.Text = 'Prueba';
            app.PruebaEditField = uieditfield(app.seleccprueba, 'text');
            app.PruebaEditField.Editable = 'off';
            app.CargarDatosButton = uibutton(app.seleccprueba, 'push');
            app.CargarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargarDatosButtonPushed, true);
            app.CargarDatosButton.Text = 'Cargar Datos';
            app.BuscarButton = uibutton(app.seleccprueba, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            % Show the figure after all components are created
            app.seleccprueba.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectprueba(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.seleccprueba)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.seleccprueba)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.seleccprueba)
        end
    end
end